classdef Overview_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image22                         matlab.ui.control.Image
        Image20_3                       matlab.ui.control.Image
        Image20_2                       matlab.ui.control.Image
        Image20                         matlab.ui.control.Image
        Panel_4                         matlab.ui.container.Panel
        AlarmsCount_Label               matlab.ui.control.Label
        CodeLabel                       matlab.ui.control.Label
        Pressure_Alarm_Icon             matlab.ui.control.Image
        NoAlarmsNotificationsLabel      matlab.ui.control.Label
        SourceLabel                     matlab.ui.control.Label
        AlarmDescriptionLabel           matlab.ui.control.Label
        StartTimeLabel                  matlab.ui.control.Label
        AknLabel                        matlab.ui.control.Label
        AlarmsDetailsLabel              matlab.ui.control.Label
        UITable_Alarms                  matlab.ui.control.Table
        Panel_2                         matlab.ui.container.Panel
        Overview_B_Reactor_Lamp         matlab.ui.control.Lamp
        Overview_B_Feed_Lamp            matlab.ui.control.Lamp
        Overview_Reactor_Level_Lamp     matlab.ui.control.Lamp
        Overview_Reactor_Temp_Lamp      matlab.ui.control.Lamp
        Overview_Reactor_Pressure_Lamp  matlab.ui.control.Lamp
        Image19_2                       matlab.ui.control.Image
        TgLabel_2                       matlab.ui.control.Label
        Overview_Prod_Sp_Label_3        matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        CLabel_2                        matlab.ui.control.Label
        KpaLabel_2                      matlab.ui.control.Label
        KscmhLabel                      matlab.ui.control.Label
        ReactorLabel_2                  matlab.ui.control.Label
        Overview_TotalFeed              matlab.ui.control.Label
        TotalFeedLabel                  matlab.ui.control.Label
        ReactorLabel                    matlab.ui.control.Label
        Overview_React_Level            matlab.ui.control.Label
        Overview_React_Temp             matlab.ui.control.Label
        Overview_React_Pressure         matlab.ui.control.Label
        TemperatureLabel_2              matlab.ui.control.Label
        LevelLabel_2                    matlab.ui.control.Label
        PressureLabel_2                 matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        UIAxes_Overview_React_Temp      matlab.ui.control.UIAxes
        UIAxes_Overview_React_Level     matlab.ui.control.UIAxes
        UIAxes_Overview_React_Pressure  matlab.ui.control.UIAxes
        Panel_4_                        matlab.ui.container.Panel
        Overview_B_Purge_Lamp           matlab.ui.control.Lamp
        Overview_Sep_Level_Lamp         matlab.ui.control.Lamp
        Overview_Sep_Temp_Lamp          matlab.ui.control.Lamp
        Overview_Sep_Pressure_Lamp      matlab.ui.control.Lamp
        Overview_B_Separator_Lamp       matlab.ui.control.Lamp
        Overview_B_Condenser_Lamp       matlab.ui.control.Lamp
        Overview_B_Compressor_Lamp      matlab.ui.control.Lamp
        TgLabel                         matlab.ui.control.Label
        Overview_Prod_Sp_Label_2        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        KpaLabel                        matlab.ui.control.Label
        m3hLabel                        matlab.ui.control.Label
        CompressorLabel                 matlab.ui.control.Label
        Overview_Sep_Level              matlab.ui.control.Label
        LevelLabel                      matlab.ui.control.Label
        Overview_Sep_Temp               matlab.ui.control.Label
        TemperatureLabel                matlab.ui.control.Label
        Overview_Sep_Pressure           matlab.ui.control.Label
        PressureLabel                   matlab.ui.control.Label
        Overview_Sep_Underflow          matlab.ui.control.Label
        SeparatorOutputLabel            matlab.ui.control.Label
        SeparatorLabel_2                matlab.ui.control.Label
        CondenserLabel                  matlab.ui.control.Label
        Image19                         matlab.ui.control.Image
        SeparatorLabel                  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        UIAxes_Overview_Sep_Temp        matlab.ui.control.UIAxes
        UIAxes_Overview_Sep_Level       matlab.ui.control.UIAxes
        UIAxes_Overview_Sep_Pressure    matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        Panel_5                         matlab.ui.container.Panel
        PauseButton                     matlab.ui.control.StateButton
        Show_Alarms_Button_F3           matlab.ui.control.StateButton
        Show_Alarms_Button_F2           matlab.ui.control.StateButton
        Diagnostic_Fault3_Perc_Label    matlab.ui.control.Label
        Diagnostic_Fault2_Perc_Label    matlab.ui.control.Label
        Diagnostic_Fault_Perc_Label     matlab.ui.control.Label
        Diagnostic_Fault3_Label         matlab.ui.control.Label
        Show_Alarms_Button_F1           matlab.ui.control.StateButton
        Diagnostic_Fault2_Label         matlab.ui.control.Label
        Diagnostic_Fault_Label          matlab.ui.control.Label
        FaultsfoundLabel                matlab.ui.control.Label
        SolutionStepsLabel              matlab.ui.control.Label
        Diagnostic_Solution_Label       matlab.ui.control.Label
        Image21                         matlab.ui.control.Image
        DiagnosticToolLabel             matlab.ui.control.Label
        Level_Label_47                  matlab.ui.control.Label
        Overview_Feed_Lamp              matlab.ui.control.Lamp
        Overview_Reactor_Conc_Lamp      matlab.ui.control.Lamp
        Level_Label_46                  matlab.ui.control.Label
        Level_Label_45                  matlab.ui.control.Label
        Overview_Product_Conc_Lamp      matlab.ui.control.Lamp
        Overview_Purge_Conc_Lamp        matlab.ui.control.Lamp
        Level_Label_44                  matlab.ui.control.Label
        Level_Label_43                  matlab.ui.control.Label
        Level_Label_42                  matlab.ui.control.Label
        Overview_ReactorFlow_Label      matlab.ui.control.Label
        Overview_ReactorA_Label         matlab.ui.control.Label
        Level_Label_41                  matlab.ui.control.Label
        Overview_ReactorF_Label         matlab.ui.control.Label
        Level_Label_40                  matlab.ui.control.Label
        Overview_ReactorE_Label         matlab.ui.control.Label
        Level_Label_39                  matlab.ui.control.Label
        Overview_ReactorD_Label         matlab.ui.control.Label
        Level_Label_38                  matlab.ui.control.Label
        Overview_ReactorC_Label         matlab.ui.control.Label
        Level_Label_37                  matlab.ui.control.Label
        Overview_ReactorB_Label         matlab.ui.control.Label
        Level_Label_36                  matlab.ui.control.Label
        Level_Label_35                  matlab.ui.control.Label
        Overview_PurgeH_Label           matlab.ui.control.Label
        Level_Label_34                  matlab.ui.control.Label
        Overview_PurgeG_Label           matlab.ui.control.Label
        Level_Label_33                  matlab.ui.control.Label
        Overview_PurgeF_Label           matlab.ui.control.Label
        Level_Label_32                  matlab.ui.control.Label
        Overview_PurgeA_Label           matlab.ui.control.Label
        Overview_PurgeE_Label           matlab.ui.control.Label
        Level_Label_31                  matlab.ui.control.Label
        Overview_PurgeD_Label           matlab.ui.control.Label
        Level_Label_30                  matlab.ui.control.Label
        Overview_PurgeC_Label           matlab.ui.control.Label
        Level_Label_29                  matlab.ui.control.Label
        Overview_PurgeB_Label           matlab.ui.control.Label
        Level_Label_28                  matlab.ui.control.Label
        Level_Label_27                  matlab.ui.control.Label
        Overview_ProductH_Label         matlab.ui.control.Label
        Level_Label_26                  matlab.ui.control.Label
        Overview_ProductG_Label         matlab.ui.control.Label
        Level_Label_25                  matlab.ui.control.Label
        Overview_ProductF_Label         matlab.ui.control.Label
        Level_Label_24                  matlab.ui.control.Label
        Overview_ProductE_Label         matlab.ui.control.Label
        Level_Label_23                  matlab.ui.control.Label
        ReactorConcLabel                matlab.ui.control.Label
        Overview_Quality_H_Label        matlab.ui.control.Label
        Level_Label_22                  matlab.ui.control.Label
        Level_Label_21                  matlab.ui.control.Label
        Level_Label_20                  matlab.ui.control.Label
        Level_Label_19                  matlab.ui.control.Label
        Level_Label_18                  matlab.ui.control.Label
        Level_Label_17                  matlab.ui.control.Label
        Overview_Quality_G_Label        matlab.ui.control.Label
        Level_Label_16                  matlab.ui.control.Label
        Level_Label_15                  matlab.ui.control.Label
        Level_Label_14                  matlab.ui.control.Label
        Level_Label_13                  matlab.ui.control.Label
        Level_Label_12                  matlab.ui.control.Label
        Level_Label_11                  matlab.ui.control.Label
        Level_Label_10                  matlab.ui.control.Label
        Level_Label_9                   matlab.ui.control.Label
        Level_Label_8                   matlab.ui.control.Label
        Level_Label_7                   matlab.ui.control.Label
        Overview_FlowC_Label            matlab.ui.control.Label
        Overview_FlowE_Label            matlab.ui.control.Label
        Overview_FlowD_Label            matlab.ui.control.Label
        Overview_FlowA_Label            matlab.ui.control.Label
        Level_Label_6                   matlab.ui.control.Label
        Level_Label_5                   matlab.ui.control.Label
        Level_Label_4                   matlab.ui.control.Label
        PurgeConcLabel                  matlab.ui.control.Label
        Overview_ProductD_Label         matlab.ui.control.Label
        Level_Label_3                   matlab.ui.control.Label
        ProductConcLabel                matlab.ui.control.Label
        Overview_Cost_Label             matlab.ui.control.Label
        Level_Label_2                   matlab.ui.control.Label
        Level_Label                     matlab.ui.control.Label
        Overview_Prod_Label             matlab.ui.control.Label
        Overview_Prod_Sp_Label          matlab.ui.control.Label
        FeedFlowLabel                   matlab.ui.control.Label
        PerformanceLabel                matlab.ui.control.Label
        UIAxes_Overview_Production      matlab.ui.control.UIAxes
        UIAxes_Overview_Cost            matlab.ui.control.UIAxes
        UIAxes_Overview_Quality         matlab.ui.control.UIAxes
        Panel_3                         matlab.ui.container.Panel
        Overview_B_Product_Lamp         matlab.ui.control.Lamp
        Overview_B_FeedC_Lamp           matlab.ui.control.Lamp
        Overview_Strip_Level_Lamp       matlab.ui.control.Lamp
        Overview_Strip_Temp_Lamp        matlab.ui.control.Lamp
        Overview_Strip_Pressure_Lamp    matlab.ui.control.Lamp
        Overview_B_Stripper_Lamp        matlab.ui.control.Lamp
        TgLabel_3                       matlab.ui.control.Label
        Overview_Prod_Sp_Label_4        matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        CLabel_3                        matlab.ui.control.Label
        KpaLabel_3                      matlab.ui.control.Label
        m3hLabel_2                      matlab.ui.control.Label
        Overview_Strip_Level            matlab.ui.control.Label
        LevelLabel_3                    matlab.ui.control.Label
        Overview_Strip_Temp             matlab.ui.control.Label
        TemperatureLabel_3              matlab.ui.control.Label
        Overview_Strip_Pressure         matlab.ui.control.Label
        PressureLabel_3                 matlab.ui.control.Label
        Overview_Strip_Production       matlab.ui.control.Label
        ProductFlowLabel                matlab.ui.control.Label
        StripperLabel_2                 matlab.ui.control.Label
        Image19_3                       matlab.ui.control.Image
        StripperLabel                   matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        UIAxes_Overview_Strip_Temp      matlab.ui.control.UIAxes
        UIAxes_Overview_Strip_Level     matlab.ui.control.UIAxes
        UIAxes_Overview_Strip_Pressure  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        overv_prod;
        overv_quality_g;
        overv_quality_h;
        overv_cost;
        overv_rp;
        overv_rt;
        overv_rl;
        overv_sep_p;
        overv_sep_t;
        overv_sep_l;
        overv_strip_p;
        overv_strip_t;
        overv_strip_l; 
        %Color and style
        lineColor = [0.3010 0.7450 0.9330];
        whiteColor = [1 1 1];
        grayColor = [0.96 0.96 0.96];
        blackColor = [0 0 0];
        redColor = [1 0.27 0.27];
        selectedColor = [0.26 0.27 0.27];
        s_mildred = uistyle('BackgroundColor','#FFA8B0');
        s_red = uistyle('BackgroundColor','#FF4500');
        %Alarm Table 
        alarmTable = [];
        alarmCount = 0;
        activeAlarms = zeros(41,2);
        %Main App
        mainApp;
        %Diagnostic
        fault;
        diagnostic_type;
        firstDiagnosticActive = false;
        secondDiagnosticActive = false;
        thirdDiagnosticActive = false;
        %Fault1 alarms
        fault1_first_Alarms = {'HXmeas1','HXmeas29','HXmeas23','HXmeas33','HXmeas34','HXmeas32','HXmeas30','LXmeas1','LXmeas29','LXmeas23','LXmeas33','LXmeas34','LXmeas32','LXmeas30'};
        fault1_sec_Alarms = {'HXmeas31','HXmeas25','LXmeas31','LXmeas25'};
        fault1_third_Alarms = {'HXmeas38','HXmeas28','HXmeas24','LXmeas38','LXmeas28','LXmeas24'};
        %Fault6 alarms
        fault6_first_Alarms = {'HXmeas1','LXmeas1'};
        fault6_sec_Alarms = {'HXmeas10','HXmeas29','LXmeas10','LXmeas29'};
        fault6_third_Alarms = {'HXmeas33','HXmeas38','HXmeas27','HXmeas31','HXmeas23','HXmeas36','HXmeas39','HXmeas25','HXmeas32','LXmeas33','LXmeas38','LXmeas27','LXmeas31','LXmeas23','LXmeas36','HXmeas39','LXmeas25','LXmeas32'};
    end
    
    methods (Access = public)
        
       function updateOverview(app, simout)
           %KPis
           %axes
           rtoProd = get_param('MultiLoop_mode1/Production Loop Monitor/Production', 'RuntimeObject');
           rtoQuality = get_param('MultiLoop_mode1/Quality Loop Monitor/Quality', 'RuntimeObject');
           rtoCost = get_param('MultiLoop_mode1/$ per hr', 'RuntimeObject');

           %feed
           app.Overview_FlowA_Label.Text = num2str(simout.InputPort(1).Data(1), '%05.2f');
           app.Overview_FlowD_Label.Text = num2str(simout.InputPort(1).Data(2), '%05.2f');
           app.Overview_FlowE_Label.Text = num2str(simout.InputPort(1).Data(3), '%05.2f');
           app.Overview_FlowC_Label.Text = num2str(simout.InputPort(1).Data(4), '%05.2f');
           app.Overview_ReactorFlow_Label.Text = num2str(simout.InputPort(1).Data(6), '%05.2f');

           %Concentration
           app.Overview_ReactorA_Label.Text = num2str(simout.InputPort(1).Data(23), '%05.2f');
           app.Overview_ReactorB_Label.Text = num2str(simout.InputPort(1).Data(24), '%05.2f');
           app.Overview_ReactorC_Label.Text = num2str(simout.InputPort(1).Data(25), '%05.2f');
           app.Overview_ReactorD_Label.Text = num2str(simout.InputPort(1).Data(26), '%05.2f'); 
           app.Overview_ReactorE_Label.Text = num2str(simout.InputPort(1).Data(27), '%05.2f'); 
           app.Overview_ReactorF_Label.Text = num2str(simout.InputPort(1).Data(28), '%05.2f'); 

           app.Overview_PurgeA_Label.Text = num2str(simout.InputPort(1).Data(29), '%05.2f');
           app.Overview_PurgeB_Label.Text = num2str(simout.InputPort(1).Data(30), '%05.2f'); 
           app.Overview_PurgeC_Label.Text = num2str(simout.InputPort(1).Data(31), '%05.2f');
           app.Overview_PurgeD_Label.Text = num2str(simout.InputPort(1).Data(32), '%05.2f'); 
           app.Overview_PurgeE_Label.Text = num2str(simout.InputPort(1).Data(33), '%05.2f'); 
           app.Overview_PurgeF_Label.Text = num2str(simout.InputPort(1).Data(34), '%05.2f'); 
           app.Overview_PurgeG_Label.Text = num2str(simout.InputPort(1).Data(35), '%05.2f');
           app.Overview_PurgeH_Label.Text = num2str(simout.InputPort(1).Data(36), '%05.2f');

           app.Overview_ProductD_Label.Text = num2str(simout.InputPort(1).Data(37), '%05.2f');
           app.Overview_ProductE_Label.Text = num2str(simout.InputPort(1).Data(38), '%05.2f'); 
           app.Overview_ProductF_Label.Text = num2str(simout.InputPort(1).Data(39), '%05.2f'); 
           app.Overview_ProductG_Label.Text = num2str(simout.InputPort(1).Data(40), '%05.2f'); 
           app.Overview_ProductH_Label.Text = num2str(simout.InputPort(1).Data(41), '%05.2f'); 
           
           %Production label
           app.Overview_Prod_Sp_Label.Text = num2str(rtoProd.InputPort(1).Data(1), '%05.2f');
           app.Overview_Prod_Label.Text = num2str(rtoProd.InputPort(1).Data(2), '%05.2f');
           %Quality label
           app.Overview_Quality_H_Label.Text = num2str(simout.InputPort(1).Data(41), '%05.2f');
           app.Overview_Quality_G_Label.Text = num2str(rtoQuality.InputPort(1).Data(2), '%05.2f');
           %$ per hour label
           app.Overview_Cost_Label.Text = num2str(rtoCost.InputPort(1).Data, '%05.2f');

           %Bottom label
           app.Overview_TotalFeed.Text = num2str(simout.InputPort(1).Data(6), '%05.2f');
           app.Overview_Sep_Underflow.Text = num2str(simout.InputPort(1).Data(14), '%05.2f');
           app.Overview_Strip_Production.Text = num2str(simout.InputPort(1).Data(17), '%05.2f');

           %Reactor, Separator and Stripper
           app.Overview_React_Pressure.Text = num2str(simout.InputPort(1).Data(7), '%05.2f');
           app.Overview_React_Temp.Text = num2str(simout.InputPort(1).Data(9), '%05.2f');
           app.Overview_React_Level.Text = num2str(simout.InputPort(1).Data(8), '%05.2f');

           app.Overview_Sep_Pressure.Text = num2str(simout.InputPort(1).Data(13), '%05.2f');
           app.Overview_Sep_Temp.Text = num2str(simout.InputPort(1).Data(11), '%05.2f');
           app.Overview_Sep_Level.Text = num2str(simout.InputPort(1).Data(12), '%05.2f');

           app.Overview_Strip_Pressure.Text = num2str(simout.InputPort(1).Data(16), '%05.2f');
           app.Overview_Strip_Temp.Text = num2str(simout.InputPort(1).Data(18), '%05.2f');
           app.Overview_Strip_Level.Text = num2str(simout.InputPort(1).Data(15), '%05.2f');
           
           if(simout.CurrentTime <= 0)
               %Kpis
               app.overv_prod = animatedline(app.UIAxes_Overview_Production, 'Color','#2c449a','LineWidth',2);
               app.overv_quality_g = animatedline(app.UIAxes_Overview_Quality, 'Color','#2c449a','LineWidth',2);
               app.overv_quality_h = animatedline(app.UIAxes_Overview_Quality, 'Color','#2c449a','LineWidth',2);
               app.overv_cost = animatedline(app.UIAxes_Overview_Cost, 'Color','#2c449a','LineWidth',2); 
  
               %Reactor
               app.overv_rp = animatedline(app.UIAxes_Overview_React_Pressure, 'Color','#5F93E5','LineWidth',2);
               app.overv_rt = animatedline(app.UIAxes_Overview_React_Temp, 'Color','#5F93E5','LineWidth',2);
               app.overv_rl = animatedline(app.UIAxes_Overview_React_Level, 'Color','#5F93E5','LineWidth',2);
               
               %Separator
               app.overv_sep_p = animatedline(app.UIAxes_Overview_Sep_Pressure, 'Color','#5F93E5','LineWidth',2);
               app.overv_sep_t = animatedline(app.UIAxes_Overview_Sep_Temp, 'Color','#5F93E5','LineWidth',2);
               app.overv_sep_l = animatedline(app.UIAxes_Overview_Sep_Level, 'Color','#5F93E5','LineWidth',2);
    
               %Stripper
               app.overv_strip_p = animatedline(app.UIAxes_Overview_Strip_Pressure, 'Color','#5F93E5','LineWidth',2);
               app.overv_strip_t = animatedline(app.UIAxes_Overview_Strip_Temp, 'Color','#5F93E5','LineWidth',2);
               app.overv_strip_l = animatedline(app.UIAxes_Overview_Strip_Level, 'Color','#5F93E5','LineWidth',2);  
           else
                t =  datetime('now'); 
                tDif = datenum([t-seconds(240) t]);
                stDif = datestr(tDif,'HH:MM');

                %KPis  
                %Production
                addpoints(app.overv_prod,datenum(t),rtoProd.InputPort(1).Data(2));
                app.UIAxes_Overview_Production.XLim = tDif;
                %datetick(app.UIAxes_Overview_Production,'x','keeplimits');
                app.UIAxes_Overview_Production.XTick = tDif;
                app.UIAxes_Overview_Production.XTickLabel = stDif;
                
                %Quality
                addpoints(app.overv_quality_g,datenum(t),rtoQuality.InputPort(1).Data(2));
                addpoints(app.overv_quality_h,datenum(t),simout.InputPort(1).Data(41));
                app.UIAxes_Overview_Quality.XLim = tDif;
                app.UIAxes_Overview_Quality.XTick = tDif;
                app.UIAxes_Overview_Quality.XTickLabel = stDif;
                
                %Cost
                addpoints(app.overv_cost,datenum(t),rtoCost.InputPort(1).Data);
                app.UIAxes_Overview_Cost.XLim = tDif;
                app.UIAxes_Overview_Cost.XTick = tDif;
                app.UIAxes_Overview_Cost.XTickLabel = stDif;
                
                %Reactor
                %Pression
                addpoints(app.overv_rp,datenum(t),simout.InputPort(1).Data(7));
                app.UIAxes_Overview_React_Pressure.XLim = tDif;
                app.UIAxes_Overview_React_Pressure.XTick = tDif;
                app.UIAxes_Overview_React_Pressure.XTickLabel = stDif;
                
                %temperature
                addpoints(app.overv_rt,datenum(t),simout.InputPort(1).Data(9))
                app.UIAxes_Overview_React_Temp.XLim = tDif;
                app.UIAxes_Overview_React_Temp.XTick = tDif;
                app.UIAxes_Overview_React_Temp.XTickLabel = stDif;

                %level
                addpoints(app.overv_rl,datenum(t),simout.InputPort(1).Data(8))
                app.UIAxes_Overview_React_Level.XLim = tDif;
                app.UIAxes_Overview_React_Level.XTick = tDif;
                app.UIAxes_Overview_React_Level.XTickLabel = stDif;

                %Separator
                %Pression
                addpoints(app.overv_sep_p,datenum(t),simout.InputPort(1).Data(13));
                app.UIAxes_Overview_Sep_Pressure.XLim = tDif;
                app.UIAxes_Overview_Sep_Pressure.XTick = tDif;
                app.UIAxes_Overview_Sep_Pressure.XTickLabel = stDif;
                
                %temperature
                addpoints(app.overv_sep_t,datenum(t),simout.InputPort(1).Data(11))
                app.UIAxes_Overview_Sep_Temp.XLim = tDif;
                app.UIAxes_Overview_Sep_Temp.XTick = tDif;
                app.UIAxes_Overview_Sep_Temp.XTickLabel = stDif;

                %level
                addpoints(app.overv_sep_l,datenum(t),simout.InputPort(1).Data(12))
                app.UIAxes_Overview_Sep_Level.XLim = tDif;
                app.UIAxes_Overview_Sep_Level.XTick = tDif;
                app.UIAxes_Overview_Sep_Level.XTickLabel = stDif;

                %Stripper
                %Pression
                addpoints(app.overv_strip_p,datenum(t),simout.InputPort(1).Data(16));
                app.UIAxes_Overview_Strip_Pressure.XLim = tDif;
                app.UIAxes_Overview_Strip_Pressure.XTick = tDif;
                app.UIAxes_Overview_Strip_Pressure.XTickLabel = stDif;
                
                %temperature
                addpoints(app.overv_strip_t,datenum(t),simout.InputPort(1).Data(18))
                app.UIAxes_Overview_Strip_Temp.XLim = tDif;
                app.UIAxes_Overview_Strip_Temp.XTick = tDif;
                app.UIAxes_Overview_Strip_Temp.XTickLabel = stDif;

                %level
                addpoints(app.overv_strip_l,datenum(t),simout.InputPort(1).Data(15))
                app.UIAxes_Overview_Strip_Level.XLim = tDif;
                app.UIAxes_Overview_Strip_Level.XTick = tDif;
                app.UIAxes_Overview_Strip_Level.XTickLabel = stDif;
           end
       end

       function initializeOverview(app)
            %Production and quality  %Setpoint 
            ps = yline(app.UIAxes_Overview_Production,22.89,'LineWidth',1);
            ps.Color = [0.31 0.77 0.28];

            ps = yline(app.UIAxes_Overview_Quality,53.8,'LineWidth',1);
            ps.Color = [0.31 0.77 0.28];

            ps = yline(app.UIAxes_Overview_Quality,43.95,'LineWidth',1);
            ps.Color = [0.31 0.77 0.28];
            
            %Reactor
            %Setpoint 
            ps = yline(app.UIAxes_Overview_React_Pressure,2800,'LineWidth',1.5);
            ps.Color = [0.3010 0.7450 0.9330];

            ts =  yline(app.UIAxes_Overview_React_Temp,122.9,'LineWidth',1.5);
            ts.Color = [0.3010 0.7450 0.9330];

            ls =  yline(app.UIAxes_Overview_React_Level,65,'LineWidth',1.5);
            ls.Color = [0.3010 0.7450 0.9330];

            %Blue box
            ax = app.UIAxes_Overview_React_Pressure;
            limY = [0 0 2895 2895];
            app.drawBlueBox(ax,limY,0);
 
            ax = app.UIAxes_Overview_React_Temp;
            limY = [0 0 150 150];
            app.drawBlueBox(ax,limY,0);
 
            ax = app.UIAxes_Overview_React_Level;
            limY = [50 50 100 100];
            app.drawBlueBox(ax,limY,0); 

            %Separator
            %Setpoint
            s_press = yline(app.UIAxes_Overview_Sep_Pressure,2704.54,'LineWidth',1.5);
            s_press.Color = app.lineColor;
            
            s_temp = yline(app.UIAxes_Overview_Sep_Temp,92.02,'LineWidth',1.5);
            s_temp.Color = app.lineColor;
            
            s_level = yline(app.UIAxes_Overview_Sep_Level,50,'LineWidth',1.5);
            s_level.Color = app.lineColor;
            
            %Blue box 
            ax = app.UIAxes_Overview_Sep_Pressure;
            limY = [2434.09 2434.09 2974.99 2974.99];
            app.drawBlueBox(ax,limY,0);

            ax = app.UIAxes_Overview_Sep_Temp;
            limY = [82.82 82.82 101.22 101.22];
            app.drawBlueBox(ax,limY,0);

            ax = app.UIAxes_Overview_Sep_Level;
            limY = [30 30 100 100];
            app.drawBlueBox(ax,limY,0);

            %Stripper
            %Setpoint
            strip_press_s = yline(app.UIAxes_Overview_Strip_Pressure,3332.05 ,'LineWidth',1.5);
            strip_press_s.Color = app.lineColor;
            
            strip_temp_s = yline(app.UIAxes_Overview_Strip_Temp,66.64,'LineWidth',1.5);
            strip_temp_s.Color = app.lineColor;
            
            strip_level_s = yline(app.UIAxes_Overview_Strip_Level,50,'LineWidth',1.5);
            strip_level_s.Color = app.lineColor;

            %Blue box 
            ax = app.UIAxes_Overview_Strip_Pressure;
            limY = [2998.85 2998.85 3665.255 3665.255 ];
            app.drawBlueBox(ax,limY,0);

            ax = app.UIAxes_Overview_Strip_Temp;
            limY = [59.98 59.98 73.3 73.3];
            app.drawBlueBox(ax,limY,0);

            ax = app.UIAxes_Overview_Strip_Level;
            limY = [30 30 100 100];
            app.drawBlueBox(ax,limY,0);

            %Table Alarms
            uit = app.UITable_Alarms;
            uit.ColumnWidth = {'1x','2x','2x','5x','2x'};
        end
  
       function drawBlueBox(~,ax,limY,axisType)
            limX = [datenum(datetime('now')-seconds(240)) datenum(datetime('now')+minutes(240)) datenum(datetime('now')+minutes(240)) datenum(datetime('now')-seconds(240))];
            box = patch(ax,limX,limY,'-');
            
            %0 = big graph, 1 = small graph
            if(axisType == 0)
                box.FaceColor = [0.3010 0.7450 0.9330];
                box.FaceAlpha = .1;
            else
                box.FaceColor = [0.72,0.89,0.97];
            end
    
            box.EdgeColor = 'none';
       end

       %------------------------Alarms-------------------------------------------%
       function checkEmptyAlarmsTable(app)
            if(app.alarmCount == 1)
                app.NoAlarmsNotificationsLabel.Visible = false;
            else
                app.NoAlarmsNotificationsLabel.Visible = true;

                if(app.firstDiagnosticActive == true)
                  app.firstDiagnosticActive = false;
                  app.secondDiagnosticActive = false;
                  app.thirdDiagnosticActive = false;
                  app.Diagnostic_Fault_Label.Text = 'None';
                  %app.Diagnostic_Solution_Label.Text = 'None';
                  app.Show_Alarms_Button_F1.Value = 0;
                  app.Show_Alarms_Button_F2.Value = 0;
                  app.Show_Alarms_Button_F3.Value = 0;
                  app.Show_Alarms_Button_F1.Visible = false;
                  app.Show_Alarms_Button_F2.Visible = false;
                  app.Show_Alarms_Button_F3.Visible = false;
               end
            end
        end
       
       function [highlight,currentFault_alarmList] = getCurrentFaultAlarms(app)
            highlight = false;
            currentFault_alarmList = {};
            if(app.Show_Alarms_Button_F1.Value == 1)
                if(app.fault == 1)
                    currentFault_alarmList = app.fault1_first_Alarms;
                elseif(app.fault == 6)
                    currentFault_alarmList = app.fault6_first_Alarms;
                end
                highlight = true;
            elseif(app.Show_Alarms_Button_F2.Value == 1)
                if(app.fault == 1)
                    currentFault_alarmList = app.fault1_sec_Alarms;
                elseif(app.fault == 6)
                    currentFault_alarmList = app.fault6_sec_Alarms;
                end
                highlight = true;
            elseif(app.Show_Alarms_Button_F3.Value == 1)
                if(app.fault == 1)
                    currentFault_alarmList = app.fault1_third_Alarms;
                elseif(app.fault == 6)
                    currentFault_alarmList = app.fault6_third_Alarms;
                end
                highlight = true;
            end
       end
       
       function addAlarmToTable(app,newRow)
            app.alarmTable = [app.alarmTable; newRow];
            set(app.UITable_Alarms,'Data',app.alarmTable);
            
            s = app.s_mildred;

            %Alarms highlights
            [highlight,currentFault_alarmList] = getCurrentFaultAlarms(app);

            %check if newRow shoudl be highlighted
            if(highlight == true)
                if(any(strcmp(currentFault_alarmList,newRow{2})))
                    s = app.s_red;
                end
            end

            addStyle(app.UITable_Alarms,s,'row',height(app.alarmTable));

            [y,Fs] = audioread('newAlarm2.wav');
            sound(y,Fs);
        end
        
       function checkAlarmTable(app, mainApp, high, low, alarmIndex, descr, source)
            if (high && ~app.activeAlarms(alarmIndex,1) == 1 || low && ~app.activeAlarms(alarmIndex,2) == 1 )
               %add alarm
               if(high)
                   id_ = strcat('HXmeas', num2str(alarmIndex));
                   app.activeAlarms(alarmIndex,1) = 1; id = id_;
                   descr=strcat('High', {' '}, descr);
               else
                   id_ = strcat('LXmeas', num2str(alarmIndex));
                   app.activeAlarms(alarmIndex,2) = 1; id = id_;
                   descr=strcat('Low', {' '}, descr);
               end
               newRow = {false, id, datestr(now,'HH:MM:SS'), descr, source};
               addAlarmToTable(app,newRow);
               app.alarmCount = app.alarmCount + 1;
               app.AlarmsCount_Label.Text =  num2str(app.alarmCount);
               setAlarmLamp(app,mainApp,alarmIndex,true);
           elseif (~high && app.activeAlarms(alarmIndex,1) == 1 || ~low && app.activeAlarms(alarmIndex,2) == 1)
               %remove alarm
               if(app.activeAlarms(alarmIndex,1) == 1)
                   id_ = strcat('HXmeas', num2str(alarmIndex));
                   app.alarmTable(strcmp(app.alarmTable.id,id_),:) = [];
                   set(app.UITable_Alarms,'Data',app.alarmTable);
                   app.activeAlarms(alarmIndex,1) = 0; 
               else
                   id_ = strcat('LXmeas', num2str(alarmIndex));
                   app.alarmTable(strcmp(app.alarmTable.id,id_),:) = [];
                   set(app.UITable_Alarms,'Data',app.alarmTable);
                   app.activeAlarms(alarmIndex,2) = 0; 
               end
               %reorgonize alarms row color
               [highlight,currentFault_alarmList] = getCurrentFaultAlarms(app);
                
               %check if newRow shoudl be highlighted
               if(highlight == true)
                    for row = 2:height(app.alarmTable)
                       exist = any(strcmp(currentFault_alarmList,app.alarmTable(row,:).id));
                       if(exist == true)
                           s = app.s_red;
                           addStyle(app.UITable_Alarms,s,'row',row);
                       else
                           s = app.s_mildred;
                           addStyle(app.UITable_Alarms,s,'row',row);
                       end
                   end
               end
             
               app.alarmCount = app.alarmCount - 1;
               app.AlarmsCount_Label.Text =  num2str(app.alarmCount);
               setAlarmLamp(app,mainApp,alarmIndex,false);
           end 
        end

       function setDiagnosticTool(app)
            if(app.firstDiagnosticActive == false)
                 app.Show_Alarms_Button_F1.Visible = true;
                 app.Show_Alarms_Button_F2.Visible = true;
                 app.Show_Alarms_Button_F3.Visible = true;
                 app.firstDiagnosticActive = true;
            end
            
               if (app.fault==1 && app.diagnostic_type==1)
               if(app.alarmCount>=3 && app.alarmCount<6)
                   app.Diagnostic_Fault_Label.Text = 'Low Flow Feed of A';
                   app.Diagnostic_Fault_Perc_Label.Text = '(33%)';
                   app.Diagnostic_Fault2_Label.Text = 'Low Flow Feed of C';
                   app.Diagnostic_Fault2_Perc_Label.Text = '(67%)';
                   app.Diagnostic_Fault3_Label.Text = ' ';
                   app.Diagnostic_Fault3_Perc_Label.Text = ' ';
                   app.Show_Alarms_Button_F3.Visible = false;
               elseif(app.alarmCount>=6 && app.alarmCount<9)
                    app.Diagnostic_Fault_Label.Text = 'Low Flow Feed of A';
                    app.Diagnostic_Fault_Perc_Label.Text = '(50%)';
                    app.Diagnostic_Fault2_Label.Text = 'Low Flow Feed of C';
                    app.Diagnostic_Fault2_Perc_Label.Text = '(33%)';     
                    app.Diagnostic_Fault3_Label.Text = 'High Concentration Product E';
                    app.Diagnostic_Fault3_Perc_Label.Text = '(17%)';
                    app.secondDiagnosticActive = true;
                    app.Show_Alarms_Button_F3.Visible = true;
               elseif(app.alarmCount>=9 && app.alarmCount<12)
                    app.Diagnostic_Fault_Label.Text = 'Low Flow Feed of A';
                    app.Diagnostic_Fault_Perc_Label.Text = '(67%)';
                    app.Diagnostic_Fault2_Label.Text = ' ';
                    app.Diagnostic_Fault2_Perc_Label.Text = ' ';
                    app.Diagnostic_Fault3_Label.Text = 'High Reactor Products Concentration';
                    app.Diagnostic_Fault3_Perc_Label.Text = '(33%)';
                    app.Show_Alarms_Button_F2.Visible = false;
                    app.thirdDiagnosticActive = true;
               elseif(app.alarmCount>=12 && app.alarmCount<41)
               app.Diagnostic_Fault_Label.Text = 'Low Flow Feed of A';
               app.Diagnostic_Fault_Perc_Label.Text = '(90%)';
               app.Diagnostic_Fault2_Label.Text = ' ';
               app.Diagnostic_Fault2_Perc_Label.Text = ' ';
               app.Diagnostic_Fault3_Label.Text = ' ';
               app.Diagnostic_Fault3_Perc_Label.Text = ' ';
               app.Show_Alarms_Button_F2.Visible = false;
               app.Show_Alarms_Button_F3.Visible = false;
               end
               end
                    %app.Diagnostic_Solution_Label.Text = 'Increase Gradually the value of Valve A in the Reactor';
            if(app.fault == 6 && app.diagnostic_type == 1)
                if(app.alarmCount >= 3 && app.alarmCount<6)
                    app.Diagnostic_Fault_Label.Text = 'Low Flow Feed of A';
                    app.Diagnostic_Fault_Perc_Label.Text = '(33%)';
                    app.Diagnostic_Fault2_Label.Text = 'High Product C to Purge';
                    app.Diagnostic_Fault2_Perc_Label.Text = '(67%)';
                    app.Diagnostic_Fault3_Label.Text = ' ';
                    app.Diagnostic_Fault3_Perc_Label.Text = ' ';
                    app.Show_Alarms_Button_F3.Visible = false;
                elseif(app.alarmCount >= 6 && app.alarmCount<9)
                    app.Diagnostic_Fault_Label.Text = 'Low Flow Feed of A';
                    app.Diagnostic_Fault_Perc_Label.Text = '(17%)';
                    app.Diagnostic_Fault2_Label.Text = 'High Product C to Purge';
                    app.Diagnostic_Fault2_Perc_Label.Text = '(33%)';
                    app.Diagnostic_Fault3_Label.Text = 'A Feed Loss';
                    app.Diagnostic_Fault3_Perc_Label.Text = '(50%)';
                    app.secondDiagnosticActive = true;
                    app.Show_Alarms_Button_F3.Visible = true;
                elseif(app.alarmCount >= 9 && app.alarmCount<12)
                    app.Diagnostic_Fault_Label.Text = ' ';
                    app.Diagnostic_Fault_Perc_Label.Text = ' ';
                    app.Diagnostic_Fault2_Label.Text = 'High Product C to Purge';
                    app.Diagnostic_Fault2_Perc_Label.Text = '(22%)';
                    app.Diagnostic_Fault3_Label.Text = 'A Feed Loss';
                    app.Diagnostic_Fault3_Perc_Label.Text = '(78%)';
                    app.thirdDiagnosticActive = true;
                    app.Show_Alarms_Button_F1.Visible = false;
                elseif(app.alarmCount >= 12)
                    app.Diagnostic_Fault_Label.Text = ' ';
                    app.Diagnostic_Fault_Perc_Label.Text = ' ';
                    app.Diagnostic_Fault2_Label.Text = ' ';
                    app.Diagnostic_Fault2_Perc_Label.Text = ' ';
                    app.Diagnostic_Fault3_Label.Text = 'A Feed Loss';
                    app.Diagnostic_Fault3_Perc_Label.Text = '(90%)';
                    app.Show_Alarms_Button_F2.Visible = false;
                    app.Show_Alarms_Button_F1.Visible = false;
                    %app.Diagnostic_Solution_Label.Text = 'Re-establish A Feed by Gradually Increasing the value of Valve A in the Reactor';
                end  
            end

            if(app.fault == 1 && app.diagnostic_type == 2)
                app.Diagnostic_Fault_Label.Text = 'C Feed Loss';
                %app.Diagnostic_Solution_Label.Text = 'Increase Gradually the value of Valve C in the Stripper';
            elseif(app.fault == 6 && app.diagnostic_type == 2)
                app.Diagnostic_Fault_Label.Text = 'C Feed Loss';
                %app.Diagnostic_Solution_Label.Text = 'Increase Gradually the value of Valve C in the Stripper';
            elseif(app.fault == 1 && app.diagnostic_type == 3)
                app.Diagnostic_Fault_Label.Text = 'Unknown';
                app.Show_Alarms_Button_F1.Visible = false;
                app.Show_Alarms_Button_F2.Visible = false;
                app.Show_Alarms_Button_F3.Visible = false;
                %app.Diagnostic_Solution_Label.Text = 'Unknown';
            elseif(app.fault == 6 && app.diagnostic_type == 3)
                app.Diagnostic_Fault_Label.Text = 'Unknown';
                app.Show_Alarms_Button_F1.Visible = false;
                app.Show_Alarms_Button_F2.Visible = false;
                app.Show_Alarms_Button_F3.Visible = false;
                %app.Diagnostic_Solution_Label.Text = 'Unknown';
            elseif(app.fault == 7 && app.diagnostic_type == 1)
                app.Diagnostic_Fault_Label.Text = 'C header pressure loss';
                %app.Diagnostic_Solution_Label.Text = 'Unknown';
            elseif(app.fault == 2 && app.diagnostic_type == 1)
                app.Diagnostic_Fault_Label.Text = 'B Composition, A/C Ratio constant';
                %app.Diagnostic_Solution_Label.Text = 'Unknown';
            end
       end

       function diagnosticToolAlarmsHighlight(app, value,currentFault_alarmList)
           %When alarms button are clicked
           if(value == 1)
                s = app.s_mildred;
                addStyle(app.UITable_Alarms,s,'table','');

                for i = 1:length(currentFault_alarmList)
                    index = find(strcmp(app.alarmTable.id, currentFault_alarmList{i}) == 1);
                    if(index > 0)
                        s = app.s_red;
                        addStyle(app.UITable_Alarms,s,'row',index);
                    end
                end    
            else
                s = app.s_mildred;
                addStyle(app.UITable_Alarms,s,'table','');
            end
       end 

       function refreshAlarms(app,mainApp,simout)
           high = simout.InputPort(1).Data(1) >= 0.3; low = simout.InputPort(1).Data(1) <= 0.24;
           checkAlarmTable(app, mainApp, high, low, 1, 'Flow Feed A', 'Feed Reactor');
           
           high = simout.InputPort(1).Data(2) >= 4020.5; low = simout.InputPort(1).Data(2) <= 3289.5;
           checkAlarmTable(app, mainApp,  high, low, 2, 'Flow Feed D', 'Feed Reactor');

           high = simout.InputPort(1).Data(3) >= 4886.2; low = simout.InputPort(1).Data(3) <= 3997.8;
           checkAlarmTable(app, mainApp,  high, low, 3, 'Flow Feed E', 'Feed Reactor');

           high = simout.InputPort(1).Data(4) >= 10.16; low = simout.InputPort(1).Data(4) <= 8.32;
           checkAlarmTable(app, mainApp,  high, low, 4, 'Flow Feed C', 'Stripper');

           high = simout.InputPort(1).Data(7) >= 2895; low = simout.InputPort(1).Data(7) <= 0;
           checkAlarmTable(app, mainApp,  high, low, 7, 'Reactor Pressure', 'Reactor');
           
           high = simout.InputPort(1).Data(9) >= 150; low = simout.InputPort(1).Data(9) <= 0;
           checkAlarmTable(app, mainApp,  high, low, 9, 'Reactor Temperature', 'Reactor');
           
           high = simout.InputPort(1).Data(8) >= 100; low = simout.InputPort(1).Data(8) <= 50;
           checkAlarmTable(app, mainApp,  high, low, 8, 'Reactor Level', 'Reactor');

           high = simout.InputPort(1).Data(6) >= 52.36; low = simout.InputPort(1).Data(6) <= 42.84;
           checkAlarmTable(app, mainApp,  high, low, 6, 'Reactor Feed', 'Feed Reactor');
           
           high = simout.InputPort(1).Data(5) >= 35.66; low = simout.InputPort(1).Data(5) <= 29.18 ;
           checkAlarmTable(app, mainApp,  high, low, 5, 'Recycle Flow', 'Compressor');

           high = simout.InputPort(1).Data(21) >= 112.72; low = simout.InputPort(1).Data(21) <= 92.22 ;
           checkAlarmTable(app, mainApp,  high, low, 21, 'Temperature Cooling Water Outlet', 'Reactor');

           %Reactor Concentration 
           high = simout.InputPort(1).Data(23) >= 35.42; low = simout.InputPort(1).Data(23) <= 28.98;
           checkAlarmTable(app, mainApp,  high, low, 23, 'Concentration Feed A To Reactor', 'Reactor Conc.');

           high = simout.InputPort(1).Data(24) >= 16.38; low = simout.InputPort(1).Data(24) <= 13.4;
           checkAlarmTable(app, mainApp,  high, low, 24, 'Concentration Feed B To Reactor', 'Reactor Conc.');

           high = simout.InputPort(1).Data(25) >= 20.68; low = simout.InputPort(1).Data(25) <= 16.92;
           checkAlarmTable(app, mainApp,  high, low, 25, 'Concentration Feed C To Reactor', 'Reactor Conc.');

           high = simout.InputPort(1).Data(26) >= 6.59; low = simout.InputPort(1).Data(26) <= 5.39;
           checkAlarmTable(app, mainApp,  high, low, 26, 'Concentration Feed D To Reactor', 'Reactor Conc.');

           high = simout.InputPort(1).Data(27) >= 18.3; low = simout.InputPort(1).Data(27) <= 14.98;
           checkAlarmTable(app, mainApp,  high, low, 27, 'Concentration Feed E To Reactor', 'Reactor Conc.');

           high = simout.InputPort(1).Data(28) >= 4.46; low = simout.InputPort(1).Data(28) <= 3.65;
           checkAlarmTable(app, mainApp,  high, low, 28, 'Concentration Feed F To Reactor', 'Reactor Conc.');

           %Sep-Cond
           high = simout.InputPort(1).Data(13) >= 2974.99; low = simout.InputPort(1).Data(13) <= 2434.09 ;
           checkAlarmTable(app, mainApp,  high, low, 13, 'Separator Pressure', 'Separator');

           high = simout.InputPort(1).Data(11) >= 101.22; low = simout.InputPort(1).Data(11) <= 82.82;
           checkAlarmTable(app, mainApp,  high, low, 11, 'Separator Temperature', 'Separator');

           high = simout.InputPort(1).Data(12) >= 100; low = simout.InputPort(1).Data(12) <= 30;
           checkAlarmTable(app, mainApp,  high, low, 12, 'Separator Level', 'Separator');

           high = simout.InputPort(1).Data(14) >= 27.79; low = simout.InputPort(1).Data(14) <= 22.73;
           checkAlarmTable(app, mainApp,  high, low, 14, 'Underflow Liquid', 'Separator');
 
           high = simout.InputPort(1).Data(22) >= 101.6; low = simout.InputPort(1).Data(22) <= 83.12;
           checkAlarmTable(app, mainApp,  high, low, 22, 'Temperature Cooling Water Outlet', 'Condenser');

           %Purge
           high = simout.InputPort(1).Data(10) >= 0.65; low = simout.InputPort(1).Data(10) <= 0.05;
           checkAlarmTable(app, mainApp,  high, low, 10, 'Flow Purge', 'Purge');

           %Purge Concentration 
           high = simout.InputPort(1).Data(29) >= 35.97; low = simout.InputPort(1).Data(29) <= 29.43;
           checkAlarmTable(app, mainApp,  high, low, 29, 'Concentration A To Purge', 'Purge Conc.');

           high = simout.InputPort(1).Data(30) >= 23.89; low = simout.InputPort(1).Data(30) <= 19.55;
           checkAlarmTable(app, mainApp,  high, low, 30, 'Concentration B To Purge', 'Purge Conc.');

           high = simout.InputPort(1).Data(31) >= 14.54; low = simout.InputPort(1).Data(31) <= 11.90;
           checkAlarmTable(app, mainApp,  high, low, 31, 'Concentration C To Purge', 'Purge Conc.');

           high = simout.InputPort(1).Data(32) >= 1.25; low = simout.InputPort(1).Data(32) <= 0.45;
           checkAlarmTable(app, mainApp,  high, low, 32, 'Concentration D To Purge', 'Purge Conc.');

           high = simout.InputPort(1).Data(33) >= 17.72; low = simout.InputPort(1).Data(33) <= 14.5;
           checkAlarmTable(app, mainApp,  high, low, 33, 'Concentration E To Purge', 'Purge Conc.');

           high = simout.InputPort(1).Data(34) >= 5.94; low = simout.InputPort(1).Data(34) <= 4.86;
           checkAlarmTable(app, mainApp,  high, low, 34, 'Concentration F To Purge', 'Purge Conc.');

           high = simout.InputPort(1).Data(35) >= 7.35; low = simout.InputPort(1).Data(35) <= 6.01;
           checkAlarmTable(app, mainApp,  high, low, 35, 'Concentration G To Purge', 'Purge Conc.');

           high = simout.InputPort(1).Data(36) >= 3.59; low = simout.InputPort(1).Data(36) <= 2.93;
           checkAlarmTable(app, mainApp,  high, low, 36, 'Concentration H To Purge', 'Purge Conc.');

           %Compressor
           high = simout.InputPort(1).Data(20) >= 300.32; low = simout.InputPort(1).Data(20) <= 245.72;
           checkAlarmTable(app, mainApp,  high, low, 20, 'Compressor Work', 'Compressor');

           %Stripper
           high = simout.InputPort(1).Data(16) >= 3665.255; low = simout.InputPort(1).Data(16) <=  2998.85;
           checkAlarmTable(app, mainApp,  high, low, 16, 'Stripper Pressure', 'Stripper');

           high = simout.InputPort(1).Data(18) >= 73.3; low = simout.InputPort(1).Data(18) <= 59.98;
           checkAlarmTable(app, mainApp,  high, low, 18, 'Stripper Temperature', 'Stripper');

           high = simout.InputPort(1).Data(15) >= 100; low = simout.InputPort(1).Data(15) <= 30;
           checkAlarmTable(app, mainApp,  high, low, 15, 'Stripper Level', 'Stripper');
        
           %Steam
           %high = simout.InputPort(1).Data(19) >= 100; low = simout.InputPort(1).Data(19) <= 30;
           %checkAlarmTable(app, mainApp,  high, low, 19, 'Stripper Level', 'Stripper');

           %product
           high = simout.InputPort(1).Data(17) >= 25.18; low = simout.InputPort(1).Data(17) <= 20.6;
           checkAlarmTable(app, mainApp,  high, low, 17, 'Product Flow', 'Product');

           high = simout.InputPort(1).Data(37) >= 1; low = simout.InputPort(1).Data(37) <= -1;
           checkAlarmTable(app, mainApp,  high, low, 37, 'Concentration D In Product', 'Product Conc.');

           high = simout.InputPort(1).Data(38) >= 0.63; low = simout.InputPort(1).Data(38) <= 0.51;
           checkAlarmTable(app, mainApp,  high, low, 38, 'Concentration E In Product', 'Product Conc.');

           high = simout.InputPort(1).Data(39) >= 0.23; low = simout.InputPort(1).Data(39) <= 0.15;
           checkAlarmTable(app, mainApp,  high, low, 39, 'Concentration F In Product', 'Product Conc.');

           high = simout.InputPort(1).Data(40) >= 59.18; low = simout.InputPort(1).Data(40) <= 48.42;
           checkAlarmTable(app, mainApp,  high, low, 40, 'Concentration G In Product', 'Product Conc.');

           high = simout.InputPort(1).Data(41) >= 48.35; low = simout.InputPort(1).Data(41) <= 39.56;
           checkAlarmTable(app, mainApp,  high, low, 41, 'Concentration H In Product', 'Product Conc.');

           %Check when first alarm to remove text or none to add text
           if(app.alarmCount == 0 && app.NoAlarmsNotificationsLabel.Visible == false || app.alarmCount == 1 && app.NoAlarmsNotificationsLabel.Visible == true) 
             checkEmptyAlarmsTable(app);
           end
           
           if(app.alarmCount >= 3 && app.alarmCount<6 && app.firstDiagnosticActive == false || app.alarmCount >= 6 && app.alarmCount<9 && app.secondDiagnosticActive == false|| app.alarmCount >= 9 && app.alarmCount<12 && app.thirdDiagnosticActive == false || app.alarmCount >= 12 && app.alarmCount<41)
             setDiagnosticTool(app);
           end
        end
    
       function setAlarmLamp(app, mainApp, alarmIndex, bl)
            switch alarmIndex
                case 1
                    if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Feed Reactor')))
                            if(~any(strcmp(app.alarmTable.source,'Reactor Conc.')))
                                app.Overview_B_Feed_Lamp.Visible = bl;
                                app.Overview_B_Feed_Lamp.Enable = bl;
                            end
                            app.Overview_Feed_Lamp.Visible = bl;
                            app.Overview_Feed_Lamp.Enable = bl;
                        end
                        app.Overview_FlowA_Label.FontColor = app.whiteColor; 
                    else
                        app.Overview_B_Feed_Lamp.Visible = 1;
                        app.Overview_B_Feed_Lamp.Enable = 1;
                        app.Overview_Feed_Lamp.Visible = 1;
                        app.Overview_Feed_Lamp.Enable = 1;
                        app.Overview_FlowA_Label.FontColor = app.redColor;
                    end

                    mainApp.Reactor_FeedA_Lamp.Visible = bl;
                    mainApp.Reactor_FeedA_Lamp.Enable = bl;
                case 2
                     if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Feed Reactor')))
                            if(~any(strcmp(app.alarmTable.source,'Reactor Conc.')))
                                app.Overview_B_Feed_Lamp.Visible = bl;
                                app.Overview_B_Feed_Lamp.Enable = bl;
                            end
                            app.Overview_Feed_Lamp.Visible = bl;
                            app.Overview_Feed_Lamp.Enable = bl;
                        end
                        app.Overview_FlowD_Label.FontColor = app.whiteColor;
                     else
                        app.Overview_B_Feed_Lamp.Visible = 1;
                        app.Overview_B_Feed_Lamp.Enable = 1;
                        app.Overview_Feed_Lamp.Visible = 1;
                        app.Overview_Feed_Lamp.Enable = 1;
                        app.Overview_FlowD_Label.FontColor = app.redColor;
                    end

                    mainApp.Reactor_FeedD_Lamp.Visible = bl;
                    mainApp.Reactor_FeedD_Lamp.Enable = bl;
                case 3
                    if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Feed Reactor')))
                            if(~any(strcmp(app.alarmTable.source,'Reactor Conc.')))
                                app.Overview_B_Feed_Lamp.Visible = bl;
                                app.Overview_B_Feed_Lamp.Enable = bl;
                            end
                            app.Overview_Feed_Lamp.Visible = bl;
                            app.Overview_Feed_Lamp.Enable = bl;
                        end
                        app.Overview_FlowE_Label.FontColor = app.whiteColor;
                    else
                        app.Overview_B_Feed_Lamp.Visible = 1;
                        app.Overview_B_Feed_Lamp.Enable = 1;
                        app.Overview_Feed_Lamp.Visible = 1;
                        app.Overview_Feed_Lamp.Enable = 1;
                        app.Overview_FlowE_Label.FontColor = app.redColor;
                    end

                    mainApp.Reactor_FeedE_Lamp.Visible = bl;
                    mainApp.Reactor_FeedE_Lamp.Enable = bl;
                case 4
                     if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Feed Reactor')))
                            app.Overview_Feed_Lamp.Visible = bl;
                            app.Overview_Feed_Lamp.Enable = bl;
                        end
                        app.Overview_FlowC_Label.FontColor = app.whiteColor;
                     else
                        app.Overview_Feed_Lamp.Visible = 1;
                        app.Overview_Feed_Lamp.Enable = 1;
                        app.Overview_FlowC_Label.FontColor = app.redColor;
                    end
                    
                    app.Overview_B_FeedC_Lamp.Visible = bl;
                    app.Overview_B_FeedC_Lamp.Enable = bl;
                
                    mainApp.Strip_FeedC_Lamp.Visible = bl;
                    mainApp.Strip_FeedC_Lamp.Enable = bl;
                case 5
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Compressor')))
                        app.Overview_B_Compressor_Lamp.Visible = bl;
                        app.Overview_B_Compressor_Lamp.Enable = bl;
                    elseif(bl)
                        app.Overview_B_Compressor_Lamp.Visible = 1;
                        app.Overview_B_Compressor_Lamp.Enable = 1;
                    end
               
                    mainApp.Reactor_Recycle_Lamp.Visible = bl;
                    mainApp.Reactor_Recycle_Lamp.Enable = bl;
                    mainApp.Comp_Recycle_Lamp.Visible = bl;
                    mainApp.Comp_Recycle_Lamp.Enable = bl;
                case 6
                     if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Feed Reactor')))
                            if(~any(strcmp(app.alarmTable.source,'Reactor Conc.')))
                                app.Overview_B_Feed_Lamp.Visible = bl;
                                app.Overview_B_Feed_Lamp.Enable = bl;
                            end
                            app.Overview_Feed_Lamp.Visible = bl;
                            app.Overview_Feed_Lamp.Enable = bl;
                        end
                        app.Overview_ReactorFlow_Label.FontColor = app.whiteColor;
                     else
                        app.Overview_B_Feed_Lamp.Visible = 1;
                        app.Overview_B_Feed_Lamp.Enable = 1;
                        app.Overview_Feed_Lamp.Visible = 1;
                        app.Overview_Feed_Lamp.Enable = 1;
                        app.Overview_ReactorFlow_Label.FontColor = app.redColor;
                    end
                    mainApp.Reactor_FeedReactor_Lamp.Visible = bl;
                    mainApp.Reactor_FeedReactor_Lamp.Enable = bl;
                case 7
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Reactor')))
                        app.Overview_B_Reactor_Lamp.Visible = bl;
                        app.Overview_B_Reactor_Lamp.Enable = bl;
                    elseif(bl)
                        app.Overview_B_Reactor_Lamp.Visible = 1;
                        app.Overview_B_Reactor_Lamp.Enable = 1;
                    end
            
                    app.Overview_Reactor_Pressure_Lamp.Visible = bl;
                    app.Overview_Reactor_Pressure_Lamp.Enable = bl;
                    mainApp.Reactor_Pressure_Lamp.Visible = bl;
                    mainApp.Reactor_Pressure_Lamp.Enable = bl;
                case 8
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Reactor')))
                        app.Overview_B_Reactor_Lamp.Visible = bl;
                        app.Overview_B_Reactor_Lamp.Enable = bl;
                    elseif(bl)
                        app.Overview_B_Reactor_Lamp.Visible = 1;
                        app.Overview_B_Reactor_Lamp.Enable = 1;
                    end
            
                    app.Overview_Reactor_Level_Lamp.Visible = bl;
                    app.Overview_Reactor_Level_Lamp.Enable = bl;
                    mainApp.Reactor_Level_Lamp.Visible = bl;
                    mainApp.Reactor_Level_Lamp.Enable = bl;
                case 9
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Reactor')))
                        app.Overview_B_Reactor_Lamp.Visible = bl;
                        app.Overview_B_Reactor_Lamp.Enable = bl;
                    elseif(bl)
                        app.Overview_B_Reactor_Lamp.Visible = 1;
                        app.Overview_B_Reactor_Lamp.Enable = 1;
                    end
            
                    app.Overview_Reactor_Temp_Lamp.Visible = bl;
                    app.Overview_Reactor_Temp_Lamp.Enable = bl;
                    mainApp.Reactor_Temp_Lamp.Visible = bl;
                    mainApp.Reactor_Temp_Lamp.Enable = bl;
                case 10
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Purge')))
                        if(~any(strcmp(app.alarmTable.source,'Purge Conc.')))
                            app.Overview_B_Purge_Lamp.Visible = bl;
                            app.Overview_B_Purge_Lamp.Enable = bl;
                        end 
                    elseif(bl)
                        app.Overview_B_Purge_Lamp.Visible = 1;
                        app.Overview_B_Purge_Lamp.Enable = 1;
                    end

                    mainApp.Sep_Purge_Lamp.Visible = bl;
                    mainApp.Sep_Purge_Lamp.Enable = bl;
                    mainApp.Comp_Purge_Lamp.Visible = bl;
                    mainApp.Comp_Purge_Lamp.Enable = bl;
                case 11
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Separator')))
                        app.Overview_B_Separator_Lamp.Visible = bl;
                        app.Overview_B_Separator_Lamp.Enable = bl;
                    elseif(bl)
                        app.Overview_B_Separator_Lamp.Visible = 1;
                        app.Overview_B_Separator_Lamp.Enable = 1;
                    end
                             
                    app.Overview_Sep_Temp_Lamp.Visible = bl;
                    app.Overview_Sep_Temp_Lamp.Enable = bl;
                    mainApp.Sep_Temp_Lamp.Visible = bl;
                    mainApp.Sep_Temp_Lamp.Enable = bl;
                case 12
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Separator')))
                        app.Overview_B_Separator_Lamp.Visible = bl;
                        app.Overview_B_Separator_Lamp.Enable = bl;
                    elseif(bl)
                        app.Overview_B_Separator_Lamp.Visible = 1;
                        app.Overview_B_Separator_Lamp.Enable = 1;
                    end
                    app.Overview_Sep_Level_Lamp.Visible = bl;
                    app.Overview_Sep_Level_Lamp.Enable = bl;
                    mainApp.Sep_Level_Lamp.Visible = bl;
                    mainApp.Sep_Level_Lamp.Enable = bl;
                case 13
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Separator')))
                        app.Overview_B_Separator_Lamp.Visible = bl;
                        app.Overview_B_Separator_Lamp.Enable = bl;
                    elseif(bl)
                        app.Overview_B_Separator_Lamp.Visible = 1;
                        app.Overview_B_Separator_Lamp.Enable = 1;
                    end
                    app.Overview_Sep_Pressure_Lamp.Visible = bl;
                    app.Overview_Sep_Pressure_Lamp.Enable = bl;
                    mainApp.Sep_Pressure_Lamp.Visible = bl;
                    mainApp.Sep_Pressure_Lamp.Enable = bl;
                case 14
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Separator')))
                        app.Overview_B_Separator_Lamp.Visible = bl;
                        app.Overview_B_Separator_Lamp.Enable = bl;
                    elseif(bl)
                        app.Overview_B_Separator_Lamp.Visible = 1;
                        app.Overview_B_Separator_Lamp.Enable = 1;
                    end
                    mainApp.Sep_Underflow_Lamp.Visible = bl;
                    mainApp.Sep_Underflow_Lamp.Enable = bl;
                case 15
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Stripper')))
                        app.Overview_B_Stripper_Lamp.Visible = bl;
                        app.Overview_B_Stripper_Lamp.Enable = bl;
                    elseif(bl)
                        app.Overview_B_Stripper_Lamp.Visible = 1;
                        app.Overview_B_Stripper_Lamp.Enable = 1;
                    end

                    app.Overview_Strip_Level_Lamp.Visible = bl;
                    app.Overview_Strip_Level_Lamp.Enable = bl;
                    mainApp.Strip_Level_Lamp.Visible = bl;
                    mainApp.Strip_Level_Lamp.Enable = bl;
                case 16
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Stripper')))
                        app.Overview_B_Stripper_Lamp.Visible = bl;
                        app.Overview_B_Stripper_Lamp.Enable = bl;
                    elseif(bl)
                        app.Overview_B_Stripper_Lamp.Visible = 1;
                        app.Overview_B_Stripper_Lamp.Enable = 1;
                    end
                    app.Overview_Strip_Pressure_Lamp.Visible = bl;
                    app.Overview_Strip_Pressure_Lamp.Enable = bl;
                    mainApp.Strip_Pressure_Lamp.Visible = bl;
                    mainApp.Strip_Pressure_Lamp.Enable = bl;
                case 17
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Product')))
                        if(~any(strcmp(app.alarmTable.source,'Product Conc.')))
                            app.Overview_B_Product_Lamp.Visible = bl;
                            app.Overview_B_Product_Lamp.Enable = bl;
                        end 
                    elseif(bl)
                        app.Overview_B_Product_Lamp.Visible = 1;
                        app.Overview_B_Product_Lamp.Enable = 1;
                    end
                    mainApp.Strip_Underflow_Lamp.Visible = bl;
                    mainApp.Strip_Underflow_Lamp.Enable = bl;
                case 18
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Stripper')))
                        app.Overview_B_Stripper_Lamp.Visible = bl;
                        app.Overview_B_Stripper_Lamp.Enable = bl;
                    elseif(bl)
                        app.Overview_B_Stripper_Lamp.Visible = 1;
                        app.Overview_B_Stripper_Lamp.Enable = 1;
                    end
                    app.Overview_Strip_Temp_Lamp.Visible = bl;
                    app.Overview_Strip_Temp_Lamp.Enable = bl;
                    mainApp.Strip_Temp_Lamp.Visible = bl;
                    mainApp.Strip_Temp_Lamp.Enable = bl;
                case 19
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Stripper')))
                        app.Overview_B_Stripper_Lamp.Visible = bl;
                        app.Overview_B_Stripper_Lamp.Enable = bl;
                    elseif(bl)
                        app.Overview_B_Stripper_Lamp.Visible = 1;
                        app.Overview_B_Stripper_Lamp.Enable = 1;
                    end
                    mainApp.Strip_Steam_Lamp.Visible = bl;
                    mainApp.Strip_Steam_Lamp.Enable = bl;
                case 20
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Compressor')))
                        app.Overview_B_Compressor_Lamp.Visible = bl;
                        app.Overview_B_Compressor_Lamp.Enable = bl;
                    elseif(bl)
                        app.Overview_B_Compressor_Lamp.Visible = 1;
                        app.Overview_B_Compressor_Lamp.Enable = 1;
                    end
                    mainApp.Comp_Work_Lamp.Visible = bl;
                    mainApp.Comp_Work_Lamp.Enable = bl;
                case 21
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Reactor')))
                        app.Overview_B_Reactor_Lamp.Visible = bl;
                        app.Overview_B_Reactor_Lamp.Enable = bl;
                    elseif(bl)
                        app.Overview_B_Reactor_Lamp.Visible = 1;
                        app.Overview_B_Reactor_Lamp.Enable = 1;
                    end
            
                    mainApp.Reactor_CWR_Lamp.Visible = bl;
                    mainApp.Reactor_CWR_Lamp.Enable = bl;
                case 22
                    if(bl == false && ~any(strcmp(app.alarmTable.source,'Condenser')))
                        app.Overview_B_Condenser_Lamp.Visible = bl;
                        app.Overview_B_Condenser_Lamp.Enable = bl;
                    elseif(bl)
                        app.Overview_B_Condenser_Lamp.Visible = 1;
                        app.Overview_B_Condenser_Lamp.Enable = 1;
                    end
            
                    mainApp.Cond_CWR_Lamp.Visible = bl;
                    mainApp.Cond_CWR_Lamp.Enable = bl;
                case 23
                    if(bl == false)
                        if (~any(strcmp(app.alarmTable.source,'Reactor Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Feed Reactor')))
                                app.Overview_B_Feed_Lamp.Visible = bl;
                                app.Overview_B_Feed_Lamp.Enable = bl;
                            end 
                            app.Overview_Reactor_Conc_Lamp.Visible = bl;
                            app.Overview_Reactor_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_ReactorA_Label.FontColor = app.whiteColor;
                    else
                        app.Overview_B_Feed_Lamp.Visible = 1;
                        app.Overview_B_Feed_Lamp.Enable = 1;
                        app.Overview_Reactor_Conc_Lamp.Visible = 1;
                        app.Overview_Reactor_Conc_Lamp.Enable = 1;
                        app.Overview_ReactorA_Label.FontColor = app.redColor;
                    end
                    
                    mainApp.Reactor_ConcA_Lamp.Visible = bl;
                    mainApp.Reactor_ConcA_Lamp.Enable = bl;
                case 24
                      if(bl == false)
                        if (~any(strcmp(app.alarmTable.source,'Reactor Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Feed Reactor')))
                                app.Overview_B_Feed_Lamp.Visible = bl;
                                app.Overview_B_Feed_Lamp.Enable = bl;
                            end 
                            app.Overview_Reactor_Conc_Lamp.Visible = bl;
                            app.Overview_Reactor_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_ReactorB_Label.FontColor = app.whiteColor;
                      else
                        app.Overview_B_Feed_Lamp.Visible = 1;
                        app.Overview_B_Feed_Lamp.Enable = 1;
                        app.Overview_Reactor_Conc_Lamp.Visible = 1;
                        app.Overview_Reactor_Conc_Lamp.Enable = 1; 
                        app.Overview_ReactorB_Label.FontColor = app.redColor;
                     end

                   mainApp.Reactor_ConcB_Lamp.Visible = bl;
                   mainApp.Reactor_ConcB_Lamp.Enable = bl;
                case 25
                     if(bl == false)
                        if (~any(strcmp(app.alarmTable.source,'Reactor Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Feed Reactor')))
                                app.Overview_B_Feed_Lamp.Visible = bl;
                                app.Overview_B_Feed_Lamp.Enable = bl;
                            end 
                            app.Overview_Reactor_Conc_Lamp.Visible = bl;
                            app.Overview_Reactor_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_ReactorC_Label.FontColor = app.whiteColor;
                     else
                        app.Overview_B_Feed_Lamp.Visible = 1;
                        app.Overview_B_Feed_Lamp.Enable = 1;
                        app.Overview_Reactor_Conc_Lamp.Visible = 1;
                        app.Overview_Reactor_Conc_Lamp.Enable = 1;
                        app.Overview_ReactorC_Label.FontColor = app.redColor;
                     end
                     mainApp.Reactor_ConcC_Lamp.Visible = bl;
                     mainApp.Reactor_ConcC_Lamp.Enable = bl;
                case 26
                     if(bl == false)
                        if (~any(strcmp(app.alarmTable.source,'Reactor Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Feed Reactor')))
                                app.Overview_B_Feed_Lamp.Visible = bl;
                                app.Overview_B_Feed_Lamp.Enable = bl;
                            end 
                            app.Overview_Reactor_Conc_Lamp.Visible = bl;
                            app.Overview_Reactor_Conc_Lamp.Enable = bl;  
                        end
                        app.Overview_ReactorD_Label.FontColor = app.whiteColor;
                     else
                        app.Overview_B_Feed_Lamp.Visible = 1;
                        app.Overview_B_Feed_Lamp.Enable = 1;
                        app.Overview_Reactor_Conc_Lamp.Visible = 1;
                        app.Overview_Reactor_Conc_Lamp.Enable = 1;
                        app.Overview_ReactorD_Label.FontColor = app.redColor;
                     end
                     mainApp.Reactor_ConcD_Lamp.Visible = bl;
                     mainApp.Reactor_ConcD_Lamp.Enable = bl;
                case 27
                     if(bl == false)
                        if (~any(strcmp(app.alarmTable.source,'Reactor Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Feed Reactor')))
                                app.Overview_B_Feed_Lamp.Visible = bl;
                                app.Overview_B_Feed_Lamp.Enable = bl;
                            end 
                            app.Overview_Reactor_Conc_Lamp.Visible = bl;
                            app.Overview_Reactor_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_ReactorE_Label.FontColor = app.whiteColor;
                     else
                        app.Overview_B_Feed_Lamp.Visible = 1;
                        app.Overview_B_Feed_Lamp.Enable = 1;
                        app.Overview_Reactor_Conc_Lamp.Visible = 1;
                        app.Overview_Reactor_Conc_Lamp.Enable = 1;
                        app.Overview_ReactorE_Label.FontColor = app.redColor;
                     end
                     mainApp.Reactor_ConcE_Lamp.Visible = bl;
                     mainApp.Reactor_ConcE_Lamp.Enable = bl;
                case 28
                     if(bl == false)
                        if (~any(strcmp(app.alarmTable.source,'Reactor Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Feed Reactor')))
                                app.Overview_B_Feed_Lamp.Visible = bl;
                                app.Overview_B_Feed_Lamp.Enable = bl;
                            end 
                            app.Overview_Reactor_Conc_Lamp.Visible = bl;
                            app.Overview_Reactor_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_ReactorF_Label.FontColor = app.whiteColor;
                     else
                        app.Overview_B_Feed_Lamp.Visible = 1;
                        app.Overview_B_Feed_Lamp.Enable = 1;
                        app.Overview_Reactor_Conc_Lamp.Visible = 1;
                        app.Overview_Reactor_Conc_Lamp.Enable = 1;
                        app.Overview_ReactorF_Label.FontColor = app.redColor;
                     end
                     mainApp.Reactor_ConcF_Lamp.Visible = bl;
                     mainApp.Reactor_ConcF_Lamp.Enable = bl;
                case 29
                     if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Purge Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Purge')))
                                app.Overview_B_Purge_Lamp.Visible = bl;
                                app.Overview_B_Purge_Lamp.Enable = bl;
                            end 
                            app.Overview_Purge_Conc_Lamp.Visible = bl;
                            app.Overview_Purge_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_PurgeA_Label.FontColor = app.whiteColor;
                     else
                        app.Overview_B_Purge_Lamp.Visible = 1;
                        app.Overview_B_Purge_Lamp.Enable = 1;
                        app.Overview_Purge_Conc_Lamp.Visible = 1;
                        app.Overview_Purge_Conc_Lamp.Enable = 1;
                        app.Overview_PurgeA_Label.FontColor = app.redColor;
                     end
                     mainApp.Sep_Purge_ConcA_Lamp.Visible = bl;
                     mainApp.Sep_Purge_ConcA_Lamp.Enable = bl;
                case 30
                    if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Purge Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Purge')))
                                app.Overview_B_Purge_Lamp.Visible = bl;
                                app.Overview_B_Purge_Lamp.Enable = bl;
                            end 
                            app.Overview_Purge_Conc_Lamp.Visible = bl;
                            app.Overview_Purge_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_PurgeB_Label.FontColor = app.whiteColor;
                    else
                        app.Overview_B_Purge_Lamp.Visible = 1;
                        app.Overview_B_Purge_Lamp.Enable = 1;
                        app.Overview_Purge_Conc_Lamp.Visible = 1;
                        app.Overview_Purge_Conc_Lamp.Enable = 1;
                        app.Overview_PurgeB_Label.FontColor = app.redColor;
                    end
                    mainApp.Sep_Purge_ConcB_Lamp.Visible = bl;
                    mainApp.Sep_Purge_ConcB_Lamp.Enable = bl;
                case 31
                     if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Purge Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Purge')))
                                app.Overview_B_Purge_Lamp.Visible = bl;
                                app.Overview_B_Purge_Lamp.Enable = bl;
                            end 
                            app.Overview_Purge_Conc_Lamp.Visible = bl;
                            app.Overview_Purge_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_PurgeC_Label.FontColor = app.whiteColor;
                     else
                        app.Overview_B_Purge_Lamp.Visible = 1;
                        app.Overview_B_Purge_Lamp.Enable = 1;
                        app.Overview_Purge_Conc_Lamp.Visible = 1;
                        app.Overview_Purge_Conc_Lamp.Enable = 1;
                        app.Overview_PurgeC_Label.FontColor = app.redColor;
                     end
                     mainApp.Sep_Purge_ConcC_Lamp.Visible = bl;
                     mainApp.Sep_Purge_ConcC_Lamp.Enable = bl;
                case 32
                    if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Purge Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Purge')))
                                app.Overview_B_Purge_Lamp.Visible = bl;
                                app.Overview_B_Purge_Lamp.Enable = bl;
                            end 
                            app.Overview_Purge_Conc_Lamp.Visible = bl;
                            app.Overview_Purge_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_PurgeD_Label.FontColor = app.whiteColor;
                    else
                        app.Overview_B_Purge_Lamp.Visible = 1;
                        app.Overview_B_Purge_Lamp.Enable = 1;
                        app.Overview_Purge_Conc_Lamp.Visible = 1;
                        app.Overview_Purge_Conc_Lamp.Enable = 1;
                        app.Overview_PurgeD_Label.FontColor = app.redColor;
                    end
                    mainApp.Sep_Purge_ConcD_Lamp.Visible = bl;
                    mainApp.Sep_Purge_ConcD_Lamp.Enable = bl;
                case 33
                     if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Purge Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Purge')))
                                app.Overview_B_Purge_Lamp.Visible = bl;
                                app.Overview_B_Purge_Lamp.Enable = bl;
                            end 
                            app.Overview_Purge_Conc_Lamp.Visible = bl;
                            app.Overview_Purge_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_PurgeE_Label.FontColor = app.whiteColor;
                     else
                        app.Overview_B_Purge_Lamp.Visible = 1;
                        app.Overview_B_Purge_Lamp.Enable = 1;
                        app.Overview_Purge_Conc_Lamp.Visible = 1;
                        app.Overview_Purge_Conc_Lamp.Enable = 1;
                        app.Overview_PurgeE_Label.FontColor = app.redColor;
                     end
                     mainApp.Sep_Purge_ConcE_Lamp.Visible = bl;
                     mainApp.Sep_Purge_ConcE_Lamp.Enable = bl;
                case 34
                    if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Purge Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Purge')))
                                app.Overview_B_Purge_Lamp.Visible = bl;
                                app.Overview_B_Purge_Lamp.Enable = bl;
                            end 
                            app.Overview_Purge_Conc_Lamp.Visible = bl;
                            app.Overview_Purge_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_PurgeF_Label.FontColor = app.whiteColor;
                    else
                        app.Overview_B_Purge_Lamp.Visible = 1;
                        app.Overview_B_Purge_Lamp.Enable = 1;
                        app.Overview_Purge_Conc_Lamp.Visible = 1;
                        app.Overview_Purge_Conc_Lamp.Enable = 1;
                        mainApp.Sep_Purge_ConcF_Lamp.Visible = 1;
                        app.Overview_PurgeF_Label.FontColor = app.redColor;
                    end
                    mainApp.Sep_Purge_ConcF_Lamp.Visible = bl;
                    mainApp.Sep_Purge_ConcF_Lamp.Enable = bl;
                case 35
                     if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Purge Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Purge')))
                                app.Overview_B_Purge_Lamp.Visible = bl;
                                app.Overview_B_Purge_Lamp.Enable = bl;
                            end 
                            app.Overview_Purge_Conc_Lamp.Visible = bl;
                            app.Overview_Purge_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_PurgeG_Label.FontColor = app.whiteColor;
                     else
                        app.Overview_B_Purge_Lamp.Visible = 1;
                        app.Overview_B_Purge_Lamp.Enable = 1;
                        app.Overview_Purge_Conc_Lamp.Visible = 1;
                        app.Overview_Purge_Conc_Lamp.Enable = 1;
                        app.Overview_PurgeG_Label.FontColor = app.redColor;
                     end
                     mainApp.Sep_Purge_ConcG_Lamp.Visible = bl;
                     mainApp.Sep_Purge_ConcG_Lamp.Enable = bl;
                case 36
                     if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Purge Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Purge')))
                                app.Overview_B_Purge_Lamp.Visible = bl;
                                app.Overview_B_Purge_Lamp.Enable = bl;
                            end 
                            app.Overview_Purge_Conc_Lamp.Visible = bl;
                            app.Overview_Purge_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_PurgeH_Label.FontColor = app.whiteColor;
                     else
                        app.Overview_B_Purge_Lamp.Visible = 1;
                        app.Overview_B_Purge_Lamp.Enable = 1;
                        app.Overview_Purge_Conc_Lamp.Visible = 1;
                        app.Overview_Purge_Conc_Lamp.Enable = 1;
                        app.Overview_PurgeH_Label.FontColor = app.redColor;
                     end
                     mainApp.Sep_Purge_ConcH_Lamp.Visible = bl;
                     mainApp.Sep_Purge_ConcH_Lamp.Enable = bl;
                case 37
                     if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Product Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Product')))
                                app.Overview_B_Product_Lamp.Visible = bl;
                                app.Overview_B_Product_Lamp.Enable = bl;
                            end 
                            app.Overview_Product_Conc_Lamp.Visible = bl;
                            app.Overview_Product_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_ProductD_Label.FontColor = app.whiteColor;
                     else
                        app.Overview_B_Product_Lamp.Visible = 1;
                        app.Overview_B_Product_Lamp.Enable = 1;
                        app.Overview_Product_Conc_Lamp.Visible = 1;
                        app.Overview_Product_Conc_Lamp.Enable = 1;
                        app.Overview_ProductD_Label.FontColor = app.redColor;
                     end
                     mainApp.Strip_Product_ConcD_Lamp.Visible = bl;
                     mainApp.Strip_Product_ConcD_Lamp.Enable = bl;
                case 38
                    if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Product Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Product')))
                                app.Overview_B_Product_Lamp.Visible = bl;
                                app.Overview_B_Product_Lamp.Enable = bl;
                            end 
                            app.Overview_Product_Conc_Lamp.Visible = bl;
                            app.Overview_Product_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_ProductE_Label.FontColor = app.whiteColor;
                    else
                        app.Overview_B_Product_Lamp.Visible = 1;
                        app.Overview_B_Product_Lamp.Enable = 1;
                        app.Overview_Product_Conc_Lamp.Visible = 1;
                        app.Overview_Product_Conc_Lamp.Enable = 1;
                        app.Overview_ProductE_Label.FontColor = app.redColor;
                    end
                    mainApp.Strip_Product_ConcE_Lamp.Visible = bl;
                    mainApp.Strip_Product_ConcE_Lamp.Enable = bl;
                case 39
                     if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Product Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Product')))
                                app.Overview_B_Product_Lamp.Visible = bl;
                                app.Overview_B_Product_Lamp.Enable = bl;
                            end 
                            app.Overview_Product_Conc_Lamp.Visible = bl;
                            app.Overview_Product_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_ProductF_Label.FontColor = app.whiteColor;
                     else
                        app.Overview_B_Product_Lamp.Visible = 1;
                        app.Overview_B_Product_Lamp.Enable = 1;
                        app.Overview_Product_Conc_Lamp.Visible = 1;
                        app.Overview_Product_Conc_Lamp.Enable = 1;
                        app.Overview_ProductF_Label.FontColor = app.redColor;
                     end
                     mainApp.Strip_Product_ConcF_Lamp.Visible = bl;
                     mainApp.Strip_Product_ConcF_Lamp.Enable = bl;
                case 40
                     if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Product Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Product')))
                                app.Overview_B_Product_Lamp.Visible = bl;
                                app.Overview_B_Product_Lamp.Enable = bl;
                            end 
                            app.Overview_Product_Conc_Lamp.Visible = bl;
                            app.Overview_Product_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_ProductG_Label.FontColor = app.whiteColor;
                     else
                        app.Overview_B_Product_Lamp.Visible = 1;
                        app.Overview_B_Product_Lamp.Enable = 1;
                        app.Overview_Product_Conc_Lamp.Visible = 1;
                        app.Overview_Product_Conc_Lamp.Enable = 1;
                        app.Overview_ProductG_Label.FontColor = app.redColor;
                     end
                     mainApp.Strip_Product_ConcG_Lamp.Visible = bl;
                     mainApp.Strip_Product_ConcG_Lamp.Enable = bl;
                case 41
                     if(bl == false)
                        if(~any(strcmp(app.alarmTable.source,'Product Conc.')))
                            if(~any(strcmp(app.alarmTable.source,'Product')))
                                app.Overview_B_Product_Lamp.Visible = bl;
                                app.Overview_B_Product_Lamp.Enable = bl;
                            end 
                            app.Overview_Product_Conc_Lamp.Visible = bl;
                            app.Overview_Product_Conc_Lamp.Enable = bl;
                        end
                        app.Overview_ProductH_Label.FontColor = app.whiteColor;
                     else
                        app.Overview_B_Product_Lamp.Visible = 1;
                        app.Overview_B_Product_Lamp.Enable = 1;
                        app.Overview_Product_Conc_Lamp.Visible = 1;
                        app.Overview_Product_Conc_Lamp.Enable = 1;
                        app.Overview_ProductH_Label.FontColor = app.redColor;
                    end
                    mainApp.Strip_Product_ConcH_Lamp.Visible = bl;
                    mainApp.Strip_Product_ConcH_Lamp.Enable = bl;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hApp)
            app.fault = evalin('base','fault');
            app.diagnostic_type = evalin('base','diagnostic_type');
            
            %Table
            ack = {}; id = {}; sTime = {}; Description = {}; source = {};
            app.alarmTable = table(ack,id,sTime,Description,source);
            app.alarmTable = [app.alarmTable; {'ack','id','startTime','descr','source'}];
            set(app.UITable_Alarms,'Data',app.alarmTable);
        end

        % Cell edit callback: UITable_Alarms
        function UITable_AlarmsCellEdit(app, event)
            indices = event.Indices;
            if(indices(2) == 1)
                if(cell2mat(app.alarmTable{indices(1),indices(2)}) == false)
                   app.alarmTable{indices(1),indices(2)} = {true};
                else
                   app.alarmTable{indices(1),indices(2)} = {false};
                end
            end
        end

        % Value changed function: Show_Alarms_Button_F1
        function Show_Alarms_Button_F1ValueChanged(app, event)
            value = app.Show_Alarms_Button_F1.Value;
            
            if(value == 1)
                app.Show_Alarms_Button_F1.FontColor = app.whiteColor;
                app.Show_Alarms_Button_F1.BackgroundColor = app.selectedColor; 
                
                if(app.Show_Alarms_Button_F2.Value == 1)
                    app.Show_Alarms_Button_F2.Value = 0;
                    app.Show_Alarms_Button_F2.BackgroundColor =  app.grayColor;
                    app.Show_Alarms_Button_F2.FontColor = app.blackColor;
                elseif(app.Show_Alarms_Button_F3.Value == 1)
                    app.Show_Alarms_Button_F3.Value = 0;
                    app.Show_Alarms_Button_F3.BackgroundColor =  app.grayColor;
                    app.Show_Alarms_Button_F3.FontColor = app.blackColor;
                end
            else
                 app.Show_Alarms_Button_F1.BackgroundColor =  app.grayColor;
                 app.Show_Alarms_Button_F1.FontColor = app.blackColor;
            end
            
            if(app.fault == 1 && app.diagnostic_type == 1)
                diagnosticToolAlarmsHighlight(app, value, app.fault1_first_Alarms);
            elseif(app.fault == 6 && app.diagnostic_type == 1)
                diagnosticToolAlarmsHighlight(app, value, app.fault6_first_Alarms);
            end
        end

        % Value changed function: Show_Alarms_Button_F2
        function Show_Alarms_Button_F2ValueChanged(app, event)
            value = app.Show_Alarms_Button_F2.Value;

            if(value == 1)
                app.Show_Alarms_Button_F2.FontColor = app.whiteColor;
                app.Show_Alarms_Button_F2.BackgroundColor = app.selectedColor; 
                
                if(app.Show_Alarms_Button_F1.Value == 1)
                    app.Show_Alarms_Button_F1.Value = 0;
                    app.Show_Alarms_Button_F1.BackgroundColor =  app.grayColor;
                    app.Show_Alarms_Button_F1.FontColor = app.blackColor;
                elseif(app.Show_Alarms_Button_F3.Value == 1)
                    app.Show_Alarms_Button_F3.Value = 0;
                    app.Show_Alarms_Button_F3.BackgroundColor =  app.grayColor;
                    app.Show_Alarms_Button_F3.FontColor = app.blackColor;
                end
            else
                 app.Show_Alarms_Button_F2.BackgroundColor =  app.grayColor;
                 app.Show_Alarms_Button_F2.FontColor = app.blackColor;
            end

            if(app.fault == 1 && app.diagnostic_type == 1)
                diagnosticToolAlarmsHighlight(app, value, app.fault1_sec_Alarms);
            elseif(app.fault == 6 && app.diagnostic_type == 1)
                diagnosticToolAlarmsHighlight(app, value, app.fault6_sec_Alarms);
            end
        end

        % Value changed function: Show_Alarms_Button_F3
        function Show_Alarms_Button_F3ValueChanged(app, event)
            value = app.Show_Alarms_Button_F3.Value;
            
            if(value == 1)
                app.Show_Alarms_Button_F3.FontColor = app.whiteColor;
                app.Show_Alarms_Button_F3.BackgroundColor = app.selectedColor; 
                
                if(app.Show_Alarms_Button_F1.Value == 1)
                    app.Show_Alarms_Button_F1.Value = 0;
                    app.Show_Alarms_Button_F1.BackgroundColor =  app.grayColor;
                    app.Show_Alarms_Button_F1.FontColor = app.blackColor;
                elseif(app.Show_Alarms_Button_F2.Value == 1)
                    app.Show_Alarms_Button_F2.Value = 0;
                    app.Show_Alarms_Button_F2.BackgroundColor =  app.grayColor;
                    app.Show_Alarms_Button_F2.FontColor = app.blackColor;
                end
            else
                 app.Show_Alarms_Button_F3.BackgroundColor =  app.grayColor;
                 app.Show_Alarms_Button_F3.FontColor = app.blackColor;
            end

            if(app.fault == 1 && app.diagnostic_type == 1)
                diagnosticToolAlarmsHighlight(app, value, app.fault1_third_Alarms);
            elseif(app.fault == 6 && app.diagnostic_type == 1)
                diagnosticToolAlarmsHighlight(app, value, app.fault6_third_Alarms);
            end
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            value = app.PauseButton.Value;
            
            if(value == 1)
                app.PauseButton.Text = 'Resume';
                set_param('MultiLoop_mode1','SimulationCommand','pause');
            else
                app.PauseButton.Text = 'Pause';
                set_param('MultiLoop_mode1','SimulationCommand','continue');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_3 = uipanel(app.UIFigure);
            app.UIAxes_Overview_Strip_Pressure = uiaxes(app.Panel_3);
            zlabel(app.UIAxes_Overview_Strip_Pressure, 'Z')
            app.UIAxes_Overview_Strip_Pressure.XLim = [0 Inf];
            app.UIAxes_Overview_Strip_Pressure.YLim = [2900 3700];
            app.UIAxes_Overview_Strip_Pressure.XTick = [];
            app.UIAxes_Overview_Strip_Pressure.XTickLabel = '';
            app.UIAxes_Overview_Strip_Pressure.YTick = [];
            app.UIAxes_Overview_Strip_Pressure.YTickLabel = '';
            app.UIAxes_Overview_Strip_Level = uiaxes(app.Panel_3);
            zlabel(app.UIAxes_Overview_Strip_Level, 'Z')
            app.UIAxes_Overview_Strip_Level.XLim = [0 Inf];
            app.UIAxes_Overview_Strip_Level.YLim = [0 100];
            app.UIAxes_Overview_Strip_Level.XTick = 0;
            app.UIAxes_Overview_Strip_Level.XTickLabel = {'0'};
            app.UIAxes_Overview_Strip_Level.YTick = [];
            app.UIAxes_Overview_Strip_Level.YTickLabel = '';
            app.UIAxes_Overview_Strip_Temp = uiaxes(app.Panel_3);
            zlabel(app.UIAxes_Overview_Strip_Temp, 'Z')
            app.UIAxes_Overview_Strip_Temp.XLim = [0 Inf];
            app.UIAxes_Overview_Strip_Temp.YLim = [50 80];
            app.UIAxes_Overview_Strip_Temp.XTick = 0;
            app.UIAxes_Overview_Strip_Temp.XTickLabel = {'0'};
            app.UIAxes_Overview_Strip_Temp.YTick = [];
            app.UIAxes_Overview_Strip_Temp.YTickLabel = '';
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = '';
            app.StripperLabel = uilabel(app.Panel_3);
            app.StripperLabel.Text = 'Stripper';
            app.Image19_3 = uiimage(app.Panel_3);
            app.Image19_3.ImageSource = 'O_stripper.png';
            app.StripperLabel_2 = uilabel(app.Panel_3);
            app.StripperLabel_2.Text = 'Stripper';
            app.ProductFlowLabel = uilabel(app.Panel_3);
            app.ProductFlowLabel.Text = 'Product Flow';
            app.Overview_Strip_Production = uilabel(app.Panel_3);
            app.Overview_Strip_Production.Text = '0.00 ';
            app.PressureLabel_3 = uilabel(app.Panel_3);
            app.PressureLabel_3.Text = 'Pressure';
            app.Overview_Strip_Pressure = uilabel(app.Panel_3);
            app.Overview_Strip_Pressure.Text = '0.00';
            app.TemperatureLabel_3 = uilabel(app.Panel_3);
            app.TemperatureLabel_3.Text = 'Temperature';
            app.Overview_Strip_Temp = uilabel(app.Panel_3);
            app.Overview_Strip_Temp.Text = '0.00';
            app.LevelLabel_3 = uilabel(app.Panel_3);
            app.LevelLabel_3.Text = 'Level';
            app.Overview_Strip_Level = uilabel(app.Panel_3);
            app.Overview_Strip_Level.Text = '0.00';
            app.m3hLabel_2 = uilabel(app.Panel_3);
            app.m3hLabel_2.Text = 'm3/h';
            app.KpaLabel_3 = uilabel(app.Panel_3);
            app.KpaLabel_3.Text = 'Kpa';
            app.CLabel_3 = uilabel(app.Panel_3);
            app.CLabel_3.Text = '°C';
            app.Label_6 = uilabel(app.Panel_3);
            app.Label_6.Text = '%';
            app.Overview_Prod_Sp_Label_4 = uilabel(app.Panel_3);
            app.Overview_Prod_Sp_Label_4.Text = '22.89';
            app.TgLabel_3 = uilabel(app.Panel_3);
            app.TgLabel_3.Text = 'Tg.';
            app.Overview_B_Stripper_Lamp = uilamp(app.Panel_3);
            app.Overview_B_Stripper_Lamp.Enable = 'off';
            app.Overview_B_Stripper_Lamp.Visible = 'off';
            app.Overview_Strip_Pressure_Lamp = uilamp(app.Panel_3);
            app.Overview_Strip_Pressure_Lamp.Enable = 'off';
            app.Overview_Strip_Pressure_Lamp.Visible = 'off';
            app.Overview_Strip_Temp_Lamp = uilamp(app.Panel_3);
            app.Overview_Strip_Temp_Lamp.Enable = 'off';
            app.Overview_Strip_Temp_Lamp.Visible = 'off';
            app.Overview_Strip_Level_Lamp = uilamp(app.Panel_3);
            app.Overview_Strip_Level_Lamp.Enable = 'off';
            app.Overview_Strip_Level_Lamp.Visible = 'off';
            app.Overview_B_FeedC_Lamp = uilamp(app.Panel_3);
            app.Overview_B_FeedC_Lamp.Enable = 'off';
            app.Overview_B_FeedC_Lamp.Visible = 'off';
            app.Overview_B_Product_Lamp = uilamp(app.Panel_3);
            app.Overview_B_Product_Lamp.Enable = 'off';
            app.Overview_B_Product_Lamp.Visible = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes_Overview_Quality = uiaxes(app.Panel);
            zlabel(app.UIAxes_Overview_Quality, 'Z')
            app.UIAxes_Overview_Quality.XLim = [0 Inf];
            app.UIAxes_Overview_Quality.YLim = [30 60];
            app.UIAxes_Overview_Quality.XTick = 0;
            app.UIAxes_Overview_Quality.XTickLabel = {'0'};
            app.UIAxes_Overview_Quality.YTick = [30 35 40 45 50 55 60];
            app.UIAxes_Overview_Quality.YTickLabel = {'30'; '35'; '40'; '45'; '50'; '55'; '60'};
            app.UIAxes_Overview_Cost = uiaxes(app.Panel);
            zlabel(app.UIAxes_Overview_Cost, 'Z')
            app.UIAxes_Overview_Cost.XLim = [0 Inf];
            app.UIAxes_Overview_Cost.YLim = [0 400];
            app.UIAxes_Overview_Cost.XTick = 0;
            app.UIAxes_Overview_Cost.XTickLabel = {'0'};
            app.UIAxes_Overview_Cost.YTick = [0 80 160 240 320 400];
            app.UIAxes_Overview_Cost.YTickLabel = {'0'; '80'; '160'; '240'; '320'; '400'};
            app.UIAxes_Overview_Production = uiaxes(app.Panel);
            zlabel(app.UIAxes_Overview_Production, 'Z')
            app.UIAxes_Overview_Production.XLim = [0 Inf];
            app.UIAxes_Overview_Production.YLim = [10 30];
            app.UIAxes_Overview_Production.XTick = 0;
            app.UIAxes_Overview_Production.XTickLabel = {'0'};
            app.UIAxes_Overview_Production.YTick = [10 15 20 25 30];
            app.UIAxes_Overview_Production.YTickLabel = {'10'; '15'; '20'; '25'; '30'};
            app.PerformanceLabel = uilabel(app.Panel);
            app.PerformanceLabel.Text = 'Performance';
            app.FeedFlowLabel = uilabel(app.Panel);
            app.FeedFlowLabel.Text = 'Feed Flow ';
            app.Overview_Prod_Sp_Label = uilabel(app.Panel);
            app.Overview_Prod_Sp_Label.Text = '0.00';
            app.Overview_Prod_Label = uilabel(app.Panel);
            app.Overview_Prod_Label.Text = '0.00';
            app.Level_Label = uilabel(app.Panel);
            app.Level_Label.Text = 'G, H';
            app.Level_Label_2 = uilabel(app.Panel);
            app.Level_Label_2.Text = 'Tg.';
            app.Overview_Cost_Label = uilabel(app.Panel);
            app.Overview_Cost_Label.Text = '0.00';
            app.ProductConcLabel = uilabel(app.Panel);
            app.ProductConcLabel.Text = 'Product Conc.';
            app.Level_Label_3 = uilabel(app.Panel);
            app.Level_Label_3.Text = 'D';
            app.Overview_ProductD_Label = uilabel(app.Panel);
            app.Overview_ProductD_Label.Text = '0.00';
            app.PurgeConcLabel = uilabel(app.Panel);
            app.PurgeConcLabel.Text = 'Purge Conc.';
            app.Level_Label_4 = uilabel(app.Panel);
            app.Level_Label_4.Text = 'Quality';
            app.Level_Label_5 = uilabel(app.Panel);
            app.Level_Label_5.Text = '$ per hour';
            app.Level_Label_6 = uilabel(app.Panel);
            app.Level_Label_6.Text = 'Production';
            app.Overview_FlowA_Label = uilabel(app.Panel);
            app.Overview_FlowA_Label.Text = '0.00';
            app.Overview_FlowD_Label = uilabel(app.Panel);
            app.Overview_FlowD_Label.Text = '0.00';
            app.Overview_FlowE_Label = uilabel(app.Panel);
            app.Overview_FlowE_Label.Text = '0.00';
            app.Overview_FlowC_Label = uilabel(app.Panel);
            app.Overview_FlowC_Label.Text = '0.00';
            app.Level_Label_7 = uilabel(app.Panel);
            app.Level_Label_7.Text = 'A';
            app.Level_Label_8 = uilabel(app.Panel);
            app.Level_Label_8.Text = 'D';
            app.Level_Label_9 = uilabel(app.Panel);
            app.Level_Label_9.Text = 'E';
            app.Level_Label_10 = uilabel(app.Panel);
            app.Level_Label_10.Text = 'C';
            app.Level_Label_11 = uilabel(app.Panel);
            app.Level_Label_11.Text = 'Kscmh';
            app.Level_Label_12 = uilabel(app.Panel);
            app.Level_Label_12.Text = 'Kg/h';
            app.Level_Label_13 = uilabel(app.Panel);
            app.Level_Label_13.Text = 'Kg/h';
            app.Level_Label_14 = uilabel(app.Panel);
            app.Level_Label_14.Text = 'Kscmh';
            app.Level_Label_15 = uilabel(app.Panel);
            app.Level_Label_15.Text = '$';
            app.Level_Label_16 = uilabel(app.Panel);
            app.Level_Label_16.Text = 'G';
            app.Overview_Quality_G_Label = uilabel(app.Panel);
            app.Overview_Quality_G_Label.Text = '0.00';
            app.Level_Label_17 = uilabel(app.Panel);
            app.Level_Label_17.Text = '%';
            app.Level_Label_18 = uilabel(app.Panel);
            app.Level_Label_18.Text = '%';
            app.Level_Label_19 = uilabel(app.Panel);
            app.Level_Label_19.Text = '%';
            app.Level_Label_20 = uilabel(app.Panel);
            app.Level_Label_20.Text = '%';
            app.Level_Label_21 = uilabel(app.Panel);
            app.Level_Label_21.Text = 'P.';
            app.Level_Label_22 = uilabel(app.Panel);
            app.Level_Label_22.Text = 'H';
            app.Overview_Quality_H_Label = uilabel(app.Panel);
            app.Overview_Quality_H_Label.Text = '0.00';
            app.ReactorConcLabel = uilabel(app.Panel);
            app.ReactorConcLabel.Text = 'Reactor Conc.';
            app.Level_Label_23 = uilabel(app.Panel);
            app.Level_Label_23.Text = 'E';
            app.Overview_ProductE_Label = uilabel(app.Panel);
            app.Overview_ProductE_Label.Text = '0.00';
            app.Level_Label_24 = uilabel(app.Panel);
            app.Level_Label_24.Text = 'F';
            app.Overview_ProductF_Label = uilabel(app.Panel);
            app.Overview_ProductF_Label.Text = '0.00';
            app.Level_Label_25 = uilabel(app.Panel);
            app.Level_Label_25.Text = 'G';
            app.Overview_ProductG_Label = uilabel(app.Panel);
            app.Overview_ProductG_Label.Text = '0.00';
            app.Level_Label_26 = uilabel(app.Panel);
            app.Level_Label_26.Text = 'H';
            app.Overview_ProductH_Label = uilabel(app.Panel);
            app.Overview_ProductH_Label.Text = '0.00';
            app.Level_Label_27 = uilabel(app.Panel);
            app.Level_Label_27.Text = 'A';
            app.Level_Label_28 = uilabel(app.Panel);
            app.Level_Label_28.Text = 'B';
            app.Overview_PurgeB_Label = uilabel(app.Panel);
            app.Overview_PurgeB_Label.Text = '0.00';
            app.Level_Label_29 = uilabel(app.Panel);
            app.Level_Label_29.Text = 'C';
            app.Overview_PurgeC_Label = uilabel(app.Panel);
            app.Overview_PurgeC_Label.Text = '0.00';
            app.Level_Label_30 = uilabel(app.Panel);
            app.Level_Label_30.Text = 'D';
            app.Overview_PurgeD_Label = uilabel(app.Panel);
            app.Overview_PurgeD_Label.Text = '0.00';
            app.Level_Label_31 = uilabel(app.Panel);
            app.Level_Label_31.Text = 'E';
            app.Overview_PurgeE_Label = uilabel(app.Panel);
            app.Overview_PurgeE_Label.Text = '0.00';
            app.Overview_PurgeA_Label = uilabel(app.Panel);
            app.Overview_PurgeA_Label.Text = '0.00';
            app.Level_Label_32 = uilabel(app.Panel);
            app.Level_Label_32.Text = 'F';
            app.Overview_PurgeF_Label = uilabel(app.Panel);
            app.Overview_PurgeF_Label.Text = '0.00';
            app.Level_Label_33 = uilabel(app.Panel);
            app.Level_Label_33.Text = 'G';
            app.Overview_PurgeG_Label = uilabel(app.Panel);
            app.Overview_PurgeG_Label.Text = '0.00';
            app.Level_Label_34 = uilabel(app.Panel);
            app.Level_Label_34.Text = 'H';
            app.Overview_PurgeH_Label = uilabel(app.Panel);
            app.Overview_PurgeH_Label.Text = '0.00';
            app.Level_Label_35 = uilabel(app.Panel);
            app.Level_Label_35.Text = '% mol';
            app.Level_Label_36 = uilabel(app.Panel);
            app.Level_Label_36.Text = 'B';
            app.Overview_ReactorB_Label = uilabel(app.Panel);
            app.Overview_ReactorB_Label.Text = '0.00';
            app.Level_Label_37 = uilabel(app.Panel);
            app.Level_Label_37.Text = 'C';
            app.Overview_ReactorC_Label = uilabel(app.Panel);
            app.Overview_ReactorC_Label.Text = '0.00';
            app.Level_Label_38 = uilabel(app.Panel);
            app.Level_Label_38.Text = 'D';
            app.Overview_ReactorD_Label = uilabel(app.Panel);
            app.Overview_ReactorD_Label.Text = '0.00';
            app.Level_Label_39 = uilabel(app.Panel);
            app.Level_Label_39.Text = 'E';
            app.Overview_ReactorE_Label = uilabel(app.Panel);
            app.Overview_ReactorE_Label.Text = '0.00';
            app.Level_Label_40 = uilabel(app.Panel);
            app.Level_Label_40.Text = 'F';
            app.Overview_ReactorF_Label = uilabel(app.Panel);
            app.Overview_ReactorF_Label.Text = '0.00';
            app.Level_Label_41 = uilabel(app.Panel);
            app.Level_Label_41.Text = 'A';
            app.Overview_ReactorA_Label = uilabel(app.Panel);
            app.Overview_ReactorA_Label.Text = '0.00';
            app.Overview_ReactorFlow_Label = uilabel(app.Panel);
            app.Overview_ReactorFlow_Label.Text = '0.00';
            app.Level_Label_42 = uilabel(app.Panel);
            app.Level_Label_42.Text = 'Reactor';
            app.Level_Label_43 = uilabel(app.Panel);
            app.Level_Label_43.Text = 'Kscmh';
            app.Level_Label_44 = uilabel(app.Panel);
            app.Level_Label_44.Text = 'Out';
            app.Overview_Purge_Conc_Lamp = uilamp(app.Panel);
            app.Overview_Purge_Conc_Lamp.Enable = 'off';
            app.Overview_Purge_Conc_Lamp.Visible = 'off';
            app.Overview_Product_Conc_Lamp = uilamp(app.Panel);
            app.Overview_Product_Conc_Lamp.Enable = 'off';
            app.Overview_Product_Conc_Lamp.Visible = 'off';
            app.Level_Label_45 = uilabel(app.Panel);
            app.Level_Label_45.Text = '% mol';
            app.Level_Label_46 = uilabel(app.Panel);
            app.Level_Label_46.Text = '% mol';
            app.Overview_Reactor_Conc_Lamp = uilamp(app.Panel);
            app.Overview_Reactor_Conc_Lamp.Enable = 'off';
            app.Overview_Reactor_Conc_Lamp.Visible = 'off';
            app.Overview_Feed_Lamp = uilamp(app.Panel);
            app.Overview_Feed_Lamp.Enable = 'off';
            app.Overview_Feed_Lamp.Visible = 'off';
            app.Level_Label_47 = uilabel(app.Panel);
            app.Level_Label_47.Text = 'In';
            app.Panel_5 = uipanel(app.Panel);
            app.DiagnosticToolLabel = uilabel(app.Panel_5);
            app.DiagnosticToolLabel.Text = 'Diagnostic Tool';
            app.Image21 = uiimage(app.Panel_5);
            app.Image21.ImageSource = 'dt_icon.png';
            app.Diagnostic_Solution_Label = uilabel(app.Panel_5);
            app.Diagnostic_Solution_Label.Visible = 'off';
            app.Diagnostic_Solution_Label.Text = 'None';
            app.SolutionStepsLabel = uilabel(app.Panel_5);
            app.SolutionStepsLabel.Visible = 'off';
            app.SolutionStepsLabel.Text = 'Solution Steps:';
            app.FaultsfoundLabel = uilabel(app.Panel_5);
            app.FaultsfoundLabel.Text = 'Faults found:';
            app.Diagnostic_Fault_Label = uilabel(app.Panel_5);
            app.Diagnostic_Fault_Label.Text = 'None';
            app.Diagnostic_Fault2_Label = uilabel(app.Panel_5);
            app.Diagnostic_Fault2_Label.Text = '';
            app.Show_Alarms_Button_F1 = uibutton(app.Panel_5, 'state');
            app.Show_Alarms_Button_F1.ValueChangedFcn = createCallbackFcn(app, @Show_Alarms_Button_F1ValueChanged, true);
            app.Show_Alarms_Button_F1.Visible = 'off';
            app.Show_Alarms_Button_F1.Text = 'Alarms';
            app.Diagnostic_Fault3_Label = uilabel(app.Panel_5);
            app.Diagnostic_Fault3_Label.Text = '';
            app.Diagnostic_Fault_Perc_Label = uilabel(app.Panel_5);
            app.Diagnostic_Fault_Perc_Label.Text = '';
            app.Diagnostic_Fault2_Perc_Label = uilabel(app.Panel_5);
            app.Diagnostic_Fault2_Perc_Label.Text = '';
            app.Diagnostic_Fault3_Perc_Label = uilabel(app.Panel_5);
            app.Diagnostic_Fault3_Perc_Label.Text = '';
            app.Show_Alarms_Button_F2 = uibutton(app.Panel_5, 'state');
            app.Show_Alarms_Button_F2.ValueChangedFcn = createCallbackFcn(app, @Show_Alarms_Button_F2ValueChanged, true);
            app.Show_Alarms_Button_F2.Visible = 'off';
            app.Show_Alarms_Button_F2.Text = 'Alarms';
            app.Show_Alarms_Button_F3 = uibutton(app.Panel_5, 'state');
            app.Show_Alarms_Button_F3.ValueChangedFcn = createCallbackFcn(app, @Show_Alarms_Button_F3ValueChanged, true);
            app.Show_Alarms_Button_F3.Visible = 'off';
            app.Show_Alarms_Button_F3.Text = 'Alarms';
            app.PauseButton = uibutton(app.Panel_5, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.Panel_4_ = uipanel(app.UIFigure);
            app.UIAxes_Overview_Sep_Pressure = uiaxes(app.Panel_4_);
            zlabel(app.UIAxes_Overview_Sep_Pressure, 'Z')
            app.UIAxes_Overview_Sep_Pressure.XLim = [0 Inf];
            app.UIAxes_Overview_Sep_Pressure.YLim = [2600 3000];
            app.UIAxes_Overview_Sep_Pressure.XTick = [];
            app.UIAxes_Overview_Sep_Pressure.XTickLabel = '';
            app.UIAxes_Overview_Sep_Pressure.YTick = [];
            app.UIAxes_Overview_Sep_Pressure.YTickLabel = '';
            app.UIAxes_Overview_Sep_Level = uiaxes(app.Panel_4_);
            zlabel(app.UIAxes_Overview_Sep_Level, 'Z')
            app.UIAxes_Overview_Sep_Level.XLim = [0 Inf];
            app.UIAxes_Overview_Sep_Level.YLim = [0 120];
            app.UIAxes_Overview_Sep_Level.XTick = 0;
            app.UIAxes_Overview_Sep_Level.XTickLabel = {'0'};
            app.UIAxes_Overview_Sep_Level.YTick = [];
            app.UIAxes_Overview_Sep_Level.YTickLabel = '';
            app.UIAxes_Overview_Sep_Temp = uiaxes(app.Panel_4_);
            zlabel(app.UIAxes_Overview_Sep_Temp, 'Z')
            app.UIAxes_Overview_Sep_Temp.XLim = [0 Inf];
            app.UIAxes_Overview_Sep_Temp.YLim = [80 105];
            app.UIAxes_Overview_Sep_Temp.XTick = 0;
            app.UIAxes_Overview_Sep_Temp.XTickLabel = {'0'};
            app.UIAxes_Overview_Sep_Temp.YTick = [];
            app.UIAxes_Overview_Sep_Temp.YTickLabel = '';
            app.Label = uilabel(app.Panel_4_);
            app.Label.Text = '';
            app.SeparatorLabel = uilabel(app.Panel_4_);
            app.SeparatorLabel.Text = 'Separator';
            app.Image19 = uiimage(app.Panel_4_);
            app.Image19.ImageSource = 'O_SepCondCompr.png';
            app.CondenserLabel = uilabel(app.Panel_4_);
            app.CondenserLabel.Text = 'Condenser';
            app.SeparatorLabel_2 = uilabel(app.Panel_4_);
            app.SeparatorLabel_2.Text = 'Separator';
            app.SeparatorOutputLabel = uilabel(app.Panel_4_);
            app.SeparatorOutputLabel.Text = 'Separator Output';
            app.Overview_Sep_Underflow = uilabel(app.Panel_4_);
            app.Overview_Sep_Underflow.Text = '0.00 ';
            app.PressureLabel = uilabel(app.Panel_4_);
            app.PressureLabel.Text = 'Pressure';
            app.Overview_Sep_Pressure = uilabel(app.Panel_4_);
            app.Overview_Sep_Pressure.Text = '0.00';
            app.TemperatureLabel = uilabel(app.Panel_4_);
            app.TemperatureLabel.Text = 'Temperature';
            app.Overview_Sep_Temp = uilabel(app.Panel_4_);
            app.Overview_Sep_Temp.Text = '0.00';
            app.LevelLabel = uilabel(app.Panel_4_);
            app.LevelLabel.Text = 'Level';
            app.Overview_Sep_Level = uilabel(app.Panel_4_);
            app.Overview_Sep_Level.Text = '0.00';
            app.CompressorLabel = uilabel(app.Panel_4_);
            app.CompressorLabel.Text = 'Compressor';
            app.m3hLabel = uilabel(app.Panel_4_);
            app.m3hLabel.Text = 'm3/h';
            app.KpaLabel = uilabel(app.Panel_4_);
            app.KpaLabel.Text = 'Kpa';
            app.CLabel = uilabel(app.Panel_4_);
            app.CLabel.Text = '°C';
            app.Label_2 = uilabel(app.Panel_4_);
            app.Label_2.Text = '%';
            app.Overview_Prod_Sp_Label_2 = uilabel(app.Panel_4_);
            app.Overview_Prod_Sp_Label_2.Text = ' 25.26';
            app.TgLabel = uilabel(app.Panel_4_);
            app.TgLabel.Text = 'Tg.';
            app.Overview_B_Compressor_Lamp = uilamp(app.Panel_4_);
            app.Overview_B_Compressor_Lamp.Enable = 'off';
            app.Overview_B_Compressor_Lamp.Visible = 'off';
            app.Overview_B_Condenser_Lamp = uilamp(app.Panel_4_);
            app.Overview_B_Condenser_Lamp.Enable = 'off';
            app.Overview_B_Condenser_Lamp.Visible = 'off';
            app.Overview_B_Separator_Lamp = uilamp(app.Panel_4_);
            app.Overview_B_Separator_Lamp.Enable = 'off';
            app.Overview_B_Separator_Lamp.Visible = 'off';
            app.Overview_Sep_Pressure_Lamp = uilamp(app.Panel_4_);
            app.Overview_Sep_Pressure_Lamp.Enable = 'off';
            app.Overview_Sep_Pressure_Lamp.Visible = 'off';
            app.Overview_Sep_Temp_Lamp = uilamp(app.Panel_4_);
            app.Overview_Sep_Temp_Lamp.Enable = 'off';
            app.Overview_Sep_Temp_Lamp.Visible = 'off';
            app.Overview_Sep_Level_Lamp = uilamp(app.Panel_4_);
            app.Overview_Sep_Level_Lamp.Enable = 'off';
            app.Overview_Sep_Level_Lamp.Visible = 'off';
            app.Overview_B_Purge_Lamp = uilamp(app.Panel_4_);
            app.Overview_B_Purge_Lamp.Enable = 'off';
            app.Overview_B_Purge_Lamp.Visible = 'off';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes_Overview_React_Pressure = uiaxes(app.Panel_2);
            zlabel(app.UIAxes_Overview_React_Pressure, 'Z')
            app.UIAxes_Overview_React_Pressure.XLim = [0 Inf];
            app.UIAxes_Overview_React_Pressure.YLim = [2700 2950];
            app.UIAxes_Overview_React_Pressure.XTick = [];
            app.UIAxes_Overview_React_Pressure.XTickLabel = '';
            app.UIAxes_Overview_React_Pressure.YTick = [];
            app.UIAxes_Overview_React_Pressure.YTickLabel = '';
            app.UIAxes_Overview_React_Level = uiaxes(app.Panel_2);
            zlabel(app.UIAxes_Overview_React_Level, 'Z')
            app.UIAxes_Overview_React_Level.XLim = [0 Inf];
            app.UIAxes_Overview_React_Level.YLim = [0 100];
            app.UIAxes_Overview_React_Level.XTick = 0;
            app.UIAxes_Overview_React_Level.XTickLabel = {'0'};
            app.UIAxes_Overview_React_Level.YTick = [];
            app.UIAxes_Overview_React_Level.YTickLabel = '';
            app.UIAxes_Overview_React_Temp = uiaxes(app.Panel_2);
            zlabel(app.UIAxes_Overview_React_Temp, 'Z')
            app.UIAxes_Overview_React_Temp.XLim = [0 Inf];
            app.UIAxes_Overview_React_Temp.YLim = [0 160];
            app.UIAxes_Overview_React_Temp.XTick = 0;
            app.UIAxes_Overview_React_Temp.XTickLabel = {'0'};
            app.UIAxes_Overview_React_Temp.YTick = [];
            app.UIAxes_Overview_React_Temp.YTickLabel = '';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '';
            app.PressureLabel_2 = uilabel(app.Panel_2);
            app.PressureLabel_2.Text = 'Pressure';
            app.LevelLabel_2 = uilabel(app.Panel_2);
            app.LevelLabel_2.Text = 'Level';
            app.TemperatureLabel_2 = uilabel(app.Panel_2);
            app.TemperatureLabel_2.Text = 'Temperature';
            app.Overview_React_Pressure = uilabel(app.Panel_2);
            app.Overview_React_Pressure.Text = '0.00';
            app.Overview_React_Temp = uilabel(app.Panel_2);
            app.Overview_React_Temp.Text = '0.00';
            app.Overview_React_Level = uilabel(app.Panel_2);
            app.Overview_React_Level.Text = '0.00';
            app.ReactorLabel = uilabel(app.Panel_2);
            app.ReactorLabel.Text = 'Reactor';
            app.TotalFeedLabel = uilabel(app.Panel_2);
            app.TotalFeedLabel.Text = 'Total Feed';
            app.Overview_TotalFeed = uilabel(app.Panel_2);
            app.Overview_TotalFeed.Text = '0.00 ';
            app.ReactorLabel_2 = uilabel(app.Panel_2);
            app.ReactorLabel_2.Text = 'Reactor';
            app.KscmhLabel = uilabel(app.Panel_2);
            app.KscmhLabel.Text = 'Kscmh';
            app.KpaLabel_2 = uilabel(app.Panel_2);
            app.KpaLabel_2.Text = 'Kpa';
            app.CLabel_2 = uilabel(app.Panel_2);
            app.CLabel_2.Text = '°C';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '%';
            app.Overview_Prod_Sp_Label_3 = uilabel(app.Panel_2);
            app.Overview_Prod_Sp_Label_3.Text = '47.6';
            app.TgLabel_2 = uilabel(app.Panel_2);
            app.TgLabel_2.Text = 'Tg.';
            app.Image19_2 = uiimage(app.Panel_2);
            app.Image19_2.ImageSource = 'O_reactor.png';
            app.Overview_Reactor_Pressure_Lamp = uilamp(app.Panel_2);
            app.Overview_Reactor_Pressure_Lamp.Enable = 'off';
            app.Overview_Reactor_Pressure_Lamp.Visible = 'off';
            app.Overview_Reactor_Temp_Lamp = uilamp(app.Panel_2);
            app.Overview_Reactor_Temp_Lamp.Enable = 'off';
            app.Overview_Reactor_Temp_Lamp.Visible = 'off';
            app.Overview_Reactor_Level_Lamp = uilamp(app.Panel_2);
            app.Overview_Reactor_Level_Lamp.Enable = 'off';
            app.Overview_Reactor_Level_Lamp.Visible = 'off';
            app.Overview_B_Feed_Lamp = uilamp(app.Panel_2);
            app.Overview_B_Feed_Lamp.Enable = 'off';
            app.Overview_B_Feed_Lamp.Visible = 'off';
            app.Overview_B_Reactor_Lamp = uilamp(app.Panel_2);
            app.Overview_B_Reactor_Lamp.Enable = 'off';
            app.Overview_B_Reactor_Lamp.Visible = 'off';
            app.Panel_4 = uipanel(app.UIFigure);
            app.UITable_Alarms = uitable(app.Panel_4);
            app.UITable_Alarms.ColumnName = '';
            app.UITable_Alarms.RowName = {};
            app.UITable_Alarms.ColumnEditable = [true false false false false];
            app.UITable_Alarms.CellEditCallback = createCallbackFcn(app, @UITable_AlarmsCellEdit, true);
            app.AlarmsDetailsLabel = uilabel(app.Panel_4);
            app.AlarmsDetailsLabel.Text = 'Alarms Details';
            app.AknLabel = uilabel(app.Panel_4);
            app.AknLabel.Text = 'Akn';
            app.StartTimeLabel = uilabel(app.Panel_4);
            app.StartTimeLabel.Text = 'Start Time';
            app.AlarmDescriptionLabel = uilabel(app.Panel_4);
            app.AlarmDescriptionLabel.Text = 'Alarm Description';
            app.SourceLabel = uilabel(app.Panel_4);
            app.SourceLabel.Text = 'Source';
            app.NoAlarmsNotificationsLabel = uilabel(app.Panel_4);
            app.NoAlarmsNotificationsLabel.Text = 'No Alarms Notifications';
            app.Pressure_Alarm_Icon = uiimage(app.Panel_4);
            app.Pressure_Alarm_Icon.ImageSource = 'MDI - alert.png';
            app.CodeLabel = uilabel(app.Panel_4);
            app.CodeLabel.Text = 'Code';
            app.AlarmsCount_Label = uilabel(app.Panel_4);
            app.AlarmsCount_Label.Text = '0';
            app.Image20 = uiimage(app.UIFigure);
            app.Image20.ImageSource = 'O_arrow.png';
            app.Image20_2 = uiimage(app.UIFigure);
            app.Image20_2.ImageSource = 'O_arrow.png';
            app.Image20_3 = uiimage(app.UIFigure);
            app.Image20_3.ImageSource = 'O_arrowL.png';
            app.Image22 = uiimage(app.UIFigure);
            app.Image22.ImageSource = 'long_arrow.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Overview_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end