classdef TEP_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        CurrentTime_Label               matlab.ui.control.Label
        StripperProductButton           matlab.ui.control.Button
        CompressorButton                matlab.ui.control.Button
        CondenserSeparatorPurgeButton   matlab.ui.control.Button
        ReactorFeedButton               matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ReactorTab                      matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        ValvesTab                       matlab.ui.container.Tab
        ReactorTemp_SetpointC_Spinner   matlab.ui.control.Spinner
        ValveCWR_Control_Label          matlab.ui.control.Label
        ReactorTemp_Setpoint_Label      matlab.ui.control.Label
        ReactorTemp_Setpoint_Reset      matlab.ui.control.Button
        FeedE_SetpointC_Spinner         matlab.ui.control.Spinner
        ValveE_Control_Label            matlab.ui.control.Label
        FeedE_Setpoint_Label            matlab.ui.control.Label
        FeedE_Setpoint_Reset            matlab.ui.control.Button
        FeedD_SetpointC_Spinner         matlab.ui.control.Spinner
        ValveD_Control_Label            matlab.ui.control.Label
        FeedD_Setpoint_Label            matlab.ui.control.Label
        FeedD_Setpoint_Reset            matlab.ui.control.Button
        FeedA_SetpointC_Spinner         matlab.ui.control.Spinner
        ValveA_Control_Label            matlab.ui.control.Label
        FeedA_Setpoint_Label            matlab.ui.control.Label
        FeedA_Setpoint_Reset            matlab.ui.control.Button
        ValveCWR_ManualButton           matlab.ui.control.Button
        ValveCWR_AutoButton             matlab.ui.control.Button
        ValveE_ManualButton             matlab.ui.control.Button
        ValveE_AutoButton               matlab.ui.control.Button
        ValveD_ManualButton             matlab.ui.control.Button
        ValveD_AutoButton               matlab.ui.control.Button
        ValveA_ManualButton             matlab.ui.control.Button
        ValveA_AutoButton               matlab.ui.control.Button
        Image3_19                       matlab.ui.control.Image
        Image3_18                       matlab.ui.control.Image
        Image3_17                       matlab.ui.control.Image
        Image3_16                       matlab.ui.control.Image
        ValveCWR_Slider                 matlab.ui.control.Slider
        ValveE_Slider                   matlab.ui.control.Slider
        ValveD_Slider                   matlab.ui.control.Slider
        ValveA_Slider                   matlab.ui.control.Slider
        TemperatureControlValveCWRLabel_4  matlab.ui.control.Label
        FlowControlValveELabel          matlab.ui.control.Label
        FlowControlValveDLabel          matlab.ui.control.Label
        FlowControlValveALabel          matlab.ui.control.Label
        ControlerTab                    matlab.ui.container.Tab
        ReactorLevel_ratioC_Label       matlab.ui.control.Label
        ReactorLevel_ratioC_Spinner     matlab.ui.control.Spinner
        ReactorLevel_ratioC_Reset       matlab.ui.control.Button
        ReactorPressure_ratioC_Label    matlab.ui.control.Label
        ReactorPressure_ratioC_Spinner  matlab.ui.control.Spinner
        ReactorPressure_ratioC_Reset    matlab.ui.control.Button
        ReactorTemp_Setpoint_Label_16   matlab.ui.control.Label
        ReactorLevel_ManualButton       matlab.ui.control.Button
        ReactorLevel_AutoButton         matlab.ui.control.Button
        ReactorPressure_ManualButton    matlab.ui.control.Button
        ReactorPressure_AutoButton      matlab.ui.control.Button
        HighLevelReactorControlerLabel  matlab.ui.control.Label
        ReactorLevel_Setpoint_Label     matlab.ui.control.Label
        ReactorTemp_Setpoint_Label_15   matlab.ui.control.Label
        ReactorLevel_SetpointC_Spinner  matlab.ui.control.Spinner
        ReactorLevel_Setpoint_Reset     matlab.ui.control.Button
        ReactorPressure_Setpoint_Label  matlab.ui.control.Label
        ReactorPressure_SetpointC_Spinner  matlab.ui.control.Spinner
        ReactorPressure_Setpoint_Reset  matlab.ui.control.Button
        GeneralControlsTab              matlab.ui.container.Tab
        yAC_ratioC_Label                matlab.ui.control.Label
        yAC_ratioC_Spinner              matlab.ui.control.Spinner
        yAC_ratioC_Reset                matlab.ui.control.Button
        yAyAc_ManualButton              matlab.ui.control.Button
        yAyAc_AutoButton                matlab.ui.control.Button
        yA_ratioC_Label                 matlab.ui.control.Label
        yA_ratioC_Spinner               matlab.ui.control.Spinner
        yA_ratioC_Reset                 matlab.ui.control.Button
        GeneralLabel_2                  matlab.ui.control.Label
        MoleG_ratioC_Label              matlab.ui.control.Label
        MoleG_ratioC_Spinner            matlab.ui.control.Spinner
        MoleG_ratioC_Reset              matlab.ui.control.Button
        ReactorTemp_Setpoint_Label_22   matlab.ui.control.Label
        MoleG_ManualButton              matlab.ui.control.Button
        MoleG_AutoButton                matlab.ui.control.Button
        yAC_Setpoint_Label              matlab.ui.control.Label
        yAC_SetpointC_Spinner           matlab.ui.control.Spinner
        yAC_Setpoint_Reset              matlab.ui.control.Button
        yA_Setpoint_Label               matlab.ui.control.Label
        ReactorTemp_Setpoint_Label_20   matlab.ui.control.Label
        yA_SetpointC_Spinner            matlab.ui.control.Spinner
        yA_Setpoint_Reset               matlab.ui.control.Button
        MoleG_Setpoint_Label            matlab.ui.control.Label
        MoleG_SetpointC_Spinner         matlab.ui.control.Spinner
        MoleG_Setpoint_Reset            matlab.ui.control.Button
        Reactor_FeedReactor_Lamp        matlab.ui.control.Lamp
        Reactor_CWR_Lamp                matlab.ui.control.Lamp
        Reactor_Recycle_Lamp            matlab.ui.control.Lamp
        Reactor_Level_Lamp              matlab.ui.control.Lamp
        Reactor_Temp_Lamp               matlab.ui.control.Lamp
        Reactor_Pressure_Lamp           matlab.ui.control.Lamp
        Panel_17                        matlab.ui.container.Panel
        Reactor_ConcF_Lamp              matlab.ui.control.Lamp
        Reactor_ConcE_Lamp              matlab.ui.control.Lamp
        Reactor_ConcD_Lamp              matlab.ui.control.Lamp
        Reactor_ConcC_Lamp              matlab.ui.control.Lamp
        Reactor_ConcB_Lamp              matlab.ui.control.Lamp
        Reactor_ConcA_Lamp              matlab.ui.control.Lamp
        FeedReactorGaugeLabel_8         matlab.ui.control.Label
        FeedReactorGauge_Conc_F         matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel_7         matlab.ui.control.Label
        FeedReactorGauge_Conc_E         matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel_6         matlab.ui.control.Label
        FeedReactorGauge_Conc_D         matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel_5         matlab.ui.control.Label
        FeedReactorGauge_Conc_C         matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel_4         matlab.ui.control.Label
        FeedReactorGauge_Conc_B         matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel_3         matlab.ui.control.Label
        FeedReactorGaugeLabel_2         matlab.ui.control.Label
        FeedReactorGauge_Conc_A         matlab.ui.control.LinearGauge
        CWR_Temperature_Label           matlab.ui.control.Label
        ValveA_Label                    matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        FeedReactorGauge                matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel           matlab.ui.control.Label
        KscmhLabel                      matlab.ui.control.Label
        FlowE_Label                     matlab.ui.control.Label
        FlowD_Label                     matlab.ui.control.Label
        FlowA_Label                     matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        Reactor_FeedE_Lamp              matlab.ui.control.Lamp
        Reactor_FeedD_Lamp              matlab.ui.control.Lamp
        Reactor_FeedA_Lamp              matlab.ui.control.Lamp
        kghLabel_2                      matlab.ui.control.Label
        kghLabel                        matlab.ui.control.Label
        FlowEGauge                      matlab.ui.control.LinearGauge
        FeedEGaugeLabel                 matlab.ui.control.Label
        FlowDGauge                      matlab.ui.control.LinearGauge
        FeedDGaugeLabel                 matlab.ui.control.Label
        MainFeedFlowLabel               matlab.ui.control.Label
        FlowAGauge                      matlab.ui.control.LinearGauge
        FeedAGaugeLabel                 matlab.ui.control.Label
        ReactorLabel                    matlab.ui.control.Label
        kPaLabel                        matlab.ui.control.Label
        Level_Label                     matlab.ui.control.Label
        Temperature_Label               matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        Pression_Label                  matlab.ui.control.Label
        ValveD_Label                    matlab.ui.control.Label
        ValveE_Label                    matlab.ui.control.Label
        ValveCWR_Label                  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        FlowReactor_Label               matlab.ui.control.Label
        KscmhLabel_2                    matlab.ui.control.Label
        CLabel_3                        matlab.ui.control.Label
        Recycle_Label                   matlab.ui.control.Label
        KscmhLabel_3                    matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        UIAxes_Temp                     matlab.ui.control.UIAxes
        UIAxes_Pressure                 matlab.ui.control.UIAxes
        UIAxes_Level                    matlab.ui.control.UIAxes
        UIAxes_CWR_Temperature          matlab.ui.control.UIAxes
        UIAxes_Recycle                  matlab.ui.control.UIAxes
        SeparatorTab                    matlab.ui.container.Tab
        TabGroup2_2                     matlab.ui.container.TabGroup
        ValveTab_2                      matlab.ui.container.Tab
        ProductFlow_SetpointC_Spinner   matlab.ui.control.Spinner
        ValveCond_Control_Label         matlab.ui.control.Label
        ProductFlow_Setpoint_Label      matlab.ui.control.Label
        ProductFlow_Setpoint_Reset      matlab.ui.control.Button
        SepLevel_SetpointC_Spinner      matlab.ui.control.Spinner
        ValveSep_Control_Label          matlab.ui.control.Label
        SepLevel_Setpoint_Label         matlab.ui.control.Label
        SepLevel_Setpoint_Reset         matlab.ui.control.Button
        PurgeFlow_SetpointC_Spinner     matlab.ui.control.Spinner
        ValvePurge_Control_Label        matlab.ui.control.Label
        PurgeFlow_Setpoint_Label        matlab.ui.control.Label
        PurgeFlow_Setpoint_Reset        matlab.ui.control.Button
        Cond_ValveCWR_ManualButton      matlab.ui.control.Button
        Cond_ValveCWR_AutoButton        matlab.ui.control.Button
        Image3_15                       matlab.ui.control.Image
        Cond_ValveCWR_Slider            matlab.ui.control.Slider
        TemperatureControlValveCWRLabel_3  matlab.ui.control.Label
        Sep_ValveLiquidU_ManualButton   matlab.ui.control.Button
        Sep_ValveLiquidU_AutoButton     matlab.ui.control.Button
        Sep_ValvePurge_ManualButton     matlab.ui.control.Button
        Sep_ValvePurge_AutoButton       matlab.ui.control.Button
        Image3_10                       matlab.ui.control.Image
        Image3_9                        matlab.ui.control.Image
        Sep_ValveLiquidU_Slider         matlab.ui.control.Slider
        Sep_ValvePurge_Slider           matlab.ui.control.Slider
        LevelControlValveLabel          matlab.ui.control.Label
        FlowControlPurgeValveLabel      matlab.ui.control.Label
        ControlerTab_2                  matlab.ui.container.Tab
        SepTemp_Setpoint_Reset          matlab.ui.control.Button
        SepFlow_Setpoint_Label          matlab.ui.control.Label
        SepFlow_SetpointC_Spinner       matlab.ui.control.Spinner
        SepFlow_Setpoint_Reset          matlab.ui.control.Button
        SepTemp_Setpoint_Label          matlab.ui.control.Label
        SepTemp_SetpointC_Spinner       matlab.ui.control.Spinner
        ReactorTemp_Setpoint_Label_19   matlab.ui.control.Label
        HighLevelSeparatorControlerLabel  matlab.ui.control.Label
        ReactorTemp_Setpoint_Label_18   matlab.ui.control.Label
        Cond_ValveCWR_Label             matlab.ui.control.Label
        Sep_Underflow_Lamp              matlab.ui.control.Lamp
        Sep_Purge_Lamp                  matlab.ui.control.Lamp
        Cond_CWR_Lamp                   matlab.ui.control.Lamp
        Sep_Level_Lamp                  matlab.ui.control.Lamp
        Sep_Temp_Lamp                   matlab.ui.control.Lamp
        Sep_Pressure_Lamp               matlab.ui.control.Lamp
        Panel_18                        matlab.ui.container.Panel
        Sep_Purge_ConcH_Lamp            matlab.ui.control.Lamp
        Sep_Purge_ConcG_Lamp            matlab.ui.control.Lamp
        Sep_Purge_ConcF_Lamp            matlab.ui.control.Lamp
        Sep_Purge_ConcE_Lamp            matlab.ui.control.Lamp
        Sep_Purge_ConcD_Lamp            matlab.ui.control.Lamp
        Sep_Purge_ConcC_Lamp            matlab.ui.control.Lamp
        Sep_Purge_ConcB_Lamp            matlab.ui.control.Lamp
        Sep_Purge_ConcA_Lamp            matlab.ui.control.Lamp
        PurgeGauge_Conc_C               matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel_17        matlab.ui.control.Label
        PurgeGauge_Conc_H               matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel_16        matlab.ui.control.Label
        PurgeGauge_Conc_G               matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel_15        matlab.ui.control.Label
        PurgeGauge_Conc_F               matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel_14        matlab.ui.control.Label
        PurgeGauge_Conc_E               matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel_13        matlab.ui.control.Label
        PurgeGauge_Conc_D               matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel_12        matlab.ui.control.Label
        FeedReactorGaugeLabel_11        matlab.ui.control.Label
        PurgeGauge_Conc_B               matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel_10        matlab.ui.control.Label
        FeedReactorGaugeLabel_9         matlab.ui.control.Label
        PurgeGauge_Conc_A               matlab.ui.control.LinearGauge
        CLabel_10                       matlab.ui.control.Label
        Cond_CWR_Temperature_Label      matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Sep_Valve_LiquidU_Label         matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Sep_Valve_Purge_Label           matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Sep_LiquidU_Label               matlab.ui.control.Label
        m3hLabel_8                      matlab.ui.control.Label
        Sep_PurgeFlow_Label             matlab.ui.control.Label
        m3hLabel_7                      matlab.ui.control.Label
        SeparatorCondenserLabel         matlab.ui.control.Label
        kPaLabel_2                      matlab.ui.control.Label
        Sep_Level_Label                 matlab.ui.control.Label
        Sep_Temperature_Label           matlab.ui.control.Label
        CLabel_6                        matlab.ui.control.Label
        Sep_Pression_Label              matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Image5_2                        matlab.ui.control.Image
        UIAxes_Cond_CWR_Temp            matlab.ui.control.UIAxes
        UIAxes_Sep_LiquidU              matlab.ui.control.UIAxes
        UIAxes_Sep_Purge                matlab.ui.control.UIAxes
        UIAxes_Sep_Pressure             matlab.ui.control.UIAxes
        UIAxes_Sep_Temp                 matlab.ui.control.UIAxes
        UIAxes_Sep_Level                matlab.ui.control.UIAxes
        CompressorTab                   matlab.ui.container.Tab
        Comp_Purge_Lamp                 matlab.ui.control.Lamp
        Comp_Work_Lamp                  matlab.ui.control.Lamp
        Comp_Recycle_Lamp               matlab.ui.control.Lamp
        Label_9                         matlab.ui.control.Label
        kWLabel                         matlab.ui.control.Label
        KscmhLabel_4                    matlab.ui.control.Label
        KscmhLabel_5                    matlab.ui.control.Label
        Comp_Valve_Label                matlab.ui.control.Label
        Comp_Recycle_Label              matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        ValvePurge_Control_Label_2      matlab.ui.control.Label
        Comp_FlowRate_ManualButton      matlab.ui.control.Button
        Comp_FlowRate_AutoButton        matlab.ui.control.Button
        Image3_11                       matlab.ui.control.Image
        Comp_FlowRate_Slider            matlab.ui.control.Slider
        FlowControlValveCompressorLabel  matlab.ui.control.Label
        CompressorValveLabel            matlab.ui.control.Label
        CompressorLabel                 matlab.ui.control.Label
        Comp_Purge_Label                matlab.ui.control.Label
        Comp_Work_Label                 matlab.ui.control.Label
        Image5_3                        matlab.ui.control.Image
        UIAxes_Comp_FlowRate            matlab.ui.control.UIAxes
        UIAxes_Comp_Work                matlab.ui.control.UIAxes
        UIAxes_Comp_Purge_FlowRate      matlab.ui.control.UIAxes
        StripperTab                     matlab.ui.container.Tab
        Strip_Underflow_Lamp            matlab.ui.control.Lamp
        Strip_Steam_Lamp                matlab.ui.control.Lamp
        Strip_Pressure_Lamp             matlab.ui.control.Lamp
        Strip_Temp_Lamp                 matlab.ui.control.Lamp
        Strip_Level_Lamp                matlab.ui.control.Lamp
        Panel_19                        matlab.ui.container.Panel
        Strip_Product_ConcH_Lamp        matlab.ui.control.Lamp
        Strip_Product_ConcG_Lamp        matlab.ui.control.Lamp
        Strip_Product_ConcF_Lamp        matlab.ui.control.Lamp
        Strip_Product_ConcE_Lamp        matlab.ui.control.Lamp
        Strip_Product_ConcD_Lamp        matlab.ui.control.Lamp
        FeedReactorGaugeLabel_23        matlab.ui.control.Label
        ProductGauge_Conc_H             matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel_22        matlab.ui.control.Label
        ProductGauge_Conc_G             matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel_21        matlab.ui.control.Label
        ProductGauge_Conc_F             matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel_20        matlab.ui.control.Label
        ProductGauge_Conc_E             matlab.ui.control.LinearGauge
        FeedReactorGaugeLabel_19        matlab.ui.control.Label
        FeedReactorGaugeLabel_18        matlab.ui.control.Label
        ProductGauge_Conc_D             matlab.ui.control.LinearGauge
        Panel_8                         matlab.ui.container.Panel
        Strip_FeedC_Lamp                matlab.ui.control.Lamp
        FlowCGauge                      matlab.ui.control.LinearGauge
        KsmchLabel                      matlab.ui.control.Label
        FlowC_Label                     matlab.ui.control.Label
        FeedCLabel                      matlab.ui.control.Label
        Strip_ValveC_Label              matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Strip_ValveProduct_Label        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Strip_ValveSteam_Label          matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Strip_Underflow_Label           matlab.ui.control.Label
        m3hLabel_6                      matlab.ui.control.Label
        Strip_SteamFlow_Label           matlab.ui.control.Label
        m3hLabel_5                      matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        ValveC_Control_Label_2          matlab.ui.control.Label
        FeedC_SetpointC_Spinner         matlab.ui.control.Spinner
        StripLevel_SetpointC_Spinner    matlab.ui.control.Spinner
        ValveStrip_Control_Label        matlab.ui.control.Label
        StripLevel_Setpoint_Label       matlab.ui.control.Label
        StripLevel_Setpoint_Reset       matlab.ui.control.Button
        ValveC_Control_Label            matlab.ui.control.Label
        FeedC_Setpoint_Label            matlab.ui.control.Label
        FeedC_Setpoint_Reset            matlab.ui.control.Button
        Image3_14                       matlab.ui.control.Image
        Strip_ValveProduct_ManualButton  matlab.ui.control.Button
        Strip_ValveProduct_AutoButton   matlab.ui.control.Button
        Strip_ValveProduct_Slider       matlab.ui.control.Slider
        LevelControlValveProductLabel   matlab.ui.control.Label
        Image3_13                       matlab.ui.control.Image
        Strip_ValveSteam_ManualButton   matlab.ui.control.Button
        Strip_ValveSteam_AutoButton     matlab.ui.control.Button
        Strip_ValveC_ManualButton       matlab.ui.control.Button
        Strip_ValveC_AutoButton         matlab.ui.control.Button
        Image3_12                       matlab.ui.control.Image
        Strip_ValveSteam_Slider         matlab.ui.control.Slider
        Strip_ValveC_Slider             matlab.ui.control.Slider
        FlowControlSteamValveLabel      matlab.ui.control.Label
        FlowControlValveCLabel          matlab.ui.control.Label
        StripperLabel                   matlab.ui.control.Label
        kPaLabel_3                      matlab.ui.control.Label
        Strip_Level_Label               matlab.ui.control.Label
        Strip_Temp_Label                matlab.ui.control.Label
        CLabel_9                        matlab.ui.control.Label
        Strip_Pression_Label            matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Image5_4                        matlab.ui.control.Image
        UIAxes_Strip_Product            matlab.ui.control.UIAxes
        UIAxes_Strip_Steam              matlab.ui.control.UIAxes
        UIAxes_Strip_Pressure           matlab.ui.control.UIAxes
        UIAxes_Strip_Temp               matlab.ui.control.UIAxes
        UIAxes_Strip_Level              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        h; % Description
        d;
        r;
        tr;
        lr;
        cwrT;
        ifx = [];
        ify = [];
        startTime;
        radar;
        count=0;
        tabBarSelected;
        %Condenser
        cond_CWR_axes
        %Separator
        sep_p;
        sep_t;
        sep_l;
        sep_purge;
        sep_liqU;
        sep_ifx;
        sep_ify;
        %Compressor
        comp_recycle;
        comp_work;
        comp_purge;
        %Stripper
        strip_p;
        strip_t;
        strip_l;
        strip_steam;
        strip_prod;
        strip_ifx;
        strip_ify;
        %Graph color
        lineColor = [0.3010 0.7450 0.9330];
        whiteColor = [1 1 1];
        selectedColor = [0.26 0.27 0.27];
        %Overview
        CallingApp;
        %Time
        time;
    end
    
    methods (Access = public)
      
        function app = updateGUI(app, ~, varargin)
           disp('update');
           % Create an object that gets the run-time value of the specified block
           simout = get_param('MultiLoop_mode1/Simout', 'RuntimeObject');

           app.CallingApp.updateOverview(simout);
           updateReactor(app,simout);
           updateSeparator(app,simout);
           updateCompressor(app,simout);
           updateStripper(app,simout);

           app.CallingApp.refreshAlarms(app, simout);
        end

        function updateReactor(app, simout)
           %Valves xmv
           rtoValveA = get_param('MultiLoop_mode1/TE Plant/ValveA','RuntimeObject');
           rtoValveD = get_param('MultiLoop_mode1/TE Plant/ValveD','RuntimeObject'); 
           rtoValveE = get_param('MultiLoop_mode1/TE Plant/ValveE','RuntimeObject');
           rtoValveCWR= get_param('MultiLoop_mode1/TE Plant/ValveCWR','RuntimeObject');

           %Setpoint Control
           rtoFeedAsetpoint = get_param('MultiLoop_mode1/TE Plant/A feed rate/A_Feed','RuntimeObject');
           rtoFeedDsetpoint = get_param('MultiLoop_mode1/TE Plant/D feed rate/D_Feed','RuntimeObject');      
           rtoFeedEsetpoint = get_param('MultiLoop_mode1/TE Plant/E feed rate/E_Feed','RuntimeObject');
           rtoReactorTempsetpoint = get_param('MultiLoop_mode1/TE Plant/Reactor temperature/ReactorTemp','RuntimeObject');

           %Axes label
           app.Pression_Label.Text = num2str(simout.InputPort(1).Data(7), '%05.2f');
           app.Temperature_Label.Text = num2str(simout.InputPort(1).Data(9), '%05.2f');
           app.Level_Label.Text = num2str(simout.InputPort(1).Data(8), '%05.2f');

           %feed
           app.FlowA_Label.Text = num2str(simout.InputPort(1).Data(1), '%05.2f');
           app.FlowD_Label.Text = num2str(simout.InputPort(1).Data(2), '%05.2f');
           app.FlowE_Label.Text = num2str(simout.InputPort(1).Data(3), '%05.2f');
           app.FlowReactor_Label.Text = num2str(simout.InputPort(1).Data(6), '%05.2f');

           %valves
           app.ValveA_Label.Text = num2str(rtoValveA.InputPort(1).Data, '%05.2f');
           app.ValveD_Label.Text = num2str(rtoValveD.InputPort(1).Data, '%05.2f');
           app.ValveE_Label.Text = num2str(rtoValveE.InputPort(1).Data, '%05.2f');  
           app.ValveCWR_Label.Text = num2str(rtoValveCWR.InputPort(1).Data, '%05.2f'); 

           %Slider valves control setpoints
           app.ValveA_Slider.Value = round(rtoValveA.InputPort(1).Data,2);
           app.ValveD_Slider.Value = round(rtoValveD.InputPort(1).Data,2);
           app.ValveE_Slider.Value = round(rtoValveE.InputPort(1).Data,2);
           app.ValveCWR_Slider.Value = round(rtoValveCWR.InputPort(1).Data,2);

           %Spinner Setpoints
           app.FeedA_SetpointC_Spinner.Value = round(rtoFeedAsetpoint.InputPort(1).Data,2);
           app.FeedD_SetpointC_Spinner.Value = round(rtoFeedDsetpoint.InputPort(1).Data,2);
           app.FeedE_SetpointC_Spinner.Value = round(rtoFeedEsetpoint.InputPort(1).Data,2);
           app.ReactorTemp_SetpointC_Spinner.Value = round(rtoReactorTempsetpoint.InputPort(1).Data,2);

           %Recylce
           app.Recycle_Label.Text = num2str(simout.InputPort(1).Data(5), '%05.2f');
           
           %CWR
           app.CWR_Temperature_Label.Text = num2str(simout.InputPort(1).Data(21), '%05.2f');
        
           %Gauge
           app.FlowAGauge.Value = simout.InputPort(1).Data(1);
           app.FlowDGauge.Value = simout.InputPort(1).Data(2);
           app.FlowEGauge.Value = simout.InputPort(1).Data(3);
           app.FeedReactorGauge.Value = simout.InputPort(1).Data(6);

           %Gauge Concentration
           app.FeedReactorGauge_Conc_A.Value = simout.InputPort(1).Data(23);
           app.FeedReactorGauge_Conc_B.Value = simout.InputPort(1).Data(24); 
           app.FeedReactorGauge_Conc_C.Value = simout.InputPort(1).Data(25); 
           app.FeedReactorGauge_Conc_D.Value = simout.InputPort(1).Data(26); 
           app.FeedReactorGauge_Conc_E.Value = simout.InputPort(1).Data(27); 
           app.FeedReactorGauge_Conc_F.Value = simout.InputPort(1).Data(28); 

           if(simout.CurrentTime <= 0)
               %Reactor
               app.h = animatedline(app.UIAxes_Pressure, 'Color','#5F93E5','LineWidth',2);
               app.tr = animatedline(app.UIAxes_Temp, 'Color','#5F93E5','LineWidth',2);
               app.lr = animatedline(app.UIAxes_Level, 'Color','#5F93E5','LineWidth',2);
               
               %small graph
               app.r = animatedline(app.UIAxes_Recycle ,'Color','#5F93E5','LineWidth',2);
               app.cwrT = animatedline(app.UIAxes_CWR_Temperature ,'Color','#5F93E5','LineWidth',2);
           else
                t =  datetime('now'); 
                tDif = datenum([t-seconds(240) t]);
                %Pression
                addpoints(app.h,datenum(t),simout.InputPort(1).Data(7));
                app.UIAxes_Pressure.XLim = tDif;
                datetick(app.UIAxes_Pressure,'x','keeplimits');
                
                %temperature
                addpoints(app.tr,datenum(t),simout.InputPort(1).Data(9))
                app.UIAxes_Temp.XLim = tDif;
                datetick(app.UIAxes_Temp,'x','keeplimits');

                %level
                addpoints(app.lr,datenum(t),simout.InputPort(1).Data(8))
                app.UIAxes_Level.XLim = tDif;
                datetick(app.UIAxes_Level,'x','keeplimits');

                %recycle - CWR
                addpoints(app.r,datenum(t), simout.InputPort(1).Data(5));
                app.UIAxes_Recycle.XLim = tDif;
                %datetick(app.UIAxes_Recycle,'x','keeplimits');

                addpoints(app.cwrT,datenum(t), simout.InputPort(1).Data(21));
                app.UIAxes_CWR_Temperature.XLim = tDif;
                %datetick(app.UIAxes_CWR_Temperature,'x','keeplimits');
           end 
        end

        function updateSeparator(app, simout)
           %Valves xmv
           rtoSepValvePurge = get_param('MultiLoop_mode1/TE Plant/Sep_ValvePurge','RuntimeObject');%6
           rtoSepValve = get_param('MultiLoop_mode1/TE Plant/Sep_ValveLiquidU','RuntimeObject');%7

           %Setpoint Control
           rtoPurgeFlowsetpoint = get_param('MultiLoop_mode1/TE Plant/Purge rate/PurgeFlow','RuntimeObject');
           rtoSepLevelsetpoint = get_param('MultiLoop_mode1/TE Plant/Separator level/SepLevel','RuntimeObject');      
           rtoProductFlowsetpoint = get_param('MultiLoop_mode1/TE Plant/Stripper flow rate/ProductFlow','RuntimeObject');

           %Axes label
           app.Sep_Pression_Label.Text = num2str(simout.InputPort(1).Data(13), '%05.2f');
           app.Sep_Temperature_Label.Text = num2str(simout.InputPort(1).Data(11), '%05.2f');
           app.Sep_Level_Label.Text = num2str(simout.InputPort(1).Data(12), '%05.2f');

           %valves labels
           app.Sep_Valve_Purge_Label.Text = num2str(rtoSepValvePurge.InputPort(1).Data, '%05.2f');
           app.Sep_Valve_LiquidU_Label.Text = num2str(rtoSepValve.InputPort(1).Data, '%05.2f');
 
           %Slider valves control setpoints
           app.Sep_ValvePurge_Slider.Value = round(rtoSepValvePurge.InputPort(1).Data,2);
           app.Sep_ValveLiquidU_Slider.Value = round(rtoSepValve.InputPort(1).Data,2);

           %Purge Flow - Liquid Underflow
           app.Sep_PurgeFlow_Label.Text = num2str(simout.InputPort(1).Data(10), '%05.2f');
           app.Sep_LiquidU_Label.Text = num2str(simout.InputPort(1).Data(14), '%05.2f');

           %Gauge Concentration
           app.PurgeGauge_Conc_A.Value = simout.InputPort(1).Data(29);
           app.PurgeGauge_Conc_B.Value = simout.InputPort(1).Data(30); 
           app.PurgeGauge_Conc_C.Value = simout.InputPort(1).Data(31); 
           app.PurgeGauge_Conc_D.Value = simout.InputPort(1).Data(32); 
           app.PurgeGauge_Conc_E.Value = simout.InputPort(1).Data(33); 
           app.PurgeGauge_Conc_F.Value = simout.InputPort(1).Data(34); 
           app.PurgeGauge_Conc_G.Value = simout.InputPort(1).Data(35);
           app.PurgeGauge_Conc_H.Value = simout.InputPort(1).Data(36);
       
           %CONDENSER
           %Valve xmv11
           rtoCondValveCWR = get_param('MultiLoop_mode1/TE Plant/Cond_ValveCWR','RuntimeObject');
           
           %Axes label
           app.Cond_CWR_Temperature_Label.Text = num2str(simout.InputPort(1).Data(22), '%05.2f');
           
           %valves 
           app.Cond_ValveCWR_Label.Text = num2str(rtoCondValveCWR.InputPort(1).Data, '%05.2f');
           
           %Slider valves control setpoints
           app.Cond_ValveCWR_Slider.Value = round(rtoCondValveCWR.InputPort(1).Data,2);

           %Spinner Setpoints
           app.PurgeFlow_SetpointC_Spinner.Value = round(rtoPurgeFlowsetpoint.InputPort(1).Data,2);
           app.SepLevel_SetpointC_Spinner.Value = round(rtoSepLevelsetpoint.InputPort(1).Data,2);
           app.ProductFlow_SetpointC_Spinner.Value = round(rtoProductFlowsetpoint.InputPort(1).Data,2);

           if(simout.CurrentTime <= 0)
               %Separator
               app.sep_p = animatedline(app.UIAxes_Sep_Pressure, 'Color','#5F93E5','LineWidth',2);
               app.sep_t = animatedline(app.UIAxes_Sep_Temp, 'Color','#5F93E5','LineWidth',2);
               app.sep_l = animatedline(app.UIAxes_Sep_Level, 'Color','#5F93E5','LineWidth',2);
               
               %small graph
               app.sep_purge = animatedline(app.UIAxes_Sep_Purge ,'Color','#5F93E5','LineWidth',2);
               app.sep_liqU = animatedline(app.UIAxes_Sep_LiquidU ,'Color','#5F93E5','LineWidth',2);
               app.cond_CWR_axes = animatedline(app.UIAxes_Cond_CWR_Temp, 'Color','#5F93E5','LineWidth',2);
           else
                t =  datetime('now'); 
                tDif = datenum([t-seconds(240) t]);
                %Pression
                addpoints(app.sep_p,datenum(t),simout.InputPort(1).Data(13));
                app.UIAxes_Sep_Pressure.XLim = tDif;
                datetick(app.UIAxes_Sep_Pressure,'x','keeplimits');
                
                %temperature
                addpoints(app.sep_t,datenum(t),simout.InputPort(1).Data(11))
                app.UIAxes_Sep_Temp.XLim = tDif;
                datetick(app.UIAxes_Sep_Temp,'x','keeplimits');

                %level
                addpoints(app.sep_l,datenum(t),simout.InputPort(1).Data(12))
                app.UIAxes_Sep_Level.XLim = tDif;
                datetick(app.UIAxes_Sep_Level,'x','keeplimits');

                %CWR Axe
                addpoints(app.cond_CWR_axes,datenum(t),simout.InputPort(1).Data(22));
                app.UIAxes_Cond_CWR_Temp.XLim = tDif;
                %datetick(app.UIAxes_Cond_CWR_Temp,'x','keeplimits');

                addpoints(app.sep_purge,datenum(t), simout.InputPort(1).Data(10));
                app.UIAxes_Sep_Purge.XLim = tDif;
                %datetick(app.UIAxes_Sep_Purge,'x','keeplimits');

                addpoints(app.sep_liqU,datenum(t), simout.InputPort(1).Data(14));
                app.UIAxes_Sep_LiquidU.XLim = tDif;
                %datetick(app.UIAxes_Sep_LiquidU,'x','keeplimits');
           end 
        end

        function updateCompressor(app,simout)
           %Valve xmv5 object
           rtoValveCompRecycle = get_param('MultiLoop_mode1/TE Plant/Comp_ValveRecycle','RuntimeObject');
           
           %Axes label
           app.Comp_Recycle_Label.Text = num2str(simout.InputPort(1).Data(5), '%05.2f');
           app.Comp_Work_Label.Text = num2str(simout.InputPort(1).Data(20), '%05.2f');
           app.Comp_Purge_Label.Text = num2str(simout.InputPort(1).Data(10), '%05.2f');

           %valves label
           app.Comp_Valve_Label.Text = num2str(rtoValveCompRecycle.InputPort(1).Data, '%05.2f');
           
           %Slider valves control setpoints
           app.Comp_FlowRate_Slider.Value = round(rtoValveCompRecycle.InputPort(1).Data,2);
    
           if(simout.CurrentTime <= 0)
               %Reactor
               app.comp_recycle = animatedline(app.UIAxes_Comp_FlowRate, 'Color','#5F93E5','LineWidth',2);
               app.comp_work = animatedline(app.UIAxes_Comp_Work, 'Color','#5F93E5','LineWidth',2);
               app.comp_purge = animatedline(app.UIAxes_Comp_Purge_FlowRate, 'Color','#5F93E5','LineWidth',2);
           
           else
               t =  datetime('now'); 
               tDif = datenum([t-seconds(240) t]);

               %Recycle Axe
               addpoints(app.comp_recycle,datenum(t),simout.InputPort(1).Data(5));
               app.UIAxes_Comp_FlowRate.XLim = tDif;
               datetick(app.UIAxes_Comp_FlowRate,'x','keeplimits');

               %Work Axe
               addpoints(app.comp_work,datenum(t),simout.InputPort(1).Data(20));
               app.UIAxes_Comp_Work.XLim = tDif;
               datetick(app.UIAxes_Comp_Work,'x','keeplimits');

               %Purge Axe
               addpoints(app.comp_purge,datenum(t),simout.InputPort(1).Data(10));
               app.UIAxes_Comp_Purge_FlowRate.XLim = tDif;
               datetick(app.UIAxes_Comp_Purge_FlowRate,'x','keeplimits');
           end
        end

        function updateStripper(app, simout)
           %Valves xmv
           rtoValveC = get_param('MultiLoop_mode1/TE Plant/ValveC','RuntimeObject');
           rtoStripValveSteam = get_param('MultiLoop_mode1/TE Plant/Strip_ValveSteam','RuntimeObject');
           rtoStripValveProduct = get_param('MultiLoop_mode1/TE Plant/Strip_ValveProduct','RuntimeObject');

           %Setpoint Control
           rtoValveCsetpoint = get_param('MultiLoop_mode1/TE Plant/C feed rate/C_Feed','RuntimeObject');
           rtoStripLevelsetpoint = get_param('MultiLoop_mode1/TE Plant/Stripper level/StripLevel','RuntimeObject');      
           
           %Axes label
           app.Strip_Pression_Label.Text = num2str(simout.InputPort(1).Data(16), '%05.2f');
           app.Strip_Temp_Label.Text = num2str(simout.InputPort(1).Data(18), '%05.2f');
           app.Strip_Level_Label.Text = num2str(simout.InputPort(1).Data(15), '%05.2f');

           %valves labels
           app.Strip_ValveC_Label.Text = num2str(rtoValveC.InputPort(1).Data, '%05.2f');
           app.Strip_ValveSteam_Label.Text = num2str(rtoStripValveSteam.InputPort(1).Data, '%05.2f');
           app.Strip_ValveProduct_Label.Text = num2str(rtoStripValveProduct.InputPort(1).Data, '%05.2f');
           
           %Slider valves control setpoints
           app.Strip_ValveC_Slider.Value = round(rtoValveC.InputPort(1).Data,2);
           app.Strip_ValveSteam_Slider.Value = round(rtoStripValveSteam.InputPort(1).Data,2);
           app.Strip_ValveProduct_Slider.Value = round(rtoStripValveProduct.InputPort(1).Data,2);

           %Steam Flow - Liquid Underflow
           app.Strip_SteamFlow_Label.Text = num2str(simout.InputPort(1).Data(19), '%05.2f');
           app.Strip_Underflow_Label.Text = num2str(simout.InputPort(1).Data(17), '%05.2f');
           
           %Gauge
           app.FlowC_Label.Text = num2str(simout.InputPort(1).Data(4), '%05.2f');
           app.FlowCGauge.Value = simout.InputPort(1).Data(4);

           %Gauge Concentration
           app.ProductGauge_Conc_D.Value = simout.InputPort(1).Data(37);
           app.ProductGauge_Conc_E.Value = simout.InputPort(1).Data(38); 
           app.ProductGauge_Conc_F.Value = simout.InputPort(1).Data(39); 
           app.ProductGauge_Conc_G.Value = simout.InputPort(1).Data(40); 
           app.ProductGauge_Conc_H.Value = simout.InputPort(1).Data(41); 

           %Spinner Setpoints
           app.FeedC_SetpointC_Spinner.Value = round(rtoValveCsetpoint.InputPort(1).Data,2);
           app.StripLevel_SetpointC_Spinner.Value = round(rtoStripLevelsetpoint.InputPort(1).Data,2);
          
           if(simout.CurrentTime <= 0)
               %Stripper
               app.strip_p = animatedline(app.UIAxes_Strip_Pressure, 'Color','#5F93E5','LineWidth',2);
               app.strip_t = animatedline(app.UIAxes_Strip_Temp, 'Color','#5F93E5','LineWidth',2);
               app.strip_l = animatedline(app.UIAxes_Strip_Level, 'Color','#5F93E5','LineWidth',2);
               
               %small graph
               app.strip_steam = animatedline(app.UIAxes_Strip_Steam ,'Color','#5F93E5','LineWidth',2);
               app.strip_prod = animatedline(app.UIAxes_Strip_Product ,'Color','#5F93E5','LineWidth',2);
           else
                t =  datetime('now'); 
                tDif = datenum([t-seconds(240) t]);
                %Pression
                addpoints(app.strip_p,datenum(t),simout.InputPort(1).Data(16));
                app.UIAxes_Strip_Pressure.XLim = tDif;
                datetick(app.UIAxes_Strip_Pressure,'x','keeplimits');
                
                %temperature
                addpoints(app.strip_t,datenum(t),simout.InputPort(1).Data(18))
                app.UIAxes_Strip_Temp.XLim = tDif;
                datetick(app.UIAxes_Strip_Temp,'x','keeplimits');

                %level
                addpoints(app.strip_l,datenum(t),simout.InputPort(1).Data(15))
                app.UIAxes_Strip_Level.XLim = tDif;
                datetick(app.UIAxes_Strip_Level,'x','keeplimits');

                %Steam and Product Flow Axes
                addpoints(app.strip_steam,datenum(t), simout.InputPort(1).Data(19));
                app.UIAxes_Strip_Steam.XLim = tDif;
                %datetick(app.UIAxes_Strip_Steam,'x','keeplimits');

                addpoints(app.strip_prod,datenum(t), simout.InputPort(1).Data(17));
                app.UIAxes_Strip_Product.XLim = tDif;
                %datetick(app.UIAxes_Strip_Product,'x','keeplimits');
           end 
        end

        function initializeReactor(app)
            %Valve Control setpoints
            app.ValveA_AutoButton.FontColor = app.whiteColor;
            app.ValveA_AutoButton.BackgroundColor = app.selectedColor; 

            app.ValveD_AutoButton.FontColor = app.whiteColor;
            app.ValveD_AutoButton.BackgroundColor = app.selectedColor; 

            app.ValveE_AutoButton.FontColor = app.whiteColor;
            app.ValveE_AutoButton.BackgroundColor = app.selectedColor; 

            app.ValveCWR_AutoButton.FontColor = app.whiteColor;
            app.ValveCWR_AutoButton.BackgroundColor = app.selectedColor; 

            %Controler
            app.ReactorPressure_AutoButton.FontColor = app.whiteColor;
            app.ReactorPressure_AutoButton.BackgroundColor = app.selectedColor; 

            app.ReactorLevel_AutoButton.FontColor = app.whiteColor;
            app.ReactorLevel_AutoButton.BackgroundColor = app.selectedColor; 

            app.yAyAc_AutoButton.FontColor = app.whiteColor;
            app.yAyAc_AutoButton.BackgroundColor = app.selectedColor; 

            app.MoleG_AutoButton.FontColor = app.whiteColor;
            app.MoleG_AutoButton.BackgroundColor = app.selectedColor; 

            %Setpoint
            ps = yline(app.UIAxes_Pressure,2800,'LineWidth',1.5);
            ps.Color = [0.3010 0.7450 0.9330];

            ts =  yline(app.UIAxes_Temp,122.9,'LineWidth',1.5);
            ts.Color = [0.3010 0.7450 0.9330];

            ls =  yline(app.UIAxes_Level,65,'LineWidth',1.5);
            ls.Color = [0.3010 0.7450 0.9330];

            rc =  yline(app.UIAxes_Recycle,32.42,'LineWidth',1.5);
            rc.Color = [0.3010 0.7450 0.9330];

            cwrs =  yline(app.UIAxes_CWR_Temperature,102.47,'LineWidth',1.5);
            cwrs.Color = [0.3010 0.7450 0.9330];

            %Blue box Pressure
            limX = [datenum(datetime('now')-seconds(240)) datenum(datetime('now')+minutes(240)) datenum(datetime('now')+minutes(240)) datenum(datetime('now')-seconds(240))];
            limY = [0 0 2895 2895];

            box = patch(app.UIAxes_Pressure,limX,limY,'-');
            box.FaceColor = [0.3010 0.7450 0.9330];
            box.FaceAlpha = [.1];
            box.EdgeColor = 'none';

            %Blue box Temperature
            limY_t = [0 0 150 150];
            
            box_t = patch(app.UIAxes_Temp,limX,limY_t,'-');
            box_t.FaceColor = [0.3010 0.7450 0.9330];
            box_t.FaceAlpha = [.1];
            box_t.EdgeColor = 'none';

            %Blue box Level
            limY_lr = [50 50 100 100];
            
            box_lr = patch(app.UIAxes_Level,limX,limY_lr,'-');
            box_lr.FaceColor = [0.3010 0.7450 0.9330];
            box_lr.FaceAlpha = [0.1];
            box_lr.EdgeColor = 'none';
           
            %Blue box Recycle
            limY_rc = [29.18 29.18 35.66 35.66];
            
            box_rc = patch(app.UIAxes_Recycle,limX,limY_rc,'-');
            box_rc.FaceColor = [0.72,0.89,0.97];
            %box_lr.FaceAlpha = [0.3];
            box_rc.EdgeColor = 'none';

            %Blue box CWR
            limY_cwr = [92.22 92.22 111.72 111.72];
            
            box_CWR = patch(app.UIAxes_CWR_Temperature,limX,limY_cwr,'-');
            box_CWR.FaceColor =  [0.72,0.89,0.97];%'#B7E4F7';
            %box_CWR.FaceAlpha = [0.3];
            box_CWR.EdgeColor = 'none';
        end

        function initializeSepCond(app)
            %Valves button color
            app.Sep_ValvePurge_AutoButton.FontColor = app.whiteColor;
            app.Sep_ValvePurge_AutoButton.BackgroundColor = app.selectedColor; 

            app.Sep_ValveLiquidU_AutoButton.FontColor = app.whiteColor;
            app.Sep_ValveLiquidU_AutoButton.BackgroundColor = app.selectedColor; 

            app.Cond_ValveCWR_AutoButton.FontColor = app.whiteColor;
            app.Cond_ValveCWR_AutoButton.BackgroundColor = app.selectedColor; 
            
            %Setpoint
            s_press = yline(app.UIAxes_Sep_Pressure,2704.54,'LineWidth',1.5);
            s_press.Color = app.lineColor;
            
            s_temp = yline(app.UIAxes_Sep_Temp,92.02,'LineWidth',1.5);
            s_temp.Color = app.lineColor;
            
            s_level = yline(app.UIAxes_Sep_Level,50,'LineWidth',1.5);
            s_level.Color = app.lineColor;

            c_cwr_temp = yline(app.UIAxes_Cond_CWR_Temp,92.36,'LineWidth',1.5);
            c_cwr_temp.Color = app.lineColor;
            
            s_purge =  yline(app.UIAxes_Sep_Purge,0.21,'LineWidth',1.5);
            s_purge.Color = app.lineColor;

            s_liqU =  yline(app.UIAxes_Sep_LiquidU,25.26,'LineWidth',1.5);
            s_liqU.Color = app.lineColor;

            %Blue box 
            ax = app.UIAxes_Sep_Pressure;
            limY = [2434.09 2434.09 2974.99 2974.99];
            app.drawBlueBox(ax,limY,0);

            ax = app.UIAxes_Sep_Temp;
            limY = [82.82 82.82  101.22 101.22];
            app.drawBlueBox(ax,limY,0);

            ax = app.UIAxes_Sep_Level;
            limY = [30 30 100 100];
            app.drawBlueBox(ax,limY,0);

            %Blue box CWR
            ax = app.UIAxes_Cond_CWR_Temp;
            limY = [83.12 83.12 101.6 101.6];
            app.drawBlueBox(ax,limY,1);

            %Blue box purge
            ax = app.UIAxes_Sep_Purge;
            limY = [0.05 0.05 0.65 0.65];
            app.drawBlueBox(ax,limY,1) 
            
            %Blue sep underflow
            ax = app.UIAxes_Sep_LiquidU;
            limY = [22.73 22.73 27.79 27.79];
            app.drawBlueBox(ax,limY,1);
        end

        function initializeCompressor(app)
            %Valves button color
            app.Comp_FlowRate_Slider.Enable = 'off';
            app.Comp_FlowRate_AutoButton.FontColor = app.whiteColor;
            app.Comp_FlowRate_AutoButton.BackgroundColor = app.selectedColor; 

            %Setpoint
            comp_recycle_s = yline(app.UIAxes_Comp_FlowRate,32.42,'LineWidth',1.5);
            comp_recycle_s.Color = app.lineColor;

            comp_work_s = yline(app.UIAxes_Comp_Work,273.02,'LineWidth',1.5);
            comp_work_s.Color = app.lineColor;

            comp_purge_s = yline(app.UIAxes_Comp_Purge_FlowRate,0.21,'LineWidth',1.5);
            comp_purge_s.Color = app.lineColor;
           
            %Blue Box
            ax = app.UIAxes_Comp_FlowRate;
            limY = [29.18 29.18 35.66 35.66];
            app.drawBlueBox(ax,limY,0);

            ax = app.UIAxes_Comp_Work;
            limY = [245.72 245.72 300.32 300.32];
            app.drawBlueBox(ax,limY,0);

            ax = app.UIAxes_Comp_Purge_FlowRate;
            limY = [0.05 0.05 0.65 0.65];
            app.drawBlueBox(ax,limY,0);
        end

        function initializeStripper(app)
            %Valves button color
            app.Strip_ValveC_AutoButton.FontColor = app.whiteColor;
            app.Strip_ValveC_AutoButton.BackgroundColor = app.selectedColor; 
            
            app.Strip_ValveSteam_AutoButton.FontColor = app.whiteColor;
            app.Strip_ValveSteam_AutoButton.BackgroundColor = app.selectedColor;

            app.Strip_ValveProduct_AutoButton.FontColor = app.whiteColor;
            app.Strip_ValveProduct_AutoButton.BackgroundColor = app.selectedColor;

            %Setpoint
            strip_press_s = yline(app.UIAxes_Strip_Pressure,3332.05 ,'LineWidth',1.5);
            strip_press_s.Color = app.lineColor;
            
            strip_temp_s = yline(app.UIAxes_Strip_Temp,66.64,'LineWidth',1.5);
            strip_temp_s.Color = app.lineColor;
            
            strip_level_s = yline(app.UIAxes_Strip_Level,50,'LineWidth',1.5);
            strip_level_s.Color = app.lineColor;

            strip_steam_s = yline(app.UIAxes_Strip_Steam,232.16,'LineWidth',1.5);
            strip_steam_s.Color = app.lineColor;
            
            strip_product_s =  yline(app.UIAxes_Strip_Product,22.89,'LineWidth',1.5);
            strip_product_s.Color = app.lineColor;

            %Blue box 
            ax = app.UIAxes_Strip_Pressure;
            limY = [2998.85 2998.85 3665.255 3665.255 ];
            app.drawBlueBox(ax,limY,0);

            ax = app.UIAxes_Strip_Temp;
            limY = [59.98 59.98 73.3 73.3];
            app.drawBlueBox(ax,limY,0);

            ax = app.UIAxes_Strip_Level;
            limY = [30 30 100 100];
            app.drawBlueBox(ax,limY,0);

            %Blue box Steam
            %ax = app.UIAxes_Strip_Steam;
            %limY = [83.12 83.12 101.6 101.6];
            %app.drawBlueBox(ax,limY,1);

            %Blue box product
            ax = app.UIAxes_Strip_Product;
            limY = [20.6 20.6 25.18 25.18];
            app.drawBlueBox(ax,limY,1) 
        end

        function drawBlueBox(~,ax,limY,axeType)
            limX = [datenum(datetime('now')-seconds(240)) datenum(datetime('now')+minutes(240)) datenum(datetime('now')+minutes(240)) datenum(datetime('now')-seconds(240))];
            box = patch(ax,limX,limY,'-');
            
            %0 = big graph, 1 = small graph
            if(axeType == 0)
                box.FaceColor = [0.3010 0.7450 0.9330];
                box.FaceAlpha = [.1];
            else
                box.FaceColor = [0.72,0.89,0.97];
            end

            box.EdgeColor = 'none';
        end
    
        function updateTime(app)
            app.CurrentTime_Label.Text = datestr(now,'HH:MM:SS');
        end 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.startTime = datetime('now');
            disp(timeofday(datetime('now')));
      
            app.tabBarSelected = app.ReactorFeedButton;
            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
            app.ReactorFeedButton.BackgroundColor = [0.31,0.31,0.31];

            app.initializeReactor();
            app.initializeSepCond();
            app.initializeCompressor();
            app.initializeStripper(); 
            app.CallingApp = Overview_app;
            app.CallingApp.initializeOverview();

            app.time = timer('Period',0.5,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.time.TimerFcn = @(~,~) updateTime(app); 
            start(app.time);
        end

        % Value changed function: ValveA_Slider
        function ValveA_SliderValueChanged(app, event)
            value = app.ValveA_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/ValveA_Setpoint', 'Value', num2str(value));
            
            app.ValveA_Label.FontSize = 18; 
            app.ValveA_Label.FontColor = [0.01 0.40 0.55];
            pause(0.4);
            app.ValveA_Label.FontSize = 16; 
            app.ValveA_Label.FontColor = [0 0 0];
        end

        % Value changed function: ValveD_Slider
        function ValveD_SliderValueChanged(app, event)
            value = app.ValveD_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/ValveD_Setpoint', 'Value', num2str(value));
            
            app.ValveD_Label.FontSize = 18; 
            app.ValveD_Label.FontColor = [0.01 0.40 0.55];
            pause(0.4);
            app.ValveD_Label.FontSize = 16; 
            app.ValveD_Label.FontColor = [0 0 0];
        end

        % Button pushed function: ValveA_AutoButton
        function ValveA_AutoButtonPushed(app, event)
            %auto selected
            set_param('MultiLoop_mode1/TE Plant/ValveA_Switch', 'sw', '1');

            selectedButton = app.ValveA_AutoButton;
            selectedButton.FontColor = [1 1 1];
            selectedButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.ValveA_ManualButton.BackgroundColor =  [0.96 0.96 0.96];
            app.ValveA_ManualButton.FontColor = [0 0 0];
            
            app.ValveA_Slider.Visible = 'off';
            app.ValveA_SP_ButtonPushed(event);
        end

        % Button pushed function: ValveA_ManualButton
        function ValveA_ManualButtonPushed(app, event)
            value = app.ValveA_Slider.Value;

            set_param('MultiLoop_mode1/TE Plant/ValveA_Setpoint', 'Value', num2str(value));
            set_param('MultiLoop_mode1/TE Plant/ValveA_Switch', 'sw', '0');
            
            selectedButton = app.ValveA_ManualButton;
            selectedButton.FontColor = [1 1 1];
            app.ValveA_AutoButton.BackgroundColor = [0.96 0.96 0.96];
            selectedButton.BackgroundColor = [0.26 0.27 0.27];
            app.ValveA_AutoButton.FontColor = [0 0 0];

            app.ValveA_Slider.Visible = 'on';
            app.FeedA_SP_XButtonPushed(app);
        end

        % Button pushed function: ValveD_AutoButton
        function ValveD_AutoButtonPushed(app, event)
            %auto selected
            set_param('MultiLoop_mode1/TE Plant/ValveD_Switch', 'sw', '1');

            selectedButton = app.ValveD_AutoButton;
            selectedButton.FontColor = [1 1 1];
            selectedButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.ValveD_ManualButton.BackgroundColor =  [0.96 0.96 0.96];
            app.ValveD_ManualButton.FontColor = [0 0 0];

            app.ValveD_Slider.Visible = 'off';
            app.ValveD_SP_ButtonPushed(event);
        end

        % Button pushed function: ValveD_ManualButton
        function ValveD_ManualButtonPushed(app, event)
            value = app.ValveD_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/ValveD_Setpoint', 'Value', num2str(value));
            set_param('MultiLoop_mode1/TE Plant/ValveD_Switch', 'sw', '0');
            
            selectedButton = app.ValveD_ManualButton;
            selectedButton.FontColor = [1 1 1];
            app.ValveD_AutoButton.BackgroundColor = [0.96 0.96 0.96];
            selectedButton.BackgroundColor = [0.26 0.27 0.27];
            app.ValveD_AutoButton.FontColor = [0 0 0];
           
            app.ValveD_Slider.Visible = 'on';
            app.FeedD_SP_XButtonPushed(app);
        end

        % Button pushed function: ReactorFeedButton
        function ReactorFeedButtonPushed(app, event)
             app.tabBarSelected.BackgroundColor = [0.2,0.2,0.2];
             app.ReactorFeedButton.BackgroundColor = [0.31,0.31,0.31];
             app.tabBarSelected = app.TabGroup.Children(1);
             app.TabGroup.SelectedTab = app.tabBarSelected;
             app.tabBarSelected = app.ReactorFeedButton;
        end

        % Button pushed function: CondenserSeparatorPurgeButton
        function CondenserSeparatorPurgeButtonPushed(app, event)
            app.tabBarSelected.BackgroundColor = [0.2,0.2,0.2];
            app.CondenserSeparatorPurgeButton.BackgroundColor = [0.31,0.31,0.31];
            app.tabBarSelected = app.TabGroup.Children(2);
            app.TabGroup.SelectedTab = app.tabBarSelected;
            app.tabBarSelected = app.CondenserSeparatorPurgeButton;
        end

        % Button pushed function: CompressorButton
        function CompressorButtonPushed(app, event)
            app.tabBarSelected.BackgroundColor = [0.2,0.2,0.2];
            app.CompressorButton.BackgroundColor = [0.31,0.31,0.31];
            app.tabBarSelected = app.TabGroup.Children(3);
            app.TabGroup.SelectedTab = app.tabBarSelected;
            app.tabBarSelected = app.CompressorButton;
        end

        % Button pushed function: StripperProductButton
        function StripperProductButtonPushed(app, event)
            app.tabBarSelected.BackgroundColor = [0.2,0.2,0.2];
            app.StripperProductButton.BackgroundColor = [0.31,0.31,0.31];
            app.tabBarSelected = app.TabGroup.Children(4);
            app.TabGroup.SelectedTab = app.tabBarSelected;
            app.tabBarSelected = app.StripperProductButton;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            %delete(app.CallingApp);
            set_param('MultiLoop_mode1','SimulationCommand','stop');
        end

        % Value changed function: ValveE_Slider
        function ValveE_SliderValueChanged(app, event)
            value = app.ValveE_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/ValveE_Setpoint', 'Value', num2str(value));
            
            app.ValveE_Label.FontSize = 18; 
            app.ValveE_Label.FontColor = [0.01 0.40 0.55];
            pause(0.4);
            app.ValveE_Label.FontSize = 16; 
            app.ValveE_Label.FontColor = [0 0 0];
        end

        % Value changed function: ValveCWR_Slider
        function ValveCWR_SliderValueChanged(app, event)
            value = app.ValveCWR_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/ValveCWR_Setpoint', 'Value', num2str(value));
            
            app.ValveCWR_Label.FontSize = 18; 
            app.ValveCWR_Label.FontColor = [0.01 0.40 0.55];
            pause(0.4);
            app.ValveCWR_Label.FontSize = 16; 
            app.ValveCWR_Label.FontColor = [0 0 0];
        end

        % Value changed function: Sep_ValvePurge_Slider
        function Sep_ValvePurge_SliderValueChanged(app, event)
            value = app.Sep_ValvePurge_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/Sep_ValvePurge_Setpoint', 'Value', num2str(value));
            
            app.Sep_Valve_Purge_Label.FontSize = 18; 
            app.Sep_Valve_Purge_Label.FontColor = [0.01 0.40 0.55];
            pause(0.4);
            app.Sep_Valve_Purge_Label.FontSize = 16; 
            app.Sep_Valve_Purge_Label.FontColor = [0 0 0];
        end

        % Value changed function: Sep_ValveLiquidU_Slider
        function Sep_ValveLiquidU_SliderValueChanged(app, event)
            value = app.Sep_ValveLiquidU_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/Sep_ValveLiquidU_Setpoint', 'Value', num2str(value));
            
            app.Sep_Valve_LiquidU_Label.FontSize = 18; 
            app.Sep_Valve_LiquidU_Label.FontColor = [0.01 0.40 0.55];
            pause(0.4);
            app.Sep_Valve_LiquidU_Label.FontSize = 16; 
            app.Sep_Valve_LiquidU_Label.FontColor = [0 0 0];
        end

        % Value changed function: Cond_ValveCWR_Slider
        function Cond_ValveCWR_SliderValueChanged(app, event)
            value = app.Cond_ValveCWR_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/Cond_ValveCWR_Setpoint', 'Value', num2str(value));
            
            app.Cond_ValveCWR_Label.FontSize = 18; 
            app.Cond_ValveCWR_Label.FontColor = [0.01 0.40 0.55];
            pause(0.4);
            app.Cond_ValveCWR_Label.FontSize = 16; 
            app.Cond_ValveCWR_Label.FontColor = [0 0 0];
        end

        % Value changed function: Comp_FlowRate_Slider
        function Comp_FlowRate_SliderValueChanged(app, event)
            value = app.Comp_FlowRate_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/Comp_ValveRecycle_Setpoint', 'Value', num2str(value));
            
            app.Comp_Valve_Label.FontSize = 18; 
            app.Comp_Valve_Label.FontColor = [0.01 0.40 0.55];
            pause(0.4);
            app.Comp_Valve_Label.FontSize = 16; 
            app.Comp_Valve_Label.FontColor = [0 0 0];
        end

        % Value changed function: Strip_ValveC_Slider
        function Strip_ValveC_SliderValueChanged(app, event)
            value = app.Strip_ValveC_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/ValveC_Setpoint', 'Value', num2str(value));
            
            app.Strip_ValveC_Label.FontSize = 18; 
            app.Strip_ValveC_Label.FontColor = [0.01 0.40 0.55];
            pause(0.4);
            app.Strip_ValveC_Label.FontSize = 16; 
            app.Strip_ValveC_Label.FontColor = [0 0 0];
        end

        % Value changed function: Strip_ValveSteam_Slider
        function Strip_ValveSteam_SliderValueChanged(app, event)
            value = app.Strip_ValveSteam_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/Strip_ValveSteam_Setpoint', 'Value', num2str(value));
            
            app.Strip_ValveSteam_Label.FontSize = 18; 
            app.Strip_ValveSteam_Label.FontColor = [0.01 0.40 0.55];
            pause(0.4);
            app.Strip_ValveSteam_Label.FontSize = 16; 
            app.Strip_ValveSteam_Label.FontColor = [0 0 0];
        end

        % Value changed function: Strip_ValveProduct_Slider
        function Strip_ValveProduct_SliderValueChanged(app, event)
            value = app.Strip_ValveProduct_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/Strip_ValveProduct_Setpoint', 'Value', num2str(value));
            
            app.Strip_ValveProduct_Label.FontSize = 18; 
            app.Strip_ValveProduct_Label.FontColor = [0.01 0.40 0.55];
            pause(0.4);
            app.Strip_ValveProduct_Label.FontSize = 16; 
            app.Strip_ValveProduct_Label.FontColor = [0 0 0];
        end

        % Button pushed function: ValveE_AutoButton
        function ValveE_AutoButtonPushed(app, event)
            %auto selected
            set_param('MultiLoop_mode1/TE Plant/ValveE_Switch', 'sw', '1');

            selectedButton = app.ValveE_AutoButton;
            selectedButton.FontColor = [1 1 1];
            selectedButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.ValveE_ManualButton.BackgroundColor =  [0.96 0.96 0.96];
            app.ValveE_ManualButton.FontColor = [0 0 0];

            app.ValveE_Slider.Visible = 'off';
            app.ValveE_SP_ButtonPushed(event);
        end

        % Button pushed function: ValveE_ManualButton
        function ValveE_ManualButtonPushed(app, event)
            value = app.ValveE_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/ValveE_Setpoint', 'Value', num2str(value));
            set_param('MultiLoop_mode1/TE Plant/ValveE_Switch', 'sw', '0');
            
            selectedButton = app.ValveE_ManualButton;
            selectedButton.FontColor = [1 1 1];
            app.ValveE_AutoButton.BackgroundColor = [0.96 0.96 0.96];
            selectedButton.BackgroundColor = [0.26 0.27 0.27];
            app.ValveE_AutoButton.FontColor = [0 0 0];

            app.ValveE_Slider.Visible = 'on';
            app.FeedE_SP_XButtonPushed(app);
        end

        % Button pushed function: ValveCWR_AutoButton
        function ValveCWR_AutoButtonPushed(app, event)
            %auto selected
            set_param('MultiLoop_mode1/TE Plant/ValveCWR_Switch', 'sw', '1');

            selectedButton = app.ValveCWR_AutoButton;
            selectedButton.FontColor = [1 1 1];
            selectedButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.ValveCWR_ManualButton.BackgroundColor =  [0.96 0.96 0.96];
            app.ValveCWR_ManualButton.FontColor = [0 0 0];

            app.ValveCWR_Slider.Visible = 'off';
            app.ValveCWR_SP_ButtonPushed(event);
        end

        % Button pushed function: ValveCWR_ManualButton
        function ValveCWR_ManualButtonPushed(app, event)
            value = app.ValveCWR_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/ValveCWR_Setpoint', 'Value', num2str(value));
            set_param('MultiLoop_mode1/TE Plant/ValveCWR_Switch', 'sw', '0');
            
            selectedButton = app.ValveCWR_ManualButton;
            selectedButton.FontColor = [1 1 1];
            app.ValveCWR_AutoButton.BackgroundColor = [0.96 0.96 0.96];
            selectedButton.BackgroundColor = [0.26 0.27 0.27];
            app.ValveCWR_AutoButton.FontColor = [0 0 0];
            
            app.ValveCWR_Slider.Visible = 'on';
            app.ReactorTemp_SP_XButtonPushed(app);
        end

        % Button pushed function: Sep_ValvePurge_AutoButton
        function Sep_ValvePurge_AutoButtonButtonPushed(app, event)
            %auto selected
            set_param('MultiLoop_mode1/TE Plant/Sep_ValvePurge_Switch', 'sw', '1');

            selectedButton = app.Sep_ValvePurge_AutoButton;
            selectedButton.FontColor = [1 1 1];
            selectedButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.Sep_ValvePurge_ManualButton.BackgroundColor =  [0.96 0.96 0.96];
            app.Sep_ValvePurge_ManualButton.FontColor = [0 0 0];
            
            app.Sep_ValvePurge_Slider.Visible = 'off';
            app.ValvePurge_SP_ButtonPushed(event);
        end

        % Button pushed function: Sep_ValvePurge_ManualButton
        function Sep_ValvePurge_ManualButtonButtonPushed(app, event)
            value = app.Sep_ValvePurge_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/Sep_ValvePurge_Setpoint', 'Value', num2str(value));
            set_param('MultiLoop_mode1/TE Plant/Sep_ValvePurge_Switch', 'sw', '0');
            
            selectedButton = app.Sep_ValvePurge_ManualButton;
            selectedButton.FontColor = [1 1 1];
            app.Sep_ValvePurge_AutoButton.BackgroundColor = [0.96 0.96 0.96];
            selectedButton.BackgroundColor = [0.26 0.27 0.27];
            app.Sep_ValvePurge_AutoButton.FontColor = [0 0 0];
            
            app.Sep_ValvePurge_Slider.Visible = 'on';
            app.PurgeFlow_SP_XButtonPushed(app);
        end

        % Button pushed function: Sep_ValveLiquidU_AutoButton
        function Sep_ValveLiquidU_AutoButtonPushed(app, event)
            %auto selected
            set_param('MultiLoop_mode1/TE Plant/Sep_ValveLiquidU_Switch', 'sw', '1');

            selectedButton = app.Sep_ValveLiquidU_AutoButton;
            selectedButton.FontColor = [1 1 1];
            selectedButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.Sep_ValveLiquidU_ManualButton.BackgroundColor =  [0.96 0.96 0.96];
            app.Sep_ValveLiquidU_ManualButton.FontColor = [0 0 0];

            app.Sep_ValveLiquidU_Slider.Visible = 'off';
            app.ValveSep_SP_ButtonPushed(event);
        end

        % Button pushed function: Sep_ValveLiquidU_ManualButton
        function Sep_ValveLiquidU_ManualButtonPushed(app, event)
            value = app.Sep_ValveLiquidU_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/Sep_ValveLiquidU_Setpoint', 'Value', num2str(value));
            set_param('MultiLoop_mode1/TE Plant/Sep_ValveLiquidU_Switch', 'sw', '0');
            
            selectedButton = app.Sep_ValveLiquidU_ManualButton;
            selectedButton.FontColor = [1 1 1];
            app.Sep_ValveLiquidU_AutoButton.BackgroundColor = [0.96 0.96 0.96];
            selectedButton.BackgroundColor = [0.26 0.27 0.27];
            app.Sep_ValveLiquidU_AutoButton.FontColor = [0 0 0];

            app.Sep_ValveLiquidU_Slider.Visible = 'on';
            app.SepLevel_SP_XButtonPushed(app);
        end

        % Button pushed function: Cond_ValveCWR_AutoButton
        function Cond_ValveCWR_AutoButtonPushed(app, event)
            %auto selected
            set_param('MultiLoop_mode1/TE Plant/Cond_ValveCWR_Switch', 'sw', '1');

            selectedButton = app.Cond_ValveCWR_AutoButton;
            selectedButton.FontColor = [1 1 1];
            selectedButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.Cond_ValveCWR_ManualButton.BackgroundColor =  [0.96 0.96 0.96];
            app.Cond_ValveCWR_ManualButton.FontColor = [0 0 0];

            app.Cond_ValveCWR_Slider.Visible = 'off';
            app.ValveCond_SP_ButtonPushed(event);
        end

        % Button pushed function: Cond_ValveCWR_ManualButton
        function Cond_ValveCWR_ManualButtonPushed(app, event)
            value = app.Cond_ValveCWR_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/Cond_ValveCWR_Setpoint', 'Value', num2str(value));
            set_param('MultiLoop_mode1/TE Plant/Cond_ValveCWR_Switch', 'sw', '0');
            
            selectedButton = app.Cond_ValveCWR_ManualButton;
            selectedButton.FontColor = [1 1 1];
            app.Cond_ValveCWR_AutoButton.BackgroundColor = [0.96 0.96 0.96];
            selectedButton.BackgroundColor = [0.26 0.27 0.27];
            app.Cond_ValveCWR_AutoButton.FontColor = [0 0 0];
            
            app.Cond_ValveCWR_Slider.Visible = 'on';
            app.ProductFlow_SP_XButtonPushed(app);
        end

        % Button pushed function: Comp_FlowRate_AutoButton
        function Comp_FlowRate_AutoButtonPushed(app, event)
            %auto selected
            set_param('MultiLoop_mode1/TE Plant/Comp_ValveRecycle_Switch', 'sw', '1');

            selectedButton = app.Comp_FlowRate_AutoButton;
            selectedButton.FontColor = [1 1 1];
            selectedButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.Comp_FlowRate_ManualButton.BackgroundColor =  [0.96 0.96 0.96];
            app.Comp_FlowRate_ManualButton.FontColor = [0 0 0];

            app.Comp_FlowRate_Slider.Enable = 'off';
        end

        % Button pushed function: Comp_FlowRate_ManualButton
        function Comp_FlowRate_ManualButtonPushed(app, event)
            value = app.Comp_FlowRate_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/Comp_ValveRecycle_Setpoint', 'Value', num2str(value));
            set_param('MultiLoop_mode1/TE Plant/Comp_ValveRecycle_Switch', 'sw', '0');
            
            selectedButton = app.Comp_FlowRate_ManualButton;
            selectedButton.FontColor = [1 1 1];
            app.Comp_FlowRate_AutoButton.BackgroundColor = [0.96 0.96 0.96];
            selectedButton.BackgroundColor = [0.26 0.27 0.27];
            app.Comp_FlowRate_AutoButton.FontColor = [0 0 0];
            app.Comp_FlowRate_Slider.Enable = 'on';
        end

        % Button pushed function: Strip_ValveC_AutoButton
        function Strip_ValveC_AutoButtonPushed(app, event)
            %auto selected
            set_param('MultiLoop_mode1/TE Plant/ValveC_Switch', 'sw', '1');

            selectedButton = app.Strip_ValveC_AutoButton;
            selectedButton.FontColor = [1 1 1];
            selectedButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.Strip_ValveC_ManualButton.BackgroundColor =  [0.96 0.96 0.96];
            app.Strip_ValveC_ManualButton.FontColor = [0 0 0];

            app.Strip_ValveC_Slider.Visible = 'off';
            app.ValveC_SP_ButtonPushed(event);
        end

        % Button pushed function: Strip_ValveC_ManualButton
        function Strip_ValveC_ManualButtonPushed(app, event)
            value = app.Strip_ValveC_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/ValveC_Setpoint', 'Value', num2str(value));
            set_param('MultiLoop_mode1/TE Plant/ValveC_Switch', 'sw', '0');
            
            selectedButton = app.Strip_ValveC_ManualButton;
            selectedButton.FontColor = [1 1 1];
            app.Strip_ValveC_AutoButton.BackgroundColor = [0.96 0.96 0.96];
            selectedButton.BackgroundColor = [0.26 0.27 0.27];
            app.Strip_ValveC_AutoButton.FontColor = [0 0 0];
            
            app.Strip_ValveC_Slider.Visible = 'on';
            app.FeedC_SP_XButtonPushed(app);
        end

        % Button pushed function: Strip_ValveSteam_AutoButton
        function Strip_ValveSteam_AutoButtonPushed(app, event)
            %auto selected
            set_param('MultiLoop_mode1/TE Plant/Strip_ValveSteam_Switch', 'sw', '1');

            selectedButton = app.Strip_ValveSteam_AutoButton;
            selectedButton.FontColor = [1 1 1];
            selectedButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.Strip_ValveSteam_ManualButton.BackgroundColor =  [0.96 0.96 0.96];
            app.Strip_ValveSteam_ManualButton.FontColor = [0 0 0];

            app.Strip_ValveSteam_Slider.Enable = 'off';
        end

        % Button pushed function: Strip_ValveSteam_ManualButton
        function Strip_ValveSteam_ManualButtonPushed(app, event)
            value = app.Strip_ValveSteam_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/Strip_ValveSteam_Setpoint', 'Value', num2str(value));
            set_param('MultiLoop_mode1/TE Plant/Strip_ValveSteam_Switch', 'sw', '0');
            
            selectedButton = app.Strip_ValveSteam_ManualButton;
            selectedButton.FontColor = [1 1 1];
            app.Strip_ValveSteam_AutoButton.BackgroundColor = [0.96 0.96 0.96];
            selectedButton.BackgroundColor = [0.26 0.27 0.27];
            app.Strip_ValveSteam_AutoButton.FontColor = [0 0 0];
            app.Strip_ValveSteam_Slider.Enable = 'on';
        end

        % Button pushed function: Strip_ValveProduct_AutoButton
        function Strip_ValveProduct_AutoButtonPushed(app, event)
            %auto selected
            set_param('MultiLoop_mode1/TE Plant/Strip_ValveProduct_Switch', 'sw', '1');

            selectedButton = app.Strip_ValveProduct_AutoButton;
            selectedButton.FontColor = [1 1 1];
            selectedButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.Strip_ValveProduct_ManualButton.BackgroundColor =  [0.96 0.96 0.96];
            app.Strip_ValveProduct_ManualButton.FontColor = [0 0 0];

            app.Strip_ValveProduct_Slider.Visible = 'off';
            app.ValveStrip_SP_ButtonPushed(event);
        end

        % Button pushed function: Strip_ValveProduct_ManualButton
        function Strip_ValveProduct_ManualButtonPushed(app, event)
            value = app.Strip_ValveProduct_Slider.Value;
            set_param('MultiLoop_mode1/TE Plant/Strip_ValveProduct_Setpoint', 'Value', num2str(value));
            set_param('MultiLoop_mode1/TE Plant/Strip_ValveProduct_Switch', 'sw', '0');
            
            selectedButton = app.Strip_ValveProduct_ManualButton;
            selectedButton.FontColor = [1 1 1];
            app.Strip_ValveProduct_AutoButton.BackgroundColor = [0.96 0.96 0.96];
            selectedButton.BackgroundColor = [0.26 0.27 0.27];
            app.Strip_ValveProduct_AutoButton.FontColor = [0 0 0];
            
            app.Strip_ValveProduct_Slider.Visible = 'on';
            app.StripLevel_SP_XButtonPushed(app);
        end

        % Button pushed function: FeedA_Setpoint_Reset
        function FeedA_Setpoint_ResetButtonPushed(app, event)
            set_param('MultiLoop_mode1/TE Plant/A feed rate/A_Feed_Switch', 'sw', '1');
        end

        % Callback function
        function ValveA_SP_ButtonPushed(app, event)
            app.ValveA_Slider.Visible = 'off';
            app.ValveA_Control_Label.Enable = 'off';
            app.ValveA_Control_Label.Visible = 'off';
            
            app.FeedA_SetpointC_Spinner.Visible = 'on';
            app.FeedA_SetpointC_Spinner.Enable = 'on';
            app.FeedA_Setpoint_Reset.Visible = 'on';
            app.FeedA_Setpoint_Reset.Enable = 'on';
            app.FeedA_Setpoint_Label.Visible = 'on';
            app.FeedA_Setpoint_Label.Enable = 'on';
        end

        % Callback function
        function FeedA_SP_XButtonPushed(app, event)
            app.ValveA_Slider.Visible = 'on';
            app.ValveA_Control_Label.Enable = 'on';
            app.ValveA_Control_Label.Visible = 'on';
            
            app.FeedA_SetpointC_Spinner.Visible = 'off';
            app.FeedA_SetpointC_Spinner.Enable = 'off';
            app.FeedA_Setpoint_Reset.Visible = 'off';
            app.FeedA_Setpoint_Reset.Enable = 'off';
            app.FeedA_Setpoint_Label.Visible = 'off';
            app.FeedA_Setpoint_Label.Enable = 'off';
        end

        % Value changed function: FeedA_SetpointC_Spinner
        function FeedA_SetpointC_SpinnerValueChanged(app, event)
            value = app.FeedA_SetpointC_Spinner.Value;

            set_param('MultiLoop_mode1/TE Plant/A feed rate/A_Feed_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/A feed rate/A_Feed_Setpoint', 'Value', num2str(value));
        end

        % Button pushed function: FeedD_Setpoint_Reset
        function FeedD_Setpoint_ResetButtonPushed(app, event)
            set_param('MultiLoop_mode1/TE Plant/D feed rate/D_Feed_Switch', 'sw', '1');
        end

        % Callback function
        function ValveD_SP_ButtonPushed(app, event)
            app.ValveD_Slider.Visible = 'off';
            app.ValveD_Control_Label.Enable = 'off';
            app.ValveD_Control_Label.Visible = 'off';
            
            app.FeedD_SetpointC_Spinner.Visible = 'on';
            app.FeedD_SetpointC_Spinner.Enable = 'on';
            app.FeedD_Setpoint_Reset.Visible = 'on';
            app.FeedD_Setpoint_Reset.Enable = 'on';
            app.FeedD_Setpoint_Label.Visible = 'on';
            app.FeedD_Setpoint_Label.Enable = 'on';
        end

        % Callback function
        function FeedD_SP_XButtonPushed(app, event)
            app.ValveD_Slider.Visible = 'on';
            app.ValveD_Control_Label.Enable = 'on';
            app.ValveD_Control_Label.Visible = 'on';
            
            app.FeedD_SetpointC_Spinner.Visible = 'off';
            app.FeedD_SetpointC_Spinner.Enable = 'off';
            app.FeedD_Setpoint_Reset.Visible = 'off';
            app.FeedD_Setpoint_Reset.Enable = 'off';
            app.FeedD_Setpoint_Label.Visible = 'off';
            app.FeedD_Setpoint_Label.Enable = 'off';
        end

        % Value changed function: FeedD_SetpointC_Spinner
        function FeedD_SetpointC_SpinnerValueChanged(app, event)
            value = app.FeedD_SetpointC_Spinner.Value;

            set_param('MultiLoop_mode1/TE Plant/D feed rate/D_Feed_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/D feed rate/D_Feed_Setpoint', 'Value', num2str(value));
        end

        % Callback function
        function ValveE_SP_ButtonPushed(app, event)
            app.ValveE_Slider.Visible = 'off';
            app.ValveE_Control_Label.Enable = 'off';
            app.ValveE_Control_Label.Visible = 'off';
            
            app.FeedE_SetpointC_Spinner.Visible = 'on';
            app.FeedE_SetpointC_Spinner.Enable = 'on';
            app.FeedE_Setpoint_Reset.Visible = 'on';
            app.FeedE_Setpoint_Reset.Enable = 'on';
            app.FeedE_Setpoint_Label.Visible = 'on';
            app.FeedE_Setpoint_Label.Enable = 'on';
        end

        % Callback function
        function ValveCWR_SP_ButtonPushed(app, event)
            app.ValveCWR_Slider.Visible = 'off';
            app.ValveCWR_Control_Label.Enable = 'off';
            app.ValveCWR_Control_Label.Visible = 'off';
            
            app.ReactorTemp_SetpointC_Spinner.Visible = 'on';
            app.ReactorTemp_SetpointC_Spinner.Enable = 'on';
            app.ReactorTemp_Setpoint_Reset.Visible = 'on';
            app.ReactorTemp_Setpoint_Reset.Enable = 'on';
            app.ReactorTemp_Setpoint_Label.Visible = 'on';
            app.ReactorTemp_Setpoint_Label.Enable = 'on';
        end

        % Value changed function: FeedE_SetpointC_Spinner
        function FeedE_SetpointC_SpinnerValueChanged(app, event)
            value = app.FeedE_SetpointC_Spinner.Value;
            
            set_param('MultiLoop_mode1/TE Plant/E feed rate/E_Feed_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/E feed rate/E_Feed_Setpoint', 'Value', num2str(value));
        end

        % Value changed function: ReactorTemp_SetpointC_Spinner
        function ReactorTemp_SetpointC_SpinnerValueChanged(app, event)
            value = app.ReactorTemp_SetpointC_Spinner.Value;
            
            set_param('MultiLoop_mode1/TE Plant/Reactor temperature/ReactorTemp_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/Reactor temperature/ReactorTemp_Setpoint', 'Value', num2str(value));
        end

        % Button pushed function: FeedE_Setpoint_Reset
        function FeedE_Setpoint_ResetButtonPushed(app, event)
            set_param('MultiLoop_mode1/TE Plant/E feed rate/E_Feed_Switch', 'sw', '1');
        end

        % Callback function
        function FeedE_SP_XButtonPushed(app, event)
            app.ValveE_Slider.Visible = 'on';
            app.ValveE_Control_Label.Enable = 'on';
            app.ValveE_Control_Label.Visible = 'on';
            
            app.FeedE_SetpointC_Spinner.Visible = 'off';
            app.FeedE_SetpointC_Spinner.Enable = 'off';
            app.FeedE_Setpoint_Reset.Visible = 'off';
            app.FeedE_Setpoint_Reset.Enable = 'off';
            app.FeedE_Setpoint_Label.Visible = 'off';
            app.FeedE_Setpoint_Label.Enable = 'off';
        end

        % Button pushed function: ReactorTemp_Setpoint_Reset
        function ReactorTemp_Setpoint_ResetButtonPushed(app, event)
            set_param('MultiLoop_mode1/TE Plant/Reactor temperature/ReactorTemp_Switch', 'sw', '1');
        end

        % Callback function
        function ReactorTemp_SP_XButtonPushed(app, event)
            app.ValveCWR_Slider.Visible = 'on';
            app.ValveCWR_Control_Label.Enable = 'on';
            app.ValveCWR_Control_Label.Visible = 'on';
            
            app.ReactorTemp_SetpointC_Spinner.Visible = 'off';
            app.ReactorTemp_SetpointC_Spinner.Enable = 'off';
            app.ReactorTemp_Setpoint_Reset.Visible = 'off';
            app.ReactorTemp_Setpoint_Reset.Enable = 'off';
            app.ReactorTemp_Setpoint_Label.Visible = 'off';
            app.ReactorTemp_Setpoint_Label.Enable = 'off';
        end

        % Callback function
        function ValvePurge_SP_ButtonPushed(app, event)
            app.Sep_ValvePurge_Slider.Visible = 'off';
            app.ValvePurge_Control_Label.Enable = 'off';
            app.ValvePurge_Control_Label.Visible = 'off';
            
            app.PurgeFlow_SetpointC_Spinner.Visible = 'on';
            app.PurgeFlow_SetpointC_Spinner.Enable = 'on';
            app.PurgeFlow_Setpoint_Reset.Visible = 'on';
            app.PurgeFlow_Setpoint_Reset.Enable = 'on';
            app.PurgeFlow_Setpoint_Label.Visible = 'on';
            app.PurgeFlow_Setpoint_Label.Enable = 'on';
        end

        % Callback function
        function ValveSep_SP_ButtonPushed(app, event)
            app.Sep_ValveLiquidU_Slider.Visible = 'off';
            app.ValveSep_Control_Label.Enable = 'off';
            app.ValveSep_Control_Label.Visible = 'off';
            
            app.SepLevel_SetpointC_Spinner.Visible = 'on';
            app.SepLevel_SetpointC_Spinner.Enable = 'on';
            app.SepLevel_Setpoint_Reset.Visible = 'on';
            app.SepLevel_Setpoint_Reset.Enable = 'on';
            app.SepLevel_Setpoint_Label.Visible = 'on';
            app.SepLevel_Setpoint_Label.Enable = 'on';
        end

        % Callback function
        function ValveCond_SP_ButtonPushed(app, event)
            app.Cond_ValveCWR_Slider.Visible = 'off';
            app.ValveCond_Control_Label.Enable = 'off';
            app.ValveCond_Control_Label.Visible = 'off';
            
            app.ProductFlow_SetpointC_Spinner.Visible = 'on';
            app.ProductFlow_SetpointC_Spinner.Enable = 'on';
            app.ProductFlow_Setpoint_Reset.Visible = 'on';
            app.ProductFlow_Setpoint_Reset.Enable = 'on';
            app.ProductFlow_Setpoint_Label.Visible = 'on';
            app.ProductFlow_Setpoint_Label.Enable = 'on';
        end

        % Value changed function: PurgeFlow_SetpointC_Spinner
        function PurgeFlow_SetpointC_SpinnerValueChanged(app, event)
            value = app.PurgeFlow_SetpointC_Spinner.Value;

            set_param('MultiLoop_mode1/TE Plant/Purge rate/PurgeFlow_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/Purge rate/PurgeFlow_Setpoint', 'Value', num2str(value));
        end

        % Value changed function: SepLevel_SetpointC_Spinner
        function SepLevel_SetpointC_SpinnerValueChanged(app, event)
            value = app.SepLevel_SetpointC_Spinner.Value;

            set_param('MultiLoop_mode1/TE Plant/Separator level/SepLevel_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/Separator level/SepLevel_Setpoint', 'Value', num2str(value));
        end

        % Value changed function: ProductFlow_SetpointC_Spinner
        function ProductFlow_SetpointC_SpinnerValueChanged(app, event)
            value = app.ProductFlow_SetpointC_Spinner.Value;

            set_param('MultiLoop_mode1/TE Plant/Stripper flow rate/ProductFlow_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/Stripper flow rate/ProductFlow_Setpoint', 'Value', num2str(value));
        end

        % Button pushed function: PurgeFlow_Setpoint_Reset
        function PurgeFlow_Setpoint_ResetButtonPushed(app, event)
            set_param('MultiLoop_mode1/TE Plant/Purge rate/PurgeFlow_Switch', 'sw', '1');
        end

        % Button pushed function: SepLevel_Setpoint_Reset
        function SepLevel_Setpoint_ResetButtonPushed(app, event)
            set_param('MultiLoop_mode1/TE Plant/Separator level/SepLevel_Switch', 'sw', '1');
        end

        % Button pushed function: ProductFlow_Setpoint_Reset
        function ProductFlow_Setpoint_ResetButtonPushed(app, event)
            set_param('MultiLoop_mode1/TE Plant/Stripper flow rate/ProductFlow_Switch', 'sw', '1');
        end

        % Callback function
        function PurgeFlow_SP_XButtonPushed(app, event)
            app.Sep_ValvePurge_Slider.Visible = 'on';
            app.ValvePurge_Control_Label.Enable = 'on';
            app.ValvePurge_Control_Label.Visible = 'on';
            
            app.PurgeFlow_SetpointC_Spinner.Visible = 'off';
            app.PurgeFlow_SetpointC_Spinner.Enable = 'off';
            app.PurgeFlow_Setpoint_Reset.Visible = 'off';
            app.PurgeFlow_Setpoint_Reset.Enable = 'off';
            app.PurgeFlow_Setpoint_Label.Visible = 'off';
            app.PurgeFlow_Setpoint_Label.Enable = 'off';
        end

        % Callback function
        function SepLevel_SP_XButtonPushed(app, event)
            app.Sep_ValveLiquidU_Slider.Visible = 'on';
            app.ValveSep_Control_Label.Enable = 'on';
            app.ValveSep_Control_Label.Visible = 'on';
            
            app.SepLevel_SetpointC_Spinner.Visible = 'off';
            app.SepLevel_SetpointC_Spinner.Enable = 'off';
            app.SepLevel_Setpoint_Reset.Visible = 'off';
            app.SepLevel_Setpoint_Reset.Enable = 'off';
            app.SepLevel_Setpoint_Label.Visible = 'off';
            app.SepLevel_Setpoint_Label.Enable = 'off';
        end

        % Callback function
        function ProductFlow_SP_XButtonPushed(app, event)
            app.Cond_ValveCWR_Slider.Visible = 'on';
            app.ValveCond_Control_Label.Enable = 'on';
            app.ValveCond_Control_Label.Visible = 'on';
            
            app.ProductFlow_SetpointC_Spinner.Visible = 'off';
            app.ProductFlow_SetpointC_Spinner.Enable = 'off';
            app.ProductFlow_Setpoint_Reset.Visible = 'off';
            app.ProductFlow_Setpoint_Reset.Enable = 'off';
            app.ProductFlow_Setpoint_Label.Visible = 'off';
            app.ProductFlow_Setpoint_Label.Enable = 'off';
        end

        % Callback function
        function ValveC_SP_ButtonPushed(app, event)
            app.Strip_ValveC_Slider.Visible = 'off';
            app.ValveC_Control_Label.Enable = 'off';
            app.ValveC_Control_Label.Visible = 'off';
            
            app.FeedC_SetpointC_Spinner.Visible = 'on';
            app.FeedC_SetpointC_Spinner.Enable = 'on';
            app.FeedC_Setpoint_Reset.Visible = 'on';
            app.FeedC_Setpoint_Reset.Enable = 'on';
            app.FeedC_Setpoint_Label.Visible = 'on';
            app.FeedC_Setpoint_Label.Enable = 'on';
        end

        % Callback function
        function ValveStrip_SP_ButtonPushed(app, event)
            app.Strip_ValveProduct_Slider.Visible = 'off';
            app.ValveStrip_Control_Label.Enable = 'off';
            app.ValveStrip_Control_Label.Visible = 'off';
            
            app.StripLevel_SetpointC_Spinner.Visible = 'on';
            app.StripLevel_SetpointC_Spinner.Enable = 'on';
            app.StripLevel_Setpoint_Reset.Visible = 'on';
            app.StripLevel_Setpoint_Reset.Enable = 'on';
            app.StripLevel_Setpoint_Label.Visible = 'on';
            app.StripLevel_Setpoint_Label.Enable = 'on';
        end

        % Value changed function: FeedC_SetpointC_Spinner
        function FeedC_SetpointC_SpinnerValueChanged(app, event)
            value = app.FeedC_SetpointC_Spinner.Value;

            set_param('MultiLoop_mode1/TE Plant/C feed rate/C_Feed_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/C feed rate/C_Feed_Setpoint', 'Value', num2str(value));
        end

        % Value changed function: StripLevel_SetpointC_Spinner
        function StripLevel_SetpointC_SpinnerValueChanged(app, event)
            value = app.StripLevel_SetpointC_Spinner.Value;
                       
            set_param('MultiLoop_mode1/TE Plant/Stripper level/StripLevel_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/Stripper level/StripLevel_Setpoint', 'Value', num2str(value));
        end

        % Button pushed function: FeedC_Setpoint_Reset
        function FeedC_Setpoint_ResetButtonPushed(app, event)
            set_param('MultiLoop_mode1/TE Plant/C feed rate/C_Feed_Switch', 'sw', '1');
        end

        % Button pushed function: StripLevel_Setpoint_Reset
        function StripLevel_Setpoint_ResetButtonPushed(app, event)
            set_param('MultiLoop_mode1/TE Plant/Stripper level/StripLevel_Switch', 'sw', '1');
        end

        % Callback function
        function FeedC_SP_XButtonPushed(app, event)
            app.Strip_ValveC_Slider.Visible = 'on';
            app.ValveC_Control_Label.Enable = 'on';
            app.ValveC_Control_Label.Visible = 'on';
            
            app.FeedC_SetpointC_Spinner.Visible = 'off';
            app.FeedC_SetpointC_Spinner.Enable = 'off';
            app.FeedC_Setpoint_Reset.Visible = 'off';
            app.FeedC_Setpoint_Reset.Enable = 'off';
            app.FeedC_Setpoint_Label.Visible = 'off';
            app.FeedC_Setpoint_Label.Enable = 'off';
        end

        % Callback function
        function StripLevel_SP_XButtonPushed(app, event)
            app.Strip_ValveProduct_Slider.Visible = 'on';
            app.ValveStrip_Control_Label.Enable = 'on';
            app.ValveStrip_Control_Label.Visible = 'on';
            
            app.StripLevel_SetpointC_Spinner.Visible = 'off';
            app.StripLevel_SetpointC_Spinner.Enable = 'off';
            app.StripLevel_Setpoint_Reset.Visible = 'off';
            app.StripLevel_Setpoint_Reset.Enable = 'off';
            app.StripLevel_Setpoint_Label.Visible = 'off';
            app.StripLevel_Setpoint_Label.Enable = 'off';
        end

        % Value changed function: ReactorPressure_SetpointC_Spinner
        function ReactorPressure_SetpointC_SpinnerValueChanged(app, event)
            value = app.ReactorPressure_SetpointC_Spinner.Value;
            
            set_param('MultiLoop_mode1/TE Plant/Reactor pressure/ReactorPressure_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/Reactor pressure/ReactorPressure_Setpoint', 'Value', num2str(value));
        end

        % Value changed function: ReactorLevel_SetpointC_Spinner
        function ReactorLevel_SetpointC_SpinnerValueChanged(app, event)
            value = app.ReactorLevel_SetpointC_Spinner.Value;
            
            set_param('MultiLoop_mode1/TE Plant/Reactor level/ReactorLevel_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/Reactor level/ReactorLevel_Setpoint', 'Value', num2str(value));
        end

        % Button pushed function: ReactorPressure_Setpoint_Reset
        function ReactorPressure_Setpoint_ResetButtonPushed(app, event)
             set_param('MultiLoop_mode1/TE Plant/Reactor pressure/ReactorPressure_Switch', 'sw', '1');
             app.ReactorPressure_SetpointC_Spinner.Value = 2800;
        end

        % Button pushed function: ReactorLevel_Setpoint_Reset
        function ReactorLevel_Setpoint_ResetButtonPushed(app, event)
             set_param('MultiLoop_mode1/TE Plant/Reactor level/ReactorLevel_Switch', 'sw', '1');
             app.ReactorLevel_SetpointC_Spinner.Value = 65;
        end

        % Value changed function: SepTemp_SetpointC_Spinner
        function SepTemp_SetpointC_SpinnerValueChanged(app, event)
            value = app.SepTemp_SetpointC_Spinner.Value;
            
            set_param('MultiLoop_mode1/TE Plant/Separator temperature/SepTemp_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/Separator temperature/SepTemp_Setpoint', 'Value', num2str(value));
        end

        % Value changed function: SepFlow_SetpointC_Spinner
        function SepFlow_SetpointC_SpinnerValueChanged(app, event)
            value = app.SepFlow_SetpointC_Spinner.Value;
            
            set_param('MultiLoop_mode1/TE Plant/Separator flow rate/SepFlow_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/Separator flow rate/SepFlow_Setpoint', 'Value', num2str(value));
        end

        % Button pushed function: SepTemp_Setpoint_Reset
        function SepTemp_Setpoint_ResetButtonPushed(app, event)
            set_param('MultiLoop_mode1/TE Plant/Separator temperature/SepTemp_Switch', 'sw', '1');
            app.SepTemp_SetpointC_Spinner.Value = 92.02;
        end

        % Button pushed function: SepFlow_Setpoint_Reset
        function SepFlow_Setpoint_ResetButtonPushed(app, event)
            set_param('MultiLoop_mode1/TE Plant/Separator flow rate/SepFlow_Switch', 'sw', '1');
            app.SepFlow_SetpointC_Spinner.Value = 25.26;
        end

        % Value changed function: MoleG_SetpointC_Spinner
        function MoleG_SetpointC_SpinnerValueChanged(app, event)
            value = app.MoleG_SetpointC_Spinner.Value;
            
            set_param('MultiLoop_mode1/TE Plant/%G in product/MoleG_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/%G in product/MoleG_Setpoint', 'Value', num2str(value));
        end

        % Value changed function: yA_SetpointC_Spinner
        function yA_SetpointC_SpinnerValueChanged(app, event)
            value = app.yA_SetpointC_Spinner.Value;
            
            set_param('MultiLoop_mode1/TE Plant/yA control/yA_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/yA control/yA_Setpoint', 'Value', num2str(value));
        end

        % Value changed function: yAC_SetpointC_Spinner
        function yAC_SetpointC_SpinnerValueChanged(app, event)
            value = app.yAC_SetpointC_Spinner.Value;
            
            set_param('MultiLoop_mode1/TE Plant/yAC control/yAC_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/yAC control/yAC_Setpoint', 'Value', num2str(value));
        end

        % Button pushed function: MoleG_Setpoint_Reset
        function MoleG_Setpoint_ResetButtonPushed(app, event)
            set_param('MultiLoop_mode1/TE Plant/%G in product/MoleG_Switch', 'sw', '1');
            app.MoleG_SetpointC_Spinner.Value = 53.8;
        end

        % Button pushed function: yA_Setpoint_Reset
        function yA_Setpoint_ResetButtonPushed(app, event)
           set_param('MultiLoop_mode1/TE Plant/yA control/yA_Switch', 'sw', '1');
           app.yA_SetpointC_Spinner.Value = 64.4;
        end

        % Button pushed function: yAC_Setpoint_Reset
        function yAC_Setpoint_ResetButtonPushed(app, event)
           set_param('MultiLoop_mode1/TE Plant/yAC control/yAC_Switch', 'sw', '1');
           app.yAC_SetpointC_Spinner.Value = 50;
        end

        % Button pushed function: ReactorPressure_AutoButton
        function ReactorPressure_AutoButtonPushed(app, event)
            %auto selected
            app.ReactorPressure_AutoButton.FontColor = [1 1 1];
            app.ReactorPressure_AutoButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.ReactorPressure_ManualButton.BackgroundColor =  [0.96 0.96 0.96];
            app.ReactorPressure_ManualButton.FontColor = [0 0 0];

            app.ReactorPressure_ratioC_Label.Visible = 'off';
            app.ReactorPressure_ratioC_Spinner.Visible = 'off';
            app.ReactorPressure_ratioC_Reset.Visible = 'off';

            app.ReactorPressure_Setpoint_Label.Visible = 'on';
            app.ReactorPressure_SetpointC_Spinner.Visible = 'on';
            app.ReactorPressure_Setpoint_Reset.Visible = 'on';
        end

        % Button pushed function: ReactorPressure_ManualButton
        function ReactorPressure_ManualButtonPushed(app, event)
            %manual selected
            app.ReactorPressure_ManualButton.FontColor = [1 1 1];
            app.ReactorPressure_ManualButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.ReactorPressure_AutoButton.BackgroundColor =  [0.96 0.96 0.96];
            app.ReactorPressure_AutoButton.FontColor = [0 0 0];

            app.ReactorPressure_ratioC_Label.Visible = 'on';
            app.ReactorPressure_ratioC_Spinner.Visible = 'on';
            app.ReactorPressure_ratioC_Reset.Visible = 'on';

            app.ReactorPressure_Setpoint_Label.Visible = 'off';
            app.ReactorPressure_SetpointC_Spinner.Visible = 'off';
            app.ReactorPressure_Setpoint_Reset.Visible = 'off';
        end

        % Button pushed function: ReactorLevel_AutoButton
        function ReactorLevel_AutoButtonPushed(app, event)
            %auto selected
            app.ReactorLevel_AutoButton.FontColor = [1 1 1];
            app.ReactorLevel_AutoButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.ReactorLevel_ManualButton.BackgroundColor =  [0.96 0.96 0.96];
            app.ReactorLevel_ManualButton.FontColor = [0 0 0];

            app.ReactorLevel_ratioC_Label.Visible = 'off';
            app.ReactorLevel_ratioC_Spinner.Visible = 'off';
            app.ReactorLevel_ratioC_Reset.Visible = 'off';

            app.ReactorLevel_Setpoint_Label.Visible = 'on';
            app.ReactorLevel_SetpointC_Spinner.Visible = 'on';
            app.ReactorLevel_Setpoint_Reset.Visible = 'on';
        end

        % Button pushed function: ReactorLevel_ManualButton
        function ReactorLevel_ManualButtonPushed(app, event)
            %manual selected
            app.ReactorLevel_ManualButton.FontColor = [1 1 1];
            app.ReactorLevel_ManualButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.ReactorLevel_AutoButton.BackgroundColor =  [0.96 0.96 0.96];
            app.ReactorLevel_AutoButton.FontColor = [0 0 0];

            app.ReactorLevel_ratioC_Label.Visible = 'on';
            app.ReactorLevel_ratioC_Spinner.Visible = 'on';
            app.ReactorLevel_ratioC_Reset.Visible = 'on';

            app.ReactorLevel_Setpoint_Label.Visible = 'off';
            app.ReactorLevel_SetpointC_Spinner.Visible = 'off';
            app.ReactorLevel_Setpoint_Reset.Visible = 'off';
        end

        % Button pushed function: MoleG_AutoButton
        function MoleG_AutoButtonPushed(app, event)
            %auto selected
            app.MoleG_AutoButton.FontColor = [1 1 1];
            app.MoleG_AutoButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.MoleG_ManualButton.BackgroundColor =  [0.96 0.96 0.96];
            app.MoleG_ManualButton.FontColor = [0 0 0];

            app.MoleG_ratioC_Label.Visible = 'off';
            app.MoleG_ratioC_Spinner.Visible = 'off';
            app.MoleG_ratioC_Reset.Visible = 'off';

            app.MoleG_Setpoint_Label.Visible = 'on';
            app.MoleG_SetpointC_Spinner.Visible = 'on';
            app.MoleG_Setpoint_Reset.Visible = 'on';
        end

        % Button pushed function: MoleG_ManualButton
        function MoleG_ManualButtonPushed(app, event)
            %manual selected
            app.MoleG_ManualButton.FontColor = [1 1 1];
            app.MoleG_ManualButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.MoleG_AutoButton.BackgroundColor =  [0.96 0.96 0.96];
            app.MoleG_AutoButton.FontColor = [0 0 0];

            app.MoleG_ratioC_Label.Visible = 'on';
            app.MoleG_ratioC_Spinner.Visible = 'on';
            app.MoleG_ratioC_Reset.Visible = 'on';

            app.MoleG_Setpoint_Label.Visible = 'off';
            app.MoleG_SetpointC_Spinner.Visible = 'off';
            app.MoleG_Setpoint_Reset.Visible = 'off';
        end

        % Button pushed function: yAyAc_AutoButton
        function yAyAc_AutoButtonPushed(app, event)
            %auto selected
            app.yAyAc_AutoButton.FontColor = [1 1 1];
            app.yAyAc_AutoButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.yAyAc_ManualButton.BackgroundColor =  [0.96 0.96 0.96];
            app.yAyAc_ManualButton.FontColor = [0 0 0];

            app.yA_ratioC_Label.Visible = 'off';
            app.yA_ratioC_Spinner.Visible = 'off';
            app.yA_ratioC_Reset.Visible = 'off';

            app.yA_Setpoint_Label.Visible = 'on';
            app.yA_SetpointC_Spinner.Visible = 'on';
            app.yA_Setpoint_Reset.Visible = 'on';

            app.yAC_ratioC_Label.Visible = 'off';
            app.yAC_ratioC_Spinner.Visible = 'off';
            app.yAC_ratioC_Reset.Visible = 'off';

            app.yAC_Setpoint_Label.Visible = 'on';
            app.yAC_SetpointC_Spinner.Visible = 'on';
            app.yAC_Setpoint_Reset.Visible = 'on';
        end

        % Button pushed function: yAyAc_ManualButton
        function yAyAc_ManualButtonPushed(app, event)
            %manual selected
            app.yAyAc_ManualButton.FontColor = [1 1 1];
            app.yAyAc_ManualButton.BackgroundColor = [0.26 0.27 0.27]; 
            
            app.yAyAc_AutoButton.BackgroundColor =  [0.96 0.96 0.96];
            app.yAyAc_AutoButton.FontColor = [0 0 0];

            app.yA_ratioC_Label.Visible = 'on';
            app.yA_ratioC_Spinner.Visible = 'on';
            app.yA_ratioC_Reset.Visible = 'on';

            app.yA_Setpoint_Label.Visible = 'off';
            app.yA_SetpointC_Spinner.Visible = 'off';
            app.yA_Setpoint_Reset.Visible = 'off';

            app.yAC_ratioC_Label.Visible = 'on';
            app.yAC_ratioC_Spinner.Visible = 'on';
            app.yAC_ratioC_Reset.Visible = 'on';

            app.yAC_Setpoint_Label.Visible = 'off';
            app.yAC_SetpointC_Spinner.Visible = 'off';
            app.yAC_Setpoint_Reset.Visible = 'off';
        end

        % Value changed function: ReactorPressure_ratioC_Spinner
        function ReactorPressure_ratioC_SpinnerValueChanged(app, event)
            value = app.ReactorPressure_ratioC_Spinner.Value;
            
            set_param('MultiLoop_mode1/TE Plant/ReactorPressure_Ratio_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/ReactorPressure_Ratio', 'Value', num2str(value));
        end

        % Value changed function: ReactorLevel_ratioC_Spinner
        function ReactorLevel_ratioC_SpinnerValueChanged(app, event)
            value = app.ReactorLevel_ratioC_Spinner.Value;
            
            set_param('MultiLoop_mode1/TE Plant/Separator temperature/SepTemp_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/Separator temperature/SepTemp_Setpoint', 'Value', num2str(value));
        end

        % Button pushed function: ReactorPressure_ratioC_Reset
        function ReactorPressure_ratioC_ResetButtonPushed(app, event)
            set_param('MultiLoop_mode1/TE Plant/ReactorPressure_Ratio_Switch', 'sw', '1');
        end

        % Button pushed function: ReactorLevel_ratioC_Reset
        function ReactorLevel_ratioC_ResetButtonPushed(app, event)
            set_param('MultiLoop_mode1/TE Plant/Separator temperature/SepTemp_Switch', 'sw', '1');
        end

        % Value changed function: MoleG_ratioC_Spinner
        function MoleG_ratioC_SpinnerValueChanged(app, event)
            value = app.MoleG_ratioC_Spinner.Value;
            
            set_param('MultiLoop_mode1/TE Plant/MoleG_Eadj_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/MoleG_Eadj', 'Value', num2str(value));
        end

        % Value changed function: yA_ratioC_Spinner
        function yA_ratioC_SpinnerValueChanged(app, event)
            value = app.yA_ratioC_Spinner.Value;
            
            set_param('MultiLoop_mode1/TE Plant/yA_Ratio_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/yA_Ratio', 'Value', num2str(value));
        end

        % Value changed function: yAC_ratioC_Spinner
        function yAC_ratioC_SpinnerValueChanged(app, event)
            value = app.yAC_ratioC_Spinner.Value;
            
            set_param('MultiLoop_mode1/TE Plant/yAC_Ratio_Switch', 'sw', '0');
            set_param('MultiLoop_mode1/TE Plant/yAC_Ratio', 'Value', num2str(value));
        end

        % Button pushed function: MoleG_ratioC_Reset
        function MoleG_ratioC_ResetButtonPushed(app, event)
            set_param('MultiLoop_mode1/TE Plant/MoleG_Eadj_Switch', 'sw', '1');
        end

        % Button pushed function: yA_ratioC_Reset
        function yA_ratioC_ResetButtonPushed(app, event)
             set_param('MultiLoop_mode1/TE Plant/yA_Ratio_Switch', 'sw', '1');
        end

        % Button pushed function: yAC_ratioC_Reset
        function yAC_ratioC_ResetButtonPushed(app, event)
             set_param('MultiLoop_mode1/TE Plant/yAC_Ratio_Switch', 'sw', '1');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ReactorTab = uitab(app.TabGroup);
            app.ReactorTab.Title = 'Reactor';
            app.UIAxes_Recycle = uiaxes(app.ReactorTab);
            zlabel(app.UIAxes_Recycle, 'Z')
            app.UIAxes_Recycle.YLim = [25 38];
            app.UIAxes_Recycle.XTick = [];
            app.UIAxes_Recycle.YTick = [];
            app.UIAxes_CWR_Temperature = uiaxes(app.ReactorTab);
            zlabel(app.UIAxes_CWR_Temperature, 'Z')
            app.UIAxes_CWR_Temperature.YLim = [80 120];
            app.UIAxes_CWR_Temperature.XTick = [];
            app.UIAxes_CWR_Temperature.YTick = [];
            app.UIAxes_Level = uiaxes(app.ReactorTab);
            title(app.UIAxes_Level, 'Reactor Level')
            zlabel(app.UIAxes_Level, 'Z')
            app.UIAxes_Level.XLim = [0 Inf];
            app.UIAxes_Level.YLim = [0 100];
            app.UIAxes_Level.XTick = 0;
            app.UIAxes_Level.XTickLabel = {'0'};
            app.UIAxes_Level.YTick = [0 25 50 75 100];
            app.UIAxes_Level.YTickLabel = {'0'; '25'; '50'; '75'; '100'};
            app.UIAxes_Pressure = uiaxes(app.ReactorTab);
            title(app.UIAxes_Pressure, 'Reactor Pressure')
            zlabel(app.UIAxes_Pressure, 'Z')
            app.UIAxes_Pressure.XLim = [0 Inf];
            app.UIAxes_Pressure.YLim = [2700 2950];
            app.UIAxes_Pressure.XTick = [];
            app.UIAxes_Pressure.XTickLabel = '';
            app.UIAxes_Pressure.YTick = [2700 2750 2800 2850 2900 2950];
            app.UIAxes_Pressure.YTickLabel = {'2700'; '2750'; '2800'; '2850'; '2900'; '2950'};
            app.UIAxes_Temp = uiaxes(app.ReactorTab);
            title(app.UIAxes_Temp, 'Reactor Temperature')
            zlabel(app.UIAxes_Temp, 'Z')
            app.UIAxes_Temp.XLim = [0 Inf];
            app.UIAxes_Temp.YLim = [0 200];
            app.UIAxes_Temp.XTick = [];
            app.UIAxes_Temp.XTickLabel = '';
            app.UIAxes_Temp.YTick = [0 60 120 150 200];
            app.UIAxes_Temp.YTickLabel = {'0'; '60'; '120'; '150'; '200'};
            app.Image2 = uiimage(app.ReactorTab);
            app.Image2.ImageSource = 'reactor.png';
            app.Label_4 = uilabel(app.ReactorTab);
            app.Label_4.Text = '%';
            app.Label_5 = uilabel(app.ReactorTab);
            app.Label_5.Text = '%';
            app.KscmhLabel_3 = uilabel(app.ReactorTab);
            app.KscmhLabel_3.Text = 'Kscmh';
            app.Recycle_Label = uilabel(app.ReactorTab);
            app.Recycle_Label.Text = '0.00';
            app.CLabel_3 = uilabel(app.ReactorTab);
            app.CLabel_3.Text = '°C';
            app.KscmhLabel_2 = uilabel(app.ReactorTab);
            app.KscmhLabel_2.Text = 'Kscmh';
            app.FlowReactor_Label = uilabel(app.ReactorTab);
            app.FlowReactor_Label.Text = '0.00';
            app.Label_3 = uilabel(app.ReactorTab);
            app.Label_3.Text = '%';
            app.Label_2 = uilabel(app.ReactorTab);
            app.Label_2.Text = '%';
            app.ValveCWR_Label = uilabel(app.ReactorTab);
            app.ValveCWR_Label.Text = '0.00';
            app.ValveE_Label = uilabel(app.ReactorTab);
            app.ValveE_Label.Text = '0.00';
            app.ValveD_Label = uilabel(app.ReactorTab);
            app.ValveD_Label.Text = '0.00';
            app.Pression_Label = uilabel(app.ReactorTab);
            app.Pression_Label.Text = '0.00';
            app.CLabel = uilabel(app.ReactorTab);
            app.CLabel.Text = '°C';
            app.Temperature_Label = uilabel(app.ReactorTab);
            app.Temperature_Label.Text = '0.00';
            app.Level_Label = uilabel(app.ReactorTab);
            app.Level_Label.Text = '0.00';
            app.kPaLabel = uilabel(app.ReactorTab);
            app.kPaLabel.Text = 'kPa';
            app.ReactorLabel = uilabel(app.ReactorTab);
            app.ReactorLabel.Text = 'Reactor';
            app.Panel_3 = uipanel(app.ReactorTab);
            app.FeedAGaugeLabel = uilabel(app.Panel_3);
            app.FeedAGaugeLabel.Text = 'Feed A';
            app.FlowAGauge = uigauge(app.Panel_3, 'linear');
            app.FlowAGauge.Limits = [0.15 0.4];
            app.FlowAGauge.MajorTicks = [0 0.15 0.25 0.35 0.4];
            app.FlowAGauge.MajorTickLabels = {'0', '0.15', '0.25', '0.35', '0.4'};
            app.MainFeedFlowLabel = uilabel(app.Panel_3);
            app.MainFeedFlowLabel.Text = 'Main Feed Flow';
            app.FeedDGaugeLabel = uilabel(app.Panel_3);
            app.FeedDGaugeLabel.Text = 'Feed D';
            app.FlowDGauge = uigauge(app.Panel_3, 'linear');
            app.FlowDGauge.Limits = [3000 4200];
            app.FlowDGauge.MajorTicks = [3000 3600 4200];
            app.FeedEGaugeLabel = uilabel(app.Panel_3);
            app.FeedEGaugeLabel.Text = 'Feed E';
            app.FlowEGauge = uigauge(app.Panel_3, 'linear');
            app.FlowEGauge.Limits = [3500 5200];
            app.FlowEGauge.MajorTicks = [3500 4600 5200];
            app.kghLabel = uilabel(app.Panel_3);
            app.kghLabel.Text = 'kg/h';
            app.kghLabel_2 = uilabel(app.Panel_3);
            app.kghLabel_2.Text = 'kg/h';
            app.Reactor_FeedA_Lamp = uilamp(app.Panel_3);
            app.Reactor_FeedA_Lamp.Enable = 'off';
            app.Reactor_FeedA_Lamp.Visible = 'off';
            app.Reactor_FeedD_Lamp = uilamp(app.Panel_3);
            app.Reactor_FeedD_Lamp.Enable = 'off';
            app.Reactor_FeedD_Lamp.Visible = 'off';
            app.Reactor_FeedE_Lamp = uilamp(app.Panel_3);
            app.Reactor_FeedE_Lamp.Enable = 'off';
            app.Reactor_FeedE_Lamp.Visible = 'off';
            app.FlowA_Label = uilabel(app.ReactorTab);
            app.FlowA_Label.Text = '0.00';
            app.FlowD_Label = uilabel(app.ReactorTab);
            app.FlowD_Label.Text = '0.00';
            app.FlowE_Label = uilabel(app.ReactorTab);
            app.FlowE_Label.Text = '0.00';
            app.KscmhLabel = uilabel(app.ReactorTab);
            app.KscmhLabel.Text = 'Kscmh';
            app.FeedReactorGaugeLabel = uilabel(app.ReactorTab);
            app.FeedReactorGaugeLabel.Text = 'Feed Reactor';
            app.FeedReactorGauge = uigauge(app.ReactorTab, 'linear');
            app.FeedReactorGauge.Limits = [30 60];
            app.Label_6 = uilabel(app.ReactorTab);
            app.Label_6.Text = '%';
            app.ValveA_Label = uilabel(app.ReactorTab);
            app.ValveA_Label.Text = '0.00';
            app.CWR_Temperature_Label = uilabel(app.ReactorTab);
            app.CWR_Temperature_Label.Text = '0.00';
            app.Panel_17 = uipanel(app.ReactorTab);
            app.FeedReactorGauge_Conc_A = uigauge(app.Panel_17, 'linear');
            app.FeedReactorGauge_Conc_A.Limits = [25 40];
            app.FeedReactorGaugeLabel_2 = uilabel(app.Panel_17);
            app.FeedReactorGaugeLabel_2.Text = 'A';
            app.FeedReactorGaugeLabel_3 = uilabel(app.Panel_17);
            app.FeedReactorGaugeLabel_3.Text = 'Inflow Concentration';
            app.FeedReactorGauge_Conc_B = uigauge(app.Panel_17, 'linear');
            app.FeedReactorGauge_Conc_B.Limits = [12 20];
            app.FeedReactorGaugeLabel_4 = uilabel(app.Panel_17);
            app.FeedReactorGaugeLabel_4.Text = 'B';
            app.FeedReactorGauge_Conc_C = uigauge(app.Panel_17, 'linear');
            app.FeedReactorGauge_Conc_C.Limits = [12 24];
            app.FeedReactorGaugeLabel_5 = uilabel(app.Panel_17);
            app.FeedReactorGaugeLabel_5.Text = 'C';
            app.FeedReactorGauge_Conc_D = uigauge(app.Panel_17, 'linear');
            app.FeedReactorGauge_Conc_D.Limits = [4 8];
            app.FeedReactorGaugeLabel_6 = uilabel(app.Panel_17);
            app.FeedReactorGaugeLabel_6.Text = 'D';
            app.FeedReactorGauge_Conc_E = uigauge(app.Panel_17, 'linear');
            app.FeedReactorGauge_Conc_E.Limits = [10 20];
            app.FeedReactorGaugeLabel_7 = uilabel(app.Panel_17);
            app.FeedReactorGaugeLabel_7.Text = 'E';
            app.FeedReactorGauge_Conc_F = uigauge(app.Panel_17, 'linear');
            app.FeedReactorGauge_Conc_F.Limits = [2 6];
            app.FeedReactorGaugeLabel_8 = uilabel(app.Panel_17);
            app.FeedReactorGaugeLabel_8.Text = 'F';
            app.Reactor_ConcA_Lamp = uilamp(app.Panel_17);
            app.Reactor_ConcA_Lamp.Enable = 'off';
            app.Reactor_ConcA_Lamp.Visible = 'off';
            app.Reactor_ConcB_Lamp = uilamp(app.Panel_17);
            app.Reactor_ConcB_Lamp.Enable = 'off';
            app.Reactor_ConcB_Lamp.Visible = 'off';
            app.Reactor_ConcC_Lamp = uilamp(app.Panel_17);
            app.Reactor_ConcC_Lamp.Enable = 'off';
            app.Reactor_ConcC_Lamp.Visible = 'off';
            app.Reactor_ConcD_Lamp = uilamp(app.Panel_17);
            app.Reactor_ConcD_Lamp.Enable = 'off';
            app.Reactor_ConcD_Lamp.Visible = 'off';
            app.Reactor_ConcE_Lamp = uilamp(app.Panel_17);
            app.Reactor_ConcE_Lamp.Enable = 'off';
            app.Reactor_ConcE_Lamp.Visible = 'off';
            app.Reactor_ConcF_Lamp = uilamp(app.Panel_17);
            app.Reactor_ConcF_Lamp.Enable = 'off';
            app.Reactor_ConcF_Lamp.Visible = 'off';
            app.Reactor_Pressure_Lamp = uilamp(app.ReactorTab);
            app.Reactor_Pressure_Lamp.Enable = 'off';
            app.Reactor_Pressure_Lamp.Visible = 'off';
            app.Reactor_Temp_Lamp = uilamp(app.ReactorTab);
            app.Reactor_Temp_Lamp.Enable = 'off';
            app.Reactor_Temp_Lamp.Visible = 'off';
            app.Reactor_Level_Lamp = uilamp(app.ReactorTab);
            app.Reactor_Level_Lamp.Enable = 'off';
            app.Reactor_Level_Lamp.Visible = 'off';
            app.Reactor_Recycle_Lamp = uilamp(app.ReactorTab);
            app.Reactor_Recycle_Lamp.Enable = 'off';
            app.Reactor_Recycle_Lamp.Visible = 'off';
            app.Reactor_CWR_Lamp = uilamp(app.ReactorTab);
            app.Reactor_CWR_Lamp.Enable = 'off';
            app.Reactor_CWR_Lamp.Visible = 'off';
            app.Reactor_FeedReactor_Lamp = uilamp(app.ReactorTab);
            app.Reactor_FeedReactor_Lamp.Enable = 'off';
            app.Reactor_FeedReactor_Lamp.Visible = 'off';
            app.TabGroup2 = uitabgroup(app.ReactorTab);
            app.ValvesTab = uitab(app.TabGroup2);
            app.ValvesTab.Title = 'Valves';
            app.FlowControlValveALabel = uilabel(app.ValvesTab);
            app.FlowControlValveALabel.Text = 'Flow Control Valve A';
            app.FlowControlValveDLabel = uilabel(app.ValvesTab);
            app.FlowControlValveDLabel.Text = 'Flow Control Valve D';
            app.FlowControlValveELabel = uilabel(app.ValvesTab);
            app.FlowControlValveELabel.Text = 'Flow Control Valve E';
            app.TemperatureControlValveCWRLabel_4 = uilabel(app.ValvesTab);
            app.TemperatureControlValveCWRLabel_4.Text = 'Temperature Control Valve CWR';
            app.ValveA_Slider = uislider(app.ValvesTab);
            app.ValveA_Slider.ValueChangedFcn = createCallbackFcn(app, @ValveA_SliderValueChanged, true);
            app.ValveA_Slider.Visible = 'off';
            app.ValveD_Slider = uislider(app.ValvesTab);
            app.ValveD_Slider.ValueChangedFcn = createCallbackFcn(app, @ValveD_SliderValueChanged, true);
            app.ValveD_Slider.Visible = 'off';
            app.ValveE_Slider = uislider(app.ValvesTab);
            app.ValveE_Slider.ValueChangedFcn = createCallbackFcn(app, @ValveE_SliderValueChanged, true);
            app.ValveE_Slider.Visible = 'off';
            app.ValveCWR_Slider = uislider(app.ValvesTab);
            app.ValveCWR_Slider.ValueChangedFcn = createCallbackFcn(app, @ValveCWR_SliderValueChanged, true);
            app.ValveCWR_Slider.Visible = 'off';
            app.Image3_16 = uiimage(app.ValvesTab);
            app.Image3_16.ImageSource = 'Group 239.png';
            app.Image3_17 = uiimage(app.ValvesTab);
            app.Image3_17.ImageSource = 'Group 239.png';
            app.Image3_18 = uiimage(app.ValvesTab);
            app.Image3_18.ImageSource = 'Group 239.png';
            app.Image3_19 = uiimage(app.ValvesTab);
            app.Image3_19.ImageSource = 'MDI - thermometer-low.png';
            app.ValveA_AutoButton = uibutton(app.ValvesTab, 'push');
            app.ValveA_AutoButton.ButtonPushedFcn = createCallbackFcn(app, @ValveA_AutoButtonPushed, true);
            app.ValveA_AutoButton.Text = 'Auto';
            app.ValveA_ManualButton = uibutton(app.ValvesTab, 'push');
            app.ValveA_ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ValveA_ManualButtonPushed, true);
            app.ValveA_ManualButton.Text = 'Manual';
            app.ValveD_AutoButton = uibutton(app.ValvesTab, 'push');
            app.ValveD_AutoButton.ButtonPushedFcn = createCallbackFcn(app, @ValveD_AutoButtonPushed, true);
            app.ValveD_AutoButton.Text = 'Auto';
            app.ValveD_ManualButton = uibutton(app.ValvesTab, 'push');
            app.ValveD_ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ValveD_ManualButtonPushed, true);
            app.ValveD_ManualButton.Text = 'Manual';
            app.ValveE_AutoButton = uibutton(app.ValvesTab, 'push');
            app.ValveE_AutoButton.ButtonPushedFcn = createCallbackFcn(app, @ValveE_AutoButtonPushed, true);
            app.ValveE_AutoButton.Text = 'Auto';
            app.ValveE_ManualButton = uibutton(app.ValvesTab, 'push');
            app.ValveE_ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ValveE_ManualButtonPushed, true);
            app.ValveE_ManualButton.Text = 'Manual';
            app.ValveCWR_AutoButton = uibutton(app.ValvesTab, 'push');
            app.ValveCWR_AutoButton.ButtonPushedFcn = createCallbackFcn(app, @ValveCWR_AutoButtonPushed, true);
            app.ValveCWR_AutoButton.Text = 'Auto';
            app.ValveCWR_ManualButton = uibutton(app.ValvesTab, 'push');
            app.ValveCWR_ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ValveCWR_ManualButtonPushed, true);
            app.ValveCWR_ManualButton.Text = 'Manual';
            app.FeedA_Setpoint_Reset = uibutton(app.ValvesTab, 'push');
            app.FeedA_Setpoint_Reset.ButtonPushedFcn = createCallbackFcn(app, @FeedA_Setpoint_ResetButtonPushed, true);
            app.FeedA_Setpoint_Reset.Text = 'Reset';
            app.FeedA_Setpoint_Label = uilabel(app.ValvesTab);
            app.FeedA_Setpoint_Label.Text = 'Setpoint (Kscmh)';
            app.ValveA_Control_Label = uilabel(app.ValvesTab);
            app.ValveA_Control_Label.Visible = 'off';
            app.ValveA_Control_Label.Text = 'Valve Opening (%)';
            app.FeedA_SetpointC_Spinner = uispinner(app.ValvesTab);
            app.FeedA_SetpointC_Spinner.Step = 0.01;
            app.FeedA_SetpointC_Spinner.Limits = [0 Inf];
            app.FeedA_SetpointC_Spinner.ValueChangedFcn = createCallbackFcn(app, @FeedA_SetpointC_SpinnerValueChanged, true);
            app.FeedD_Setpoint_Reset = uibutton(app.ValvesTab, 'push');
            app.FeedD_Setpoint_Reset.ButtonPushedFcn = createCallbackFcn(app, @FeedD_Setpoint_ResetButtonPushed, true);
            app.FeedD_Setpoint_Reset.Text = 'Reset';
            app.FeedD_Setpoint_Label = uilabel(app.ValvesTab);
            app.FeedD_Setpoint_Label.Text = 'Setpoint (kg/h)';
            app.ValveD_Control_Label = uilabel(app.ValvesTab);
            app.ValveD_Control_Label.Visible = 'off';
            app.ValveD_Control_Label.Text = 'Valve Opening (%)';
            app.FeedD_SetpointC_Spinner = uispinner(app.ValvesTab);
            app.FeedD_SetpointC_Spinner.ValueChangedFcn = createCallbackFcn(app, @FeedD_SetpointC_SpinnerValueChanged, true);
            app.FeedE_Setpoint_Reset = uibutton(app.ValvesTab, 'push');
            app.FeedE_Setpoint_Reset.ButtonPushedFcn = createCallbackFcn(app, @FeedE_Setpoint_ResetButtonPushed, true);
            app.FeedE_Setpoint_Reset.Text = 'Reset';
            app.FeedE_Setpoint_Label = uilabel(app.ValvesTab);
            app.FeedE_Setpoint_Label.Text = 'Setpoint (kg/h)';
            app.ValveE_Control_Label = uilabel(app.ValvesTab);
            app.ValveE_Control_Label.Visible = 'off';
            app.ValveE_Control_Label.Text = 'Valve Opening (%)';
            app.FeedE_SetpointC_Spinner = uispinner(app.ValvesTab);
            app.FeedE_SetpointC_Spinner.ValueChangedFcn = createCallbackFcn(app, @FeedE_SetpointC_SpinnerValueChanged, true);
            app.ReactorTemp_Setpoint_Reset = uibutton(app.ValvesTab, 'push');
            app.ReactorTemp_Setpoint_Reset.ButtonPushedFcn = createCallbackFcn(app, @ReactorTemp_Setpoint_ResetButtonPushed, true);
            app.ReactorTemp_Setpoint_Reset.Text = 'Reset';
            app.ReactorTemp_Setpoint_Label = uilabel(app.ValvesTab);
            app.ReactorTemp_Setpoint_Label.Text = 'Setpoint (°C)';
            app.ValveCWR_Control_Label = uilabel(app.ValvesTab);
            app.ValveCWR_Control_Label.Visible = 'off';
            app.ValveCWR_Control_Label.Text = 'Valve Opening (%)';
            app.ReactorTemp_SetpointC_Spinner = uispinner(app.ValvesTab);
            app.ReactorTemp_SetpointC_Spinner.ValueChangedFcn = createCallbackFcn(app, @ReactorTemp_SetpointC_SpinnerValueChanged, true);
            app.ControlerTab = uitab(app.TabGroup2);
            app.ControlerTab.Title = 'Controler';
            app.ReactorPressure_Setpoint_Reset = uibutton(app.ControlerTab, 'push');
            app.ReactorPressure_Setpoint_Reset.ButtonPushedFcn = createCallbackFcn(app, @ReactorPressure_Setpoint_ResetButtonPushed, true);
            app.ReactorPressure_Setpoint_Reset.Text = 'Reset';
            app.ReactorPressure_SetpointC_Spinner = uispinner(app.ControlerTab);
            app.ReactorPressure_SetpointC_Spinner.ValueChangedFcn = createCallbackFcn(app, @ReactorPressure_SetpointC_SpinnerValueChanged, true);
            app.ReactorPressure_SetpointC_Spinner.Value = 2800;
            app.ReactorPressure_Setpoint_Label = uilabel(app.ControlerTab);
            app.ReactorPressure_Setpoint_Label.Text = 'Setpoint (kPa)';
            app.ReactorLevel_Setpoint_Reset = uibutton(app.ControlerTab, 'push');
            app.ReactorLevel_Setpoint_Reset.ButtonPushedFcn = createCallbackFcn(app, @ReactorLevel_Setpoint_ResetButtonPushed, true);
            app.ReactorLevel_Setpoint_Reset.Text = 'Reset';
            app.ReactorLevel_SetpointC_Spinner = uispinner(app.ControlerTab);
            app.ReactorLevel_SetpointC_Spinner.ValueChangedFcn = createCallbackFcn(app, @ReactorLevel_SetpointC_SpinnerValueChanged, true);
            app.ReactorLevel_SetpointC_Spinner.Value = 65;
            app.ReactorTemp_Setpoint_Label_15 = uilabel(app.ControlerTab);
            app.ReactorTemp_Setpoint_Label_15.Text = 'Reator Level';
            app.ReactorLevel_Setpoint_Label = uilabel(app.ControlerTab);
            app.ReactorLevel_Setpoint_Label.Text = 'Setpoint (%)';
            app.HighLevelReactorControlerLabel = uilabel(app.ControlerTab);
            app.HighLevelReactorControlerLabel.Text = 'High Level Reactor Controler';
            app.ReactorPressure_AutoButton = uibutton(app.ControlerTab, 'push');
            app.ReactorPressure_AutoButton.ButtonPushedFcn = createCallbackFcn(app, @ReactorPressure_AutoButtonPushed, true);
            app.ReactorPressure_AutoButton.Text = 'Auto';
            app.ReactorPressure_ManualButton = uibutton(app.ControlerTab, 'push');
            app.ReactorPressure_ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ReactorPressure_ManualButtonPushed, true);
            app.ReactorPressure_ManualButton.Text = 'Manual';
            app.ReactorLevel_AutoButton = uibutton(app.ControlerTab, 'push');
            app.ReactorLevel_AutoButton.ButtonPushedFcn = createCallbackFcn(app, @ReactorLevel_AutoButtonPushed, true);
            app.ReactorLevel_AutoButton.Text = 'Auto';
            app.ReactorLevel_ManualButton = uibutton(app.ControlerTab, 'push');
            app.ReactorLevel_ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ReactorLevel_ManualButtonPushed, true);
            app.ReactorLevel_ManualButton.Text = 'Manual';
            app.ReactorTemp_Setpoint_Label_16 = uilabel(app.ControlerTab);
            app.ReactorTemp_Setpoint_Label_16.Text = 'Reactor Pressure';
            app.ReactorPressure_ratioC_Reset = uibutton(app.ControlerTab, 'push');
            app.ReactorPressure_ratioC_Reset.ButtonPushedFcn = createCallbackFcn(app, @ReactorPressure_ratioC_ResetButtonPushed, true);
            app.ReactorPressure_ratioC_Reset.Visible = 'off';
            app.ReactorPressure_ratioC_Reset.Text = 'Reset';
            app.ReactorPressure_ratioC_Spinner = uispinner(app.ControlerTab);
            app.ReactorPressure_ratioC_Spinner.Step = 0.01;
            app.ReactorPressure_ratioC_Spinner.Limits = [0 1];
            app.ReactorPressure_ratioC_Spinner.ValueChangedFcn = createCallbackFcn(app, @ReactorPressure_ratioC_SpinnerValueChanged, true);
            app.ReactorPressure_ratioC_Spinner.Visible = 'off';
            app.ReactorPressure_ratioC_Spinner.Value = 1;
            app.ReactorPressure_ratioC_Label = uilabel(app.ControlerTab);
            app.ReactorPressure_ratioC_Label.Visible = 'off';
            app.ReactorPressure_ratioC_Label.Text = 'Purge Flow Ratio';
            app.ReactorLevel_ratioC_Reset = uibutton(app.ControlerTab, 'push');
            app.ReactorLevel_ratioC_Reset.ButtonPushedFcn = createCallbackFcn(app, @ReactorLevel_ratioC_ResetButtonPushed, true);
            app.ReactorLevel_ratioC_Reset.Visible = 'off';
            app.ReactorLevel_ratioC_Reset.Text = 'Reset';
            app.ReactorLevel_ratioC_Spinner = uispinner(app.ControlerTab);
            app.ReactorLevel_ratioC_Spinner.Step = 0.1;
            app.ReactorLevel_ratioC_Spinner.Limits = [0 Inf];
            app.ReactorLevel_ratioC_Spinner.ValueChangedFcn = createCallbackFcn(app, @ReactorLevel_ratioC_SpinnerValueChanged, true);
            app.ReactorLevel_ratioC_Spinner.Visible = 'off';
            app.ReactorLevel_ratioC_Spinner.Value = 92.02;
            app.ReactorLevel_ratioC_Label = uilabel(app.ControlerTab);
            app.ReactorLevel_ratioC_Label.Visible = 'off';
            app.ReactorLevel_ratioC_Label.Text = 'Separator Temperature Setpoint (°C)';
            app.GeneralControlsTab = uitab(app.TabGroup2);
            app.GeneralControlsTab.Title = 'General Controls';
            app.MoleG_Setpoint_Reset = uibutton(app.GeneralControlsTab, 'push');
            app.MoleG_Setpoint_Reset.ButtonPushedFcn = createCallbackFcn(app, @MoleG_Setpoint_ResetButtonPushed, true);
            app.MoleG_Setpoint_Reset.Text = 'Reset';
            app.MoleG_SetpointC_Spinner = uispinner(app.GeneralControlsTab);
            app.MoleG_SetpointC_Spinner.Step = 0.1;
            app.MoleG_SetpointC_Spinner.ValueChangedFcn = createCallbackFcn(app, @MoleG_SetpointC_SpinnerValueChanged, true);
            app.MoleG_SetpointC_Spinner.Value = 53.8;
            app.MoleG_Setpoint_Label = uilabel(app.GeneralControlsTab);
            app.MoleG_Setpoint_Label.Text = 'Setpoint (Mole %)';
            app.yA_Setpoint_Reset = uibutton(app.GeneralControlsTab, 'push');
            app.yA_Setpoint_Reset.ButtonPushedFcn = createCallbackFcn(app, @yA_Setpoint_ResetButtonPushed, true);
            app.yA_Setpoint_Reset.Text = 'Reset';
            app.yA_SetpointC_Spinner = uispinner(app.GeneralControlsTab);
            app.yA_SetpointC_Spinner.Step = 0.1;
            app.yA_SetpointC_Spinner.ValueChangedFcn = createCallbackFcn(app, @yA_SetpointC_SpinnerValueChanged, true);
            app.yA_SetpointC_Spinner.Value = 64.4;
            app.ReactorTemp_Setpoint_Label_20 = uilabel(app.GeneralControlsTab);
            app.ReactorTemp_Setpoint_Label_20.Text = 'yA and yAC';
            app.yA_Setpoint_Label = uilabel(app.GeneralControlsTab);
            app.yA_Setpoint_Label.Text = 'yA Setpoint (Mole %)';
            app.yAC_Setpoint_Reset = uibutton(app.GeneralControlsTab, 'push');
            app.yAC_Setpoint_Reset.ButtonPushedFcn = createCallbackFcn(app, @yAC_Setpoint_ResetButtonPushed, true);
            app.yAC_Setpoint_Reset.Text = 'Reset';
            app.yAC_SetpointC_Spinner = uispinner(app.GeneralControlsTab);
            app.yAC_SetpointC_Spinner.ValueChangedFcn = createCallbackFcn(app, @yAC_SetpointC_SpinnerValueChanged, true);
            app.yAC_SetpointC_Spinner.Value = 50;
            app.yAC_Setpoint_Label = uilabel(app.GeneralControlsTab);
            app.yAC_Setpoint_Label.Text = 'yAC Setpoint (Mole %)';
            app.MoleG_AutoButton = uibutton(app.GeneralControlsTab, 'push');
            app.MoleG_AutoButton.ButtonPushedFcn = createCallbackFcn(app, @MoleG_AutoButtonPushed, true);
            app.MoleG_AutoButton.Text = 'Auto';
            app.MoleG_ManualButton = uibutton(app.GeneralControlsTab, 'push');
            app.MoleG_ManualButton.ButtonPushedFcn = createCallbackFcn(app, @MoleG_ManualButtonPushed, true);
            app.MoleG_ManualButton.Text = 'Manual';
            app.ReactorTemp_Setpoint_Label_22 = uilabel(app.GeneralControlsTab);
            app.ReactorTemp_Setpoint_Label_22.Text = '% Mole G';
            app.MoleG_ratioC_Reset = uibutton(app.GeneralControlsTab, 'push');
            app.MoleG_ratioC_Reset.ButtonPushedFcn = createCallbackFcn(app, @MoleG_ratioC_ResetButtonPushed, true);
            app.MoleG_ratioC_Reset.Visible = 'off';
            app.MoleG_ratioC_Reset.Text = 'Reset';
            app.MoleG_ratioC_Spinner = uispinner(app.GeneralControlsTab);
            app.MoleG_ratioC_Spinner.Step = 0.01;
            app.MoleG_ratioC_Spinner.Limits = [0 Inf];
            app.MoleG_ratioC_Spinner.ValueChangedFcn = createCallbackFcn(app, @MoleG_ratioC_SpinnerValueChanged, true);
            app.MoleG_ratioC_Spinner.Visible = 'off';
            app.MoleG_ratioC_Spinner.Value = 1;
            app.MoleG_ratioC_Label = uilabel(app.GeneralControlsTab);
            app.MoleG_ratioC_Label.Visible = 'off';
            app.MoleG_ratioC_Label.Text = 'Eadj';
            app.GeneralLabel_2 = uilabel(app.GeneralControlsTab);
            app.GeneralLabel_2.Text = 'General';
            app.yA_ratioC_Reset = uibutton(app.GeneralControlsTab, 'push');
            app.yA_ratioC_Reset.ButtonPushedFcn = createCallbackFcn(app, @yA_ratioC_ResetButtonPushed, true);
            app.yA_ratioC_Reset.Visible = 'off';
            app.yA_ratioC_Reset.Text = 'Reset';
            app.yA_ratioC_Spinner = uispinner(app.GeneralControlsTab);
            app.yA_ratioC_Spinner.Step = 0.01;
            app.yA_ratioC_Spinner.Limits = [0 1];
            app.yA_ratioC_Spinner.ValueChangedFcn = createCallbackFcn(app, @yA_ratioC_SpinnerValueChanged, true);
            app.yA_ratioC_Spinner.Visible = 'off';
            app.yA_ratioC_Spinner.Value = 1;
            app.yA_ratioC_Label = uilabel(app.GeneralControlsTab);
            app.yA_ratioC_Label.Visible = 'off';
            app.yA_ratioC_Label.Text = 'Feed A Ratio';
            app.yAyAc_AutoButton = uibutton(app.GeneralControlsTab, 'push');
            app.yAyAc_AutoButton.ButtonPushedFcn = createCallbackFcn(app, @yAyAc_AutoButtonPushed, true);
            app.yAyAc_AutoButton.Text = 'Auto';
            app.yAyAc_ManualButton = uibutton(app.GeneralControlsTab, 'push');
            app.yAyAc_ManualButton.ButtonPushedFcn = createCallbackFcn(app, @yAyAc_ManualButtonPushed, true);
            app.yAyAc_ManualButton.Text = 'Manual';
            app.yAC_ratioC_Reset = uibutton(app.GeneralControlsTab, 'push');
            app.yAC_ratioC_Reset.ButtonPushedFcn = createCallbackFcn(app, @yAC_ratioC_ResetButtonPushed, true);
            app.yAC_ratioC_Reset.Visible = 'off';
            app.yAC_ratioC_Reset.Text = 'Reset';
            app.yAC_ratioC_Spinner = uispinner(app.GeneralControlsTab);
            app.yAC_ratioC_Spinner.Step = 0.01;
            app.yAC_ratioC_Spinner.Limits = [0 1];
            app.yAC_ratioC_Spinner.ValueChangedFcn = createCallbackFcn(app, @yAC_ratioC_SpinnerValueChanged, true);
            app.yAC_ratioC_Spinner.Visible = 'off';
            app.yAC_ratioC_Spinner.Value = 1;
            app.yAC_ratioC_Label = uilabel(app.GeneralControlsTab);
            app.yAC_ratioC_Label.Visible = 'off';
            app.yAC_ratioC_Label.Text = 'Feed C Ratio';
            app.SeparatorTab = uitab(app.TabGroup);
            app.SeparatorTab.Title = 'Separator';
            app.UIAxes_Sep_Level = uiaxes(app.SeparatorTab);
            title(app.UIAxes_Sep_Level, 'Separator Level')
            zlabel(app.UIAxes_Sep_Level, 'Z')
            app.UIAxes_Sep_Level.XLim = [0 Inf];
            app.UIAxes_Sep_Level.YLim = [0 100];
            app.UIAxes_Sep_Level.XTick = 0;
            app.UIAxes_Sep_Level.XTickLabel = {'0'};
            app.UIAxes_Sep_Level.YTick = [0 25 50 75 100];
            app.UIAxes_Sep_Level.YTickLabel = {'0'; '25'; '50'; '75'; '100'};
            app.UIAxes_Sep_Temp = uiaxes(app.SeparatorTab);
            title(app.UIAxes_Sep_Temp, 'Separator Temperature')
            zlabel(app.UIAxes_Sep_Temp, 'Z')
            app.UIAxes_Sep_Temp.XLim = [0 Inf];
            app.UIAxes_Sep_Temp.YLim = [0 120];
            app.UIAxes_Sep_Temp.XTick = 0;
            app.UIAxes_Sep_Temp.XTickLabel = {'0'};
            app.UIAxes_Sep_Temp.YTick = [0 30 60 90 120];
            app.UIAxes_Sep_Temp.YTickLabel = {'0'; '30'; '60'; '90'; '120'};
            app.UIAxes_Sep_Pressure = uiaxes(app.SeparatorTab);
            title(app.UIAxes_Sep_Pressure, 'Separator Pressure')
            zlabel(app.UIAxes_Sep_Pressure, 'Z')
            app.UIAxes_Sep_Pressure.XLim = [0 Inf];
            app.UIAxes_Sep_Pressure.YLim = [2600 3000];
            app.UIAxes_Sep_Pressure.XTick = [];
            app.UIAxes_Sep_Pressure.XTickLabel = '';
            app.UIAxes_Sep_Pressure.YTick = [2600 2680 2760 2820 2880 2940 3000];
            app.UIAxes_Sep_Pressure.YTickLabel = {'2600'; '2680'; '2760'; '2820'; '2880'; '2940'; '3000'};
            app.UIAxes_Sep_Purge = uiaxes(app.SeparatorTab);
            zlabel(app.UIAxes_Sep_Purge, 'Z')
            app.UIAxes_Sep_Purge.YLim = [0 0.8];
            app.UIAxes_Sep_Purge.XTick = [];
            app.UIAxes_Sep_Purge.YTick = [];
            app.UIAxes_Sep_LiquidU = uiaxes(app.SeparatorTab);
            zlabel(app.UIAxes_Sep_LiquidU, 'Z')
            app.UIAxes_Sep_LiquidU.YLim = [18 32];
            app.UIAxes_Sep_LiquidU.XTick = [];
            app.UIAxes_Sep_LiquidU.YTick = [];
            app.UIAxes_Cond_CWR_Temp = uiaxes(app.SeparatorTab);
            zlabel(app.UIAxes_Cond_CWR_Temp, 'Z')
            app.UIAxes_Cond_CWR_Temp.YLim = [80 110];
            app.UIAxes_Cond_CWR_Temp.XTick = [];
            app.UIAxes_Cond_CWR_Temp.YTick = [];
            app.Image5_2 = uiimage(app.SeparatorTab);
            app.Image5_2.ImageSource = 'Sep_Cond.png';
            app.Label_8 = uilabel(app.SeparatorTab);
            app.Label_8.Text = '%';
            app.Sep_Pression_Label = uilabel(app.SeparatorTab);
            app.Sep_Pression_Label.Text = '0.00';
            app.CLabel_6 = uilabel(app.SeparatorTab);
            app.CLabel_6.Text = '°C';
            app.Sep_Temperature_Label = uilabel(app.SeparatorTab);
            app.Sep_Temperature_Label.Text = '0.00';
            app.Sep_Level_Label = uilabel(app.SeparatorTab);
            app.Sep_Level_Label.Text = '0.00';
            app.kPaLabel_2 = uilabel(app.SeparatorTab);
            app.kPaLabel_2.Text = 'kPa';
            app.SeparatorCondenserLabel = uilabel(app.SeparatorTab);
            app.SeparatorCondenserLabel.Text = 'Separator/Condenser';
            app.m3hLabel_7 = uilabel(app.SeparatorTab);
            app.m3hLabel_7.Text = 'm3/h';
            app.Sep_PurgeFlow_Label = uilabel(app.SeparatorTab);
            app.Sep_PurgeFlow_Label.Text = '0.00';
            app.m3hLabel_8 = uilabel(app.SeparatorTab);
            app.m3hLabel_8.Text = 'm3/h';
            app.Sep_LiquidU_Label = uilabel(app.SeparatorTab);
            app.Sep_LiquidU_Label.Text = '0.00';
            app.Label_15 = uilabel(app.SeparatorTab);
            app.Label_15.Text = '%';
            app.Sep_Valve_Purge_Label = uilabel(app.SeparatorTab);
            app.Sep_Valve_Purge_Label.Text = '0.00';
            app.Label_16 = uilabel(app.SeparatorTab);
            app.Label_16.Text = '%';
            app.Sep_Valve_LiquidU_Label = uilabel(app.SeparatorTab);
            app.Sep_Valve_LiquidU_Label.Text = '0.00';
            app.Label_18 = uilabel(app.SeparatorTab);
            app.Label_18.Text = '%';
            app.Cond_CWR_Temperature_Label = uilabel(app.SeparatorTab);
            app.Cond_CWR_Temperature_Label.Text = '0.00';
            app.CLabel_10 = uilabel(app.SeparatorTab);
            app.CLabel_10.Text = '°C';
            app.Panel_18 = uipanel(app.SeparatorTab);
            app.PurgeGauge_Conc_A = uigauge(app.Panel_18, 'linear');
            app.PurgeGauge_Conc_A.Limits = [25 40];
            app.FeedReactorGaugeLabel_9 = uilabel(app.Panel_18);
            app.FeedReactorGaugeLabel_9.Text = 'A';
            app.FeedReactorGaugeLabel_10 = uilabel(app.Panel_18);
            app.FeedReactorGaugeLabel_10.Text = 'Purge Concentration';
            app.PurgeGauge_Conc_B = uigauge(app.Panel_18, 'linear');
            app.PurgeGauge_Conc_B.Limits = [16 26];
            app.FeedReactorGaugeLabel_11 = uilabel(app.Panel_18);
            app.FeedReactorGaugeLabel_11.Text = 'B';
            app.FeedReactorGaugeLabel_12 = uilabel(app.Panel_18);
            app.FeedReactorGaugeLabel_12.Text = 'C';
            app.PurgeGauge_Conc_D = uigauge(app.Panel_18, 'linear');
            app.PurgeGauge_Conc_D.Limits = [0.2 1.4];
            app.FeedReactorGaugeLabel_13 = uilabel(app.Panel_18);
            app.FeedReactorGaugeLabel_13.Text = 'D';
            app.PurgeGauge_Conc_E = uigauge(app.Panel_18, 'linear');
            app.PurgeGauge_Conc_E.Limits = [10 20];
            app.FeedReactorGaugeLabel_14 = uilabel(app.Panel_18);
            app.FeedReactorGaugeLabel_14.Text = 'E';
            app.PurgeGauge_Conc_F = uigauge(app.Panel_18, 'linear');
            app.PurgeGauge_Conc_F.Limits = [3 7];
            app.FeedReactorGaugeLabel_15 = uilabel(app.Panel_18);
            app.FeedReactorGaugeLabel_15.Text = 'F';
            app.PurgeGauge_Conc_G = uigauge(app.Panel_18, 'linear');
            app.PurgeGauge_Conc_G.Limits = [4 8];
            app.FeedReactorGaugeLabel_16 = uilabel(app.Panel_18);
            app.FeedReactorGaugeLabel_16.Text = 'G';
            app.PurgeGauge_Conc_H = uigauge(app.Panel_18, 'linear');
            app.PurgeGauge_Conc_H.Limits = [2 4.5];
            app.FeedReactorGaugeLabel_17 = uilabel(app.Panel_18);
            app.FeedReactorGaugeLabel_17.Text = 'H';
            app.PurgeGauge_Conc_C = uigauge(app.Panel_18, 'linear');
            app.PurgeGauge_Conc_C.Limits = [8 18];
            app.Sep_Purge_ConcA_Lamp = uilamp(app.Panel_18);
            app.Sep_Purge_ConcA_Lamp.Enable = 'off';
            app.Sep_Purge_ConcA_Lamp.Visible = 'off';
            app.Sep_Purge_ConcB_Lamp = uilamp(app.Panel_18);
            app.Sep_Purge_ConcB_Lamp.Enable = 'off';
            app.Sep_Purge_ConcB_Lamp.Visible = 'off';
            app.Sep_Purge_ConcC_Lamp = uilamp(app.Panel_18);
            app.Sep_Purge_ConcC_Lamp.Enable = 'off';
            app.Sep_Purge_ConcC_Lamp.Visible = 'off';
            app.Sep_Purge_ConcD_Lamp = uilamp(app.Panel_18);
            app.Sep_Purge_ConcD_Lamp.Enable = 'off';
            app.Sep_Purge_ConcD_Lamp.Visible = 'off';
            app.Sep_Purge_ConcE_Lamp = uilamp(app.Panel_18);
            app.Sep_Purge_ConcE_Lamp.Enable = 'off';
            app.Sep_Purge_ConcE_Lamp.Visible = 'off';
            app.Sep_Purge_ConcF_Lamp = uilamp(app.Panel_18);
            app.Sep_Purge_ConcF_Lamp.Enable = 'off';
            app.Sep_Purge_ConcF_Lamp.Visible = 'off';
            app.Sep_Purge_ConcG_Lamp = uilamp(app.Panel_18);
            app.Sep_Purge_ConcG_Lamp.Enable = 'off';
            app.Sep_Purge_ConcG_Lamp.Visible = 'off';
            app.Sep_Purge_ConcH_Lamp = uilamp(app.Panel_18);
            app.Sep_Purge_ConcH_Lamp.Enable = 'off';
            app.Sep_Purge_ConcH_Lamp.Visible = 'off';
            app.Sep_Pressure_Lamp = uilamp(app.SeparatorTab);
            app.Sep_Pressure_Lamp.Enable = 'off';
            app.Sep_Pressure_Lamp.Visible = 'off';
            app.Sep_Temp_Lamp = uilamp(app.SeparatorTab);
            app.Sep_Temp_Lamp.Enable = 'off';
            app.Sep_Temp_Lamp.Visible = 'off';
            app.Sep_Level_Lamp = uilamp(app.SeparatorTab);
            app.Sep_Level_Lamp.Enable = 'off';
            app.Sep_Level_Lamp.Visible = 'off';
            app.Cond_CWR_Lamp = uilamp(app.SeparatorTab);
            app.Cond_CWR_Lamp.Enable = 'off';
            app.Cond_CWR_Lamp.Visible = 'off';
            app.Sep_Purge_Lamp = uilamp(app.SeparatorTab);
            app.Sep_Purge_Lamp.Enable = 'off';
            app.Sep_Purge_Lamp.Visible = 'off';
            app.Sep_Underflow_Lamp = uilamp(app.SeparatorTab);
            app.Sep_Underflow_Lamp.Enable = 'off';
            app.Sep_Underflow_Lamp.Visible = 'off';
            app.Cond_ValveCWR_Label = uilabel(app.SeparatorTab);
            app.Cond_ValveCWR_Label.Text = '0.00';
            app.TabGroup2_2 = uitabgroup(app.SeparatorTab);
            app.ValveTab_2 = uitab(app.TabGroup2_2);
            app.ValveTab_2.Title = 'Valve';
            app.FlowControlPurgeValveLabel = uilabel(app.ValveTab_2);
            app.FlowControlPurgeValveLabel.Text = 'Flow Control Purge Valve';
            app.LevelControlValveLabel = uilabel(app.ValveTab_2);
            app.LevelControlValveLabel.Text = 'Level Control Valve ';
            app.Sep_ValvePurge_Slider = uislider(app.ValveTab_2);
            app.Sep_ValvePurge_Slider.ValueChangedFcn = createCallbackFcn(app, @Sep_ValvePurge_SliderValueChanged, true);
            app.Sep_ValvePurge_Slider.Visible = 'off';
            app.Sep_ValveLiquidU_Slider = uislider(app.ValveTab_2);
            app.Sep_ValveLiquidU_Slider.ValueChangedFcn = createCallbackFcn(app, @Sep_ValveLiquidU_SliderValueChanged, true);
            app.Sep_ValveLiquidU_Slider.Visible = 'off';
            app.Image3_9 = uiimage(app.ValveTab_2);
            app.Image3_9.ImageSource = 'Group 239.png';
            app.Image3_10 = uiimage(app.ValveTab_2);
            app.Image3_10.ImageSource = 'level_icon.png';
            app.Sep_ValvePurge_AutoButton = uibutton(app.ValveTab_2, 'push');
            app.Sep_ValvePurge_AutoButton.ButtonPushedFcn = createCallbackFcn(app, @Sep_ValvePurge_AutoButtonButtonPushed, true);
            app.Sep_ValvePurge_AutoButton.Text = 'Auto';
            app.Sep_ValvePurge_ManualButton = uibutton(app.ValveTab_2, 'push');
            app.Sep_ValvePurge_ManualButton.ButtonPushedFcn = createCallbackFcn(app, @Sep_ValvePurge_ManualButtonButtonPushed, true);
            app.Sep_ValvePurge_ManualButton.Text = 'Manual';
            app.Sep_ValveLiquidU_AutoButton = uibutton(app.ValveTab_2, 'push');
            app.Sep_ValveLiquidU_AutoButton.ButtonPushedFcn = createCallbackFcn(app, @Sep_ValveLiquidU_AutoButtonPushed, true);
            app.Sep_ValveLiquidU_AutoButton.Text = 'Auto';
            app.Sep_ValveLiquidU_ManualButton = uibutton(app.ValveTab_2, 'push');
            app.Sep_ValveLiquidU_ManualButton.ButtonPushedFcn = createCallbackFcn(app, @Sep_ValveLiquidU_ManualButtonPushed, true);
            app.Sep_ValveLiquidU_ManualButton.Text = 'Manual';
            app.TemperatureControlValveCWRLabel_3 = uilabel(app.ValveTab_2);
            app.TemperatureControlValveCWRLabel_3.Text = 'Temperature Control Valve CWR';
            app.Cond_ValveCWR_Slider = uislider(app.ValveTab_2);
            app.Cond_ValveCWR_Slider.ValueChangedFcn = createCallbackFcn(app, @Cond_ValveCWR_SliderValueChanged, true);
            app.Cond_ValveCWR_Slider.Visible = 'off';
            app.Image3_15 = uiimage(app.ValveTab_2);
            app.Image3_15.ImageSource = 'MDI - thermometer-low.png';
            app.Cond_ValveCWR_AutoButton = uibutton(app.ValveTab_2, 'push');
            app.Cond_ValveCWR_AutoButton.ButtonPushedFcn = createCallbackFcn(app, @Cond_ValveCWR_AutoButtonPushed, true);
            app.Cond_ValveCWR_AutoButton.Text = 'Auto';
            app.Cond_ValveCWR_ManualButton = uibutton(app.ValveTab_2, 'push');
            app.Cond_ValveCWR_ManualButton.ButtonPushedFcn = createCallbackFcn(app, @Cond_ValveCWR_ManualButtonPushed, true);
            app.Cond_ValveCWR_ManualButton.Text = 'Manual';
            app.PurgeFlow_Setpoint_Reset = uibutton(app.ValveTab_2, 'push');
            app.PurgeFlow_Setpoint_Reset.ButtonPushedFcn = createCallbackFcn(app, @PurgeFlow_Setpoint_ResetButtonPushed, true);
            app.PurgeFlow_Setpoint_Reset.Text = 'Reset';
            app.PurgeFlow_Setpoint_Label = uilabel(app.ValveTab_2);
            app.PurgeFlow_Setpoint_Label.Text = 'Setpoint (m3/h)';
            app.ValvePurge_Control_Label = uilabel(app.ValveTab_2);
            app.ValvePurge_Control_Label.Visible = 'off';
            app.ValvePurge_Control_Label.Text = 'Valve Opening (%)';
            app.PurgeFlow_SetpointC_Spinner = uispinner(app.ValveTab_2);
            app.PurgeFlow_SetpointC_Spinner.Step = 0.1;
            app.PurgeFlow_SetpointC_Spinner.ValueChangedFcn = createCallbackFcn(app, @PurgeFlow_SetpointC_SpinnerValueChanged, true);
            app.SepLevel_Setpoint_Reset = uibutton(app.ValveTab_2, 'push');
            app.SepLevel_Setpoint_Reset.ButtonPushedFcn = createCallbackFcn(app, @SepLevel_Setpoint_ResetButtonPushed, true);
            app.SepLevel_Setpoint_Reset.Text = 'Reset';
            app.SepLevel_Setpoint_Label = uilabel(app.ValveTab_2);
            app.SepLevel_Setpoint_Label.Text = 'Setpoint (%)';
            app.ValveSep_Control_Label = uilabel(app.ValveTab_2);
            app.ValveSep_Control_Label.Visible = 'off';
            app.ValveSep_Control_Label.Text = 'Valve Opening (%)';
            app.SepLevel_SetpointC_Spinner = uispinner(app.ValveTab_2);
            app.SepLevel_SetpointC_Spinner.ValueChangedFcn = createCallbackFcn(app, @SepLevel_SetpointC_SpinnerValueChanged, true);
            app.ProductFlow_Setpoint_Reset = uibutton(app.ValveTab_2, 'push');
            app.ProductFlow_Setpoint_Reset.ButtonPushedFcn = createCallbackFcn(app, @ProductFlow_Setpoint_ResetButtonPushed, true);
            app.ProductFlow_Setpoint_Reset.Text = 'Reset';
            app.ProductFlow_Setpoint_Label = uilabel(app.ValveTab_2);
            app.ProductFlow_Setpoint_Label.Text = 'Product Flow Setpoint (m3/h)';
            app.ValveCond_Control_Label = uilabel(app.ValveTab_2);
            app.ValveCond_Control_Label.Visible = 'off';
            app.ValveCond_Control_Label.Text = 'Valve Opening (%)';
            app.ProductFlow_SetpointC_Spinner = uispinner(app.ValveTab_2);
            app.ProductFlow_SetpointC_Spinner.Step = 0.1;
            app.ProductFlow_SetpointC_Spinner.ValueChangedFcn = createCallbackFcn(app, @ProductFlow_SetpointC_SpinnerValueChanged, true);
            app.ControlerTab_2 = uitab(app.TabGroup2_2);
            app.ControlerTab_2.Title = 'Controler';
            app.ReactorTemp_Setpoint_Label_18 = uilabel(app.ControlerTab_2);
            app.ReactorTemp_Setpoint_Label_18.Text = 'Separator Flow Rate';
            app.HighLevelSeparatorControlerLabel = uilabel(app.ControlerTab_2);
            app.HighLevelSeparatorControlerLabel.Text = 'High Level Separator Controler';
            app.ReactorTemp_Setpoint_Label_19 = uilabel(app.ControlerTab_2);
            app.ReactorTemp_Setpoint_Label_19.Text = 'Separator Temperature';
            app.SepTemp_SetpointC_Spinner = uispinner(app.ControlerTab_2);
            app.SepTemp_SetpointC_Spinner.Step = 0.1;
            app.SepTemp_SetpointC_Spinner.ValueChangedFcn = createCallbackFcn(app, @SepTemp_SetpointC_SpinnerValueChanged, true);
            app.SepTemp_SetpointC_Spinner.Value = 92.02;
            app.SepTemp_Setpoint_Label = uilabel(app.ControlerTab_2);
            app.SepTemp_Setpoint_Label.Text = 'Setpoint (°C)';
            app.SepFlow_Setpoint_Reset = uibutton(app.ControlerTab_2, 'push');
            app.SepFlow_Setpoint_Reset.ButtonPushedFcn = createCallbackFcn(app, @SepFlow_Setpoint_ResetButtonPushed, true);
            app.SepFlow_Setpoint_Reset.Text = 'Reset';
            app.SepFlow_SetpointC_Spinner = uispinner(app.ControlerTab_2);
            app.SepFlow_SetpointC_Spinner.Step = 0.1;
            app.SepFlow_SetpointC_Spinner.ValueChangedFcn = createCallbackFcn(app, @SepFlow_SetpointC_SpinnerValueChanged, true);
            app.SepFlow_SetpointC_Spinner.Value = 25.26;
            app.SepFlow_Setpoint_Label = uilabel(app.ControlerTab_2);
            app.SepFlow_Setpoint_Label.Text = 'Setpoint (m3/h)';
            app.SepTemp_Setpoint_Reset = uibutton(app.ControlerTab_2, 'push');
            app.SepTemp_Setpoint_Reset.ButtonPushedFcn = createCallbackFcn(app, @SepTemp_Setpoint_ResetButtonPushed, true);
            app.SepTemp_Setpoint_Reset.Text = 'Reset';
            app.CompressorTab = uitab(app.TabGroup);
            app.CompressorTab.Title = 'Compressor';
            app.UIAxes_Comp_Purge_FlowRate = uiaxes(app.CompressorTab);
            title(app.UIAxes_Comp_Purge_FlowRate, 'Purge Flow Rate')
            zlabel(app.UIAxes_Comp_Purge_FlowRate, 'Z')
            app.UIAxes_Comp_Purge_FlowRate.YLim = [0 0.8];
            app.UIAxes_Comp_Purge_FlowRate.XTick = 0;
            app.UIAxes_Comp_Purge_FlowRate.XTickLabel = {'0'};
            app.UIAxes_Comp_Purge_FlowRate.YTick = [0 0.2 0.4 0.6 0.8];
            app.UIAxes_Comp_Purge_FlowRate.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'};
            app.UIAxes_Comp_Work = uiaxes(app.CompressorTab);
            title(app.UIAxes_Comp_Work, 'Compressor Work')
            zlabel(app.UIAxes_Comp_Work, 'Z')
            app.UIAxes_Comp_Work.XLim = [0 Inf];
            app.UIAxes_Comp_Work.YLim = [230 320];
            app.UIAxes_Comp_Work.XTick = 0;
            app.UIAxes_Comp_Work.XTickLabel = {'0'};
            app.UIAxes_Comp_Work.YTick = [230 260 290 320];
            app.UIAxes_Comp_Work.YTickLabel = {'230'; '260'; '290'; '320'};
            app.UIAxes_Comp_FlowRate = uiaxes(app.CompressorTab);
            title(app.UIAxes_Comp_FlowRate, 'Recycle Flow Rate')
            zlabel(app.UIAxes_Comp_FlowRate, 'Z')
            app.UIAxes_Comp_FlowRate.XLim = [0 Inf];
            app.UIAxes_Comp_FlowRate.YLim = [0 50];
            app.UIAxes_Comp_FlowRate.XTick = 0;
            app.UIAxes_Comp_FlowRate.XTickLabel = {'0'};
            app.UIAxes_Comp_FlowRate.YTick = [0 10 20 30 40 50];
            app.UIAxes_Comp_FlowRate.YTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'};
            app.Image5_3 = uiimage(app.CompressorTab);
            app.Image5_3.ImageSource = 'Compressor.png';
            app.Comp_Work_Label = uilabel(app.CompressorTab);
            app.Comp_Work_Label.Text = '0.00';
            app.Comp_Purge_Label = uilabel(app.CompressorTab);
            app.Comp_Purge_Label.Text = '0.00';
            app.CompressorLabel = uilabel(app.CompressorTab);
            app.CompressorLabel.Text = 'Compressor';
            app.Panel_6 = uipanel(app.CompressorTab);
            app.CompressorValveLabel = uilabel(app.Panel_6);
            app.CompressorValveLabel.Text = 'Compressor Valve';
            app.FlowControlValveCompressorLabel = uilabel(app.Panel_6);
            app.FlowControlValveCompressorLabel.Text = 'Flow Control Valve Compressor';
            app.Comp_FlowRate_Slider = uislider(app.Panel_6);
            app.Comp_FlowRate_Slider.ValueChangedFcn = createCallbackFcn(app, @Comp_FlowRate_SliderValueChanged, true);
            app.Image3_11 = uiimage(app.Panel_6);
            app.Image3_11.ImageSource = 'Group 239.png';
            app.Comp_FlowRate_AutoButton = uibutton(app.Panel_6, 'push');
            app.Comp_FlowRate_AutoButton.ButtonPushedFcn = createCallbackFcn(app, @Comp_FlowRate_AutoButtonPushed, true);
            app.Comp_FlowRate_AutoButton.Text = 'Auto';
            app.Comp_FlowRate_ManualButton = uibutton(app.Panel_6, 'push');
            app.Comp_FlowRate_ManualButton.ButtonPushedFcn = createCallbackFcn(app, @Comp_FlowRate_ManualButtonPushed, true);
            app.Comp_FlowRate_ManualButton.Text = 'Manual';
            app.ValvePurge_Control_Label_2 = uilabel(app.Panel_6);
            app.ValvePurge_Control_Label_2.Text = 'Valve Opening (%)';
            app.Comp_Recycle_Label = uilabel(app.CompressorTab);
            app.Comp_Recycle_Label.Text = '0.00';
            app.Comp_Valve_Label = uilabel(app.CompressorTab);
            app.Comp_Valve_Label.Text = '0.00';
            app.KscmhLabel_5 = uilabel(app.CompressorTab);
            app.KscmhLabel_5.Text = 'Kscmh';
            app.KscmhLabel_4 = uilabel(app.CompressorTab);
            app.KscmhLabel_4.Text = 'Kscmh';
            app.kWLabel = uilabel(app.CompressorTab);
            app.kWLabel.Text = 'kW';
            app.Label_9 = uilabel(app.CompressorTab);
            app.Label_9.Text = '%';
            app.Comp_Recycle_Lamp = uilamp(app.CompressorTab);
            app.Comp_Recycle_Lamp.Enable = 'off';
            app.Comp_Recycle_Lamp.Visible = 'off';
            app.Comp_Work_Lamp = uilamp(app.CompressorTab);
            app.Comp_Work_Lamp.Enable = 'off';
            app.Comp_Work_Lamp.Visible = 'off';
            app.Comp_Purge_Lamp = uilamp(app.CompressorTab);
            app.Comp_Purge_Lamp.Enable = 'off';
            app.Comp_Purge_Lamp.Visible = 'off';
            app.StripperTab = uitab(app.TabGroup);
            app.StripperTab.Title = 'Stripper';
            app.UIAxes_Strip_Level = uiaxes(app.StripperTab);
            title(app.UIAxes_Strip_Level, 'Stripper Level')
            zlabel(app.UIAxes_Strip_Level, 'Z')
            app.UIAxes_Strip_Level.XLim = [0 Inf];
            app.UIAxes_Strip_Level.YLim = [0 100];
            app.UIAxes_Strip_Level.XTick = 0;
            app.UIAxes_Strip_Level.XTickLabel = {'0'};
            app.UIAxes_Strip_Level.YTick = [0 25 50 75 100];
            app.UIAxes_Strip_Level.YTickLabel = {'0'; '25'; '50'; '75'; '100'};
            app.UIAxes_Strip_Temp = uiaxes(app.StripperTab);
            title(app.UIAxes_Strip_Temp, 'Stripper Temperature')
            zlabel(app.UIAxes_Strip_Temp, 'Z')
            app.UIAxes_Strip_Temp.XLim = [0 Inf];
            app.UIAxes_Strip_Temp.YLim = [0 100];
            app.UIAxes_Strip_Temp.XTick = 0;
            app.UIAxes_Strip_Temp.XTickLabel = {'0'};
            app.UIAxes_Strip_Temp.YTick = [0 20 40 60 80 100];
            app.UIAxes_Strip_Temp.YTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.UIAxes_Strip_Pressure = uiaxes(app.StripperTab);
            title(app.UIAxes_Strip_Pressure, 'Stripper Pressure')
            zlabel(app.UIAxes_Strip_Pressure, 'Z')
            app.UIAxes_Strip_Pressure.XLim = [0 Inf];
            app.UIAxes_Strip_Pressure.YLim = [2900 3700];
            app.UIAxes_Strip_Pressure.XTick = 0;
            app.UIAxes_Strip_Pressure.XTickLabel = {'0'};
            app.UIAxes_Strip_Pressure.YTick = [2900 3100 3300 3500 3700];
            app.UIAxes_Strip_Pressure.YTickLabel = {'2900'; '3100'; '3300'; '3500'; '3700'};
            app.UIAxes_Strip_Steam = uiaxes(app.StripperTab);
            zlabel(app.UIAxes_Strip_Steam, 'Z')
            app.UIAxes_Strip_Steam.XTick = [];
            app.UIAxes_Strip_Steam.YTick = [];
            app.UIAxes_Strip_Product = uiaxes(app.StripperTab);
            zlabel(app.UIAxes_Strip_Product, 'Z')
            app.UIAxes_Strip_Product.XTick = [];
            app.UIAxes_Strip_Product.YTick = [];
            app.Image5_4 = uiimage(app.StripperTab);
            app.Image5_4.ImageSource = 'stripper.png';
            app.Label_10 = uilabel(app.StripperTab);
            app.Label_10.Text = '%';
            app.Strip_Pression_Label = uilabel(app.StripperTab);
            app.Strip_Pression_Label.Text = '0.00';
            app.CLabel_9 = uilabel(app.StripperTab);
            app.CLabel_9.Text = '°C';
            app.Strip_Temp_Label = uilabel(app.StripperTab);
            app.Strip_Temp_Label.Text = '0.00';
            app.Strip_Level_Label = uilabel(app.StripperTab);
            app.Strip_Level_Label.Text = '0.00';
            app.kPaLabel_3 = uilabel(app.StripperTab);
            app.kPaLabel_3.Text = 'kPa';
            app.StripperLabel = uilabel(app.StripperTab);
            app.StripperLabel.Text = 'Stripper';
            app.Panel_7 = uipanel(app.StripperTab);
            app.FlowControlValveCLabel = uilabel(app.Panel_7);
            app.FlowControlValveCLabel.Text = 'Flow Control Valve C';
            app.FlowControlSteamValveLabel = uilabel(app.Panel_7);
            app.FlowControlSteamValveLabel.Text = 'Flow Control Steam Valve';
            app.Strip_ValveC_Slider = uislider(app.Panel_7);
            app.Strip_ValveC_Slider.ValueChangedFcn = createCallbackFcn(app, @Strip_ValveC_SliderValueChanged, true);
            app.Strip_ValveC_Slider.Visible = 'off';
            app.Strip_ValveSteam_Slider = uislider(app.Panel_7);
            app.Strip_ValveSteam_Slider.ValueChangedFcn = createCallbackFcn(app, @Strip_ValveSteam_SliderValueChanged, true);
            app.Strip_ValveSteam_Slider.Enable = 'off';
            app.Image3_12 = uiimage(app.Panel_7);
            app.Image3_12.ImageSource = 'Group 239.png';
            app.Strip_ValveC_AutoButton = uibutton(app.Panel_7, 'push');
            app.Strip_ValveC_AutoButton.ButtonPushedFcn = createCallbackFcn(app, @Strip_ValveC_AutoButtonPushed, true);
            app.Strip_ValveC_AutoButton.Text = 'Auto';
            app.Strip_ValveC_ManualButton = uibutton(app.Panel_7, 'push');
            app.Strip_ValveC_ManualButton.ButtonPushedFcn = createCallbackFcn(app, @Strip_ValveC_ManualButtonPushed, true);
            app.Strip_ValveC_ManualButton.Text = 'Manual';
            app.Strip_ValveSteam_AutoButton = uibutton(app.Panel_7, 'push');
            app.Strip_ValveSteam_AutoButton.ButtonPushedFcn = createCallbackFcn(app, @Strip_ValveSteam_AutoButtonPushed, true);
            app.Strip_ValveSteam_AutoButton.Text = 'Auto';
            app.Strip_ValveSteam_ManualButton = uibutton(app.Panel_7, 'push');
            app.Strip_ValveSteam_ManualButton.ButtonPushedFcn = createCallbackFcn(app, @Strip_ValveSteam_ManualButtonPushed, true);
            app.Strip_ValveSteam_ManualButton.Text = 'Manual';
            app.Image3_13 = uiimage(app.Panel_7);
            app.Image3_13.ImageSource = 'Group 239.png';
            app.LevelControlValveProductLabel = uilabel(app.Panel_7);
            app.LevelControlValveProductLabel.Text = 'Level Control Valve Product';
            app.Strip_ValveProduct_Slider = uislider(app.Panel_7);
            app.Strip_ValveProduct_Slider.ValueChangedFcn = createCallbackFcn(app, @Strip_ValveProduct_SliderValueChanged, true);
            app.Strip_ValveProduct_Slider.Visible = 'off';
            app.Strip_ValveProduct_AutoButton = uibutton(app.Panel_7, 'push');
            app.Strip_ValveProduct_AutoButton.ButtonPushedFcn = createCallbackFcn(app, @Strip_ValveProduct_AutoButtonPushed, true);
            app.Strip_ValveProduct_AutoButton.Text = 'Auto';
            app.Strip_ValveProduct_ManualButton = uibutton(app.Panel_7, 'push');
            app.Strip_ValveProduct_ManualButton.ButtonPushedFcn = createCallbackFcn(app, @Strip_ValveProduct_ManualButtonPushed, true);
            app.Strip_ValveProduct_ManualButton.Text = 'Manual';
            app.Image3_14 = uiimage(app.Panel_7);
            app.Image3_14.ImageSource = 'level_icon.png';
            app.FeedC_Setpoint_Reset = uibutton(app.Panel_7, 'push');
            app.FeedC_Setpoint_Reset.ButtonPushedFcn = createCallbackFcn(app, @FeedC_Setpoint_ResetButtonPushed, true);
            app.FeedC_Setpoint_Reset.Text = 'Reset';
            app.FeedC_Setpoint_Label = uilabel(app.Panel_7);
            app.FeedC_Setpoint_Label.Text = 'Setpoint (Ksmch)';
            app.ValveC_Control_Label = uilabel(app.Panel_7);
            app.ValveC_Control_Label.Visible = 'off';
            app.ValveC_Control_Label.Text = 'Valve Opening (%)';
            app.StripLevel_Setpoint_Reset = uibutton(app.Panel_7, 'push');
            app.StripLevel_Setpoint_Reset.ButtonPushedFcn = createCallbackFcn(app, @StripLevel_Setpoint_ResetButtonPushed, true);
            app.StripLevel_Setpoint_Reset.Text = 'Reset';
            app.StripLevel_Setpoint_Label = uilabel(app.Panel_7);
            app.StripLevel_Setpoint_Label.Text = 'Setpoint (%)';
            app.ValveStrip_Control_Label = uilabel(app.Panel_7);
            app.ValveStrip_Control_Label.Visible = 'off';
            app.ValveStrip_Control_Label.Text = 'Valve Opening (%)';
            app.StripLevel_SetpointC_Spinner = uispinner(app.Panel_7);
            app.StripLevel_SetpointC_Spinner.Limits = [0 Inf];
            app.StripLevel_SetpointC_Spinner.ValueChangedFcn = createCallbackFcn(app, @StripLevel_SetpointC_SpinnerValueChanged, true);
            app.FeedC_SetpointC_Spinner = uispinner(app.Panel_7);
            app.FeedC_SetpointC_Spinner.Step = 0.1;
            app.FeedC_SetpointC_Spinner.Limits = [0 Inf];
            app.FeedC_SetpointC_Spinner.ValueChangedFcn = createCallbackFcn(app, @FeedC_SetpointC_SpinnerValueChanged, true);
            app.ValveC_Control_Label_2 = uilabel(app.Panel_7);
            app.ValveC_Control_Label_2.Text = 'Valve Opening (%)';
            app.m3hLabel_5 = uilabel(app.StripperTab);
            app.m3hLabel_5.Text = 'm3/h';
            app.Strip_SteamFlow_Label = uilabel(app.StripperTab);
            app.Strip_SteamFlow_Label.Text = '0.00';
            app.m3hLabel_6 = uilabel(app.StripperTab);
            app.m3hLabel_6.Text = 'm3/h';
            app.Strip_Underflow_Label = uilabel(app.StripperTab);
            app.Strip_Underflow_Label.Text = '0.00';
            app.Label_12 = uilabel(app.StripperTab);
            app.Label_12.Text = '%';
            app.Strip_ValveSteam_Label = uilabel(app.StripperTab);
            app.Strip_ValveSteam_Label.Text = '0.00';
            app.Label_13 = uilabel(app.StripperTab);
            app.Label_13.Text = '%';
            app.Strip_ValveProduct_Label = uilabel(app.StripperTab);
            app.Strip_ValveProduct_Label.Text = '0.00';
            app.Label_14 = uilabel(app.StripperTab);
            app.Label_14.Text = '%';
            app.Strip_ValveC_Label = uilabel(app.StripperTab);
            app.Strip_ValveC_Label.Text = '0.00';
            app.Panel_8 = uipanel(app.StripperTab);
            app.FeedCLabel = uilabel(app.Panel_8);
            app.FeedCLabel.Text = 'Feed C';
            app.FlowC_Label = uilabel(app.Panel_8);
            app.FlowC_Label.Text = '0.00';
            app.KsmchLabel = uilabel(app.Panel_8);
            app.KsmchLabel.Text = 'Ksmch';
            app.FlowCGauge = uigauge(app.Panel_8, 'linear');
            app.FlowCGauge.Limits = [0 20];
            app.Strip_FeedC_Lamp = uilamp(app.Panel_8);
            app.Strip_FeedC_Lamp.Enable = 'off';
            app.Strip_FeedC_Lamp.Visible = 'off';
            app.Panel_19 = uipanel(app.StripperTab);
            app.ProductGauge_Conc_D = uigauge(app.Panel_19, 'linear');
            app.ProductGauge_Conc_D.Limits = [-1.5 1.5];
            app.FeedReactorGaugeLabel_18 = uilabel(app.Panel_19);
            app.FeedReactorGaugeLabel_18.Text = 'D';
            app.FeedReactorGaugeLabel_19 = uilabel(app.Panel_19);
            app.FeedReactorGaugeLabel_19.Text = 'Product Concentration';
            app.ProductGauge_Conc_E = uigauge(app.Panel_19, 'linear');
            app.ProductGauge_Conc_E.Limits = [0.4 0.8];
            app.FeedReactorGaugeLabel_20 = uilabel(app.Panel_19);
            app.FeedReactorGaugeLabel_20.Text = 'E';
            app.ProductGauge_Conc_F = uigauge(app.Panel_19, 'linear');
            app.ProductGauge_Conc_F.Limits = [0.12 0.27];
            app.FeedReactorGaugeLabel_21 = uilabel(app.Panel_19);
            app.FeedReactorGaugeLabel_21.Text = 'F';
            app.ProductGauge_Conc_G = uigauge(app.Panel_19, 'linear');
            app.ProductGauge_Conc_G.Limits = [45 65];
            app.FeedReactorGaugeLabel_22 = uilabel(app.Panel_19);
            app.FeedReactorGaugeLabel_22.Text = 'G';
            app.ProductGauge_Conc_H = uigauge(app.Panel_19, 'linear');
            app.ProductGauge_Conc_H.Limits = [35 55];
            app.FeedReactorGaugeLabel_23 = uilabel(app.Panel_19);
            app.FeedReactorGaugeLabel_23.Text = 'H';
            app.Strip_Product_ConcD_Lamp = uilamp(app.Panel_19);
            app.Strip_Product_ConcD_Lamp.Enable = 'off';
            app.Strip_Product_ConcD_Lamp.Visible = 'off';
            app.Strip_Product_ConcE_Lamp = uilamp(app.Panel_19);
            app.Strip_Product_ConcE_Lamp.Enable = 'off';
            app.Strip_Product_ConcE_Lamp.Visible = 'off';
            app.Strip_Product_ConcF_Lamp = uilamp(app.Panel_19);
            app.Strip_Product_ConcF_Lamp.Enable = 'off';
            app.Strip_Product_ConcF_Lamp.Visible = 'off';
            app.Strip_Product_ConcG_Lamp = uilamp(app.Panel_19);
            app.Strip_Product_ConcG_Lamp.Enable = 'off';
            app.Strip_Product_ConcG_Lamp.Visible = 'off';
            app.Strip_Product_ConcH_Lamp = uilamp(app.Panel_19);
            app.Strip_Product_ConcH_Lamp.Enable = 'off';
            app.Strip_Product_ConcH_Lamp.Visible = 'off';
            app.Strip_Level_Lamp = uilamp(app.StripperTab);
            app.Strip_Level_Lamp.Enable = 'off';
            app.Strip_Level_Lamp.Visible = 'off';
            app.Strip_Temp_Lamp = uilamp(app.StripperTab);
            app.Strip_Temp_Lamp.Enable = 'off';
            app.Strip_Temp_Lamp.Visible = 'off';
            app.Strip_Pressure_Lamp = uilamp(app.StripperTab);
            app.Strip_Pressure_Lamp.Enable = 'off';
            app.Strip_Pressure_Lamp.Visible = 'off';
            app.Strip_Steam_Lamp = uilamp(app.StripperTab);
            app.Strip_Steam_Lamp.Enable = 'off';
            app.Strip_Steam_Lamp.Visible = 'off';
            app.Strip_Underflow_Lamp = uilamp(app.StripperTab);
            app.Strip_Underflow_Lamp.Enable = 'off';
            app.Strip_Underflow_Lamp.Visible = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.ReactorFeedButton = uibutton(app.Panel, 'push');
            app.ReactorFeedButton.ButtonPushedFcn = createCallbackFcn(app, @ReactorFeedButtonPushed, true);
            app.ReactorFeedButton.Text = 'Reactor/Feed';
            app.CondenserSeparatorPurgeButton = uibutton(app.Panel, 'push');
            app.CondenserSeparatorPurgeButton.ButtonPushedFcn = createCallbackFcn(app, @CondenserSeparatorPurgeButtonPushed, true);
            app.CondenserSeparatorPurgeButton.Text = 'Condenser/Separator/Purge';
            app.CompressorButton = uibutton(app.Panel, 'push');
            app.CompressorButton.ButtonPushedFcn = createCallbackFcn(app, @CompressorButtonPushed, true);
            app.CompressorButton.Text = 'Compressor';
            app.StripperProductButton = uibutton(app.Panel, 'push');
            app.StripperProductButton.ButtonPushedFcn = createCallbackFcn(app, @StripperProductButtonPushed, true);
            app.StripperProductButton.Text = 'Stripper/Product';
            app.CurrentTime_Label = uilabel(app.Panel);
            app.CurrentTime_Label.Text = '00-00-00';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TEP_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end