classdef fault_selection_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimulatorHoursLabel             matlab.ui.control.Label
        FaultActivationTimeSpinner      matlab.ui.control.Spinner
        FaultActivationTimeSpinnerLabel  matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        FaultSelectionLabel             matlab.ui.control.Label
        FaultDropDown                   matlab.ui.control.DropDown
        FaultDropDownLabel              matlab.ui.control.Label
        DiagnosticSituationButtonGroup  matlab.ui.container.ButtonGroup
        UnknownDiagnosticButton         matlab.ui.control.RadioButton
        IncorrectDiagnosticButton       matlab.ui.control.RadioButton
        CorrectDiagnosticButton         matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        faultArray = zeros(1,28);
        callingApp;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base','fault',0);
            app.faultArray = strjoin(string(app.faultArray));
            app.faultArray = strcat('[', app.faultArray, ']') ;
            set_param('MultiLoop_mode1/Disturbances', 'Time', "0");
            set_param('MultiLoop_mode1/Disturbances', 'Before', app.faultArray );
            set_param('MultiLoop_mode1/Disturbances', 'After', app.faultArray );
            app.faultArray = zeros(1,28);

            %Valves Auto
            set_param('MultiLoop_mode1/TE Plant/ValveA_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/ValveD_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/ValveE_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/ValveC_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/ValveCWR_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/Comp_ValveRecycle_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/Sep_ValvePurge_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/Sep_ValveLiquidU_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/Strip_ValveProduct_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/Strip_ValveSteam_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/Cond_ValveCWR_Switch', 'sw', '1');

            %Setpoint auto
            set_param('MultiLoop_mode1/TE Plant/A feed rate/A_Feed_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/C feed rate/C_Feed_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/D feed rate/D_Feed_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/E feed rate/E_Feed_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/%G in product/MoleG_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/Stripper flow rate/ProductFlow_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/Purge rate/PurgeFlow_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/Reactor level/ReactorLevel_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/Reactor pressure/ReactorPressure_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/Reactor temperature/ReactorTemp_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/Separator flow rate/SepFlow_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/Separator level/SepLevel_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/Separator temperature/SepTemp_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/Stripper level/StripLevel_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/yA control/yA_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/yAC control/yAC_Switch', 'sw', '1');

            %Controler auto
            set_param('MultiLoop_mode1/TE Plant/ReactorPressure_Ratio_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/MoleG_Eadj_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/yA_Ratio_Switch', 'sw', '1');
            set_param('MultiLoop_mode1/TE Plant/yAC_Ratio_Switch', 'sw', '1');
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if app.CorrectDiagnosticButton.Value   %where "Button1" is the name of the first radio button
                DianosticChoice = 1;
            elseif app.IncorrectDiagnosticButton.Value
                DianosticChoice = 2;
            else
                DianosticChoice = 3; 
            end
            assignin('base','diagnostic_type', DianosticChoice);
            set_param('MultiLoop_mode1','SimulationCommand','start');
           
            %Close app
            delete(app.UIFigure);
        end

        % Value changed function: FaultDropDown
        function FaultDropDownValueChanged(app, event)
            value = app.FaultDropDown.Value;
            if(value ~= "None")
                value = str2num(erase(value,'Fault '));
                app.faultArray(value) = 1;
                assignin('base','fault',value);
            else
                assignin('base','fault',0);
            end
           
            app.faultArray = strjoin(string(app.faultArray));
            app.faultArray = strcat('[', app.faultArray, ']') ;
            set_param('MultiLoop_mode1/Disturbances', 'After', app.faultArray );
            app.faultArray = zeros(1,28);
        end

        % Value changed function: FaultActivationTimeSpinner
        function FaultActivationTimeSpinnerValueChanged(app, event)
            value = app.FaultActivationTimeSpinner.Value;
            %72 hours 
            set_param('MultiLoop_mode1/Disturbances', 'Time', num2str(value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DiagnosticSituationButtonGroup = uibuttongroup(app.UIFigure);
            app.DiagnosticSituationButtonGroup.Title = 'Diagnostic Situation';
            app.CorrectDiagnosticButton = uiradiobutton(app.DiagnosticSituationButtonGroup);
            app.CorrectDiagnosticButton.Text = 'Correct Diagnostic';
            app.CorrectDiagnosticButton.Value = true;
            app.IncorrectDiagnosticButton = uiradiobutton(app.DiagnosticSituationButtonGroup);
            app.IncorrectDiagnosticButton.Text = 'Incorrect Diagnostic';
            app.UnknownDiagnosticButton = uiradiobutton(app.DiagnosticSituationButtonGroup);
            app.UnknownDiagnosticButton.Text = 'Unknown Diagnostic';
            app.FaultDropDownLabel = uilabel(app.UIFigure);
            app.FaultDropDownLabel.Text = 'Fault';
            app.FaultDropDown = uidropdown(app.UIFigure);
            app.FaultDropDown.Items = {'None', 'Fault 1', 'Fault 2', 'Fault 3', 'Fault 4', 'Fault 5', 'Fault 6', 'Fault 7', 'Fault 8', 'Fault 9', 'Fault 10', 'Fault 11', 'Fault 12', 'Fault 13', 'Fault 14', 'Fault 15', 'Fault 16', 'Fault 17', 'Fault 18', 'Fault 19', 'Fault 20', 'Fault 21', 'Fault 22', 'Fault 23', 'Fault 24', 'Fault 25', 'Fault 26', 'Fault 27', 'Fault 28'};
            app.FaultDropDown.ValueChangedFcn = createCallbackFcn(app, @FaultDropDownValueChanged, true);
            app.FaultDropDown.Value = 'None';
            app.FaultSelectionLabel = uilabel(app.UIFigure);
            app.FaultSelectionLabel.Text = 'Fault Selection';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FaultActivationTimeSpinnerLabel = uilabel(app.UIFigure);
            app.FaultActivationTimeSpinnerLabel.Text = 'Fault Activation Time';
            app.FaultActivationTimeSpinner = uispinner(app.UIFigure);
            app.FaultActivationTimeSpinner.Step = 0.5;
            app.FaultActivationTimeSpinner.Limits = [0 71];
            app.FaultActivationTimeSpinner.ValueChangedFcn = createCallbackFcn(app, @FaultActivationTimeSpinnerValueChanged, true);
            app.SimulatorHoursLabel = uilabel(app.UIFigure);
            app.SimulatorHoursLabel.Text = 'Simulator (Hours)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fault_selection_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end